classdef Analysis_Tool < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AnalysisToolUIFigure            matlab.ui.Figure
        ProtocolMenu                    matlab.ui.container.Menu
        CreateProtocolMenu              matlab.ui.container.Menu
        ReloadCurrentProtocolMenu       matlab.ui.container.Menu
        LoadProtocolMenu                matlab.ui.container.Menu
        DeleteProtocolMenu              matlab.ui.container.Menu
        SubjectMenu                     matlab.ui.container.Menu
        CreateSubjectMenu               matlab.ui.container.Menu
        DeleteSubjectMenu               matlab.ui.container.Menu
        StudyMenu                       matlab.ui.container.Menu
        ReviewRawFilesMenu              matlab.ui.container.Menu
        SelectComponentsMenu            matlab.ui.container.Menu
        DeleteStudyMenu                 matlab.ui.container.Menu
        EventMenu                       matlab.ui.container.Menu
        RenameEventMenu                 matlab.ui.container.Menu
        DeleteEventMenu                 matlab.ui.container.Menu
        ConversionMenu                  matlab.ui.container.Menu
        ConverttoEDFMenu                matlab.ui.container.Menu
        ConverttoBIDSMenu               matlab.ui.container.Menu
        OptionsMenu                     matlab.ui.container.Menu
        AnalysisTypeMenu                matlab.ui.container.Menu
        EEGMenu                         matlab.ui.container.Menu
        MEGMenu                         matlab.ui.container.Menu
        OpenBrainstormMenu              matlab.ui.container.Menu
        AddTagMenu                      matlab.ui.container.Menu
        ColorModeMenu                   matlab.ui.container.Menu
        ulaval                          matlab.ui.control.Image
        braincanada                     matlab.ui.control.Image
        cervo                           matlab.ui.control.Image
        PROTOCOLPanel                   matlab.ui.container.Panel
        CurrentProtocolLabel            matlab.ui.control.Label
        CurrentProtocolEditField        matlab.ui.control.EditField
        UpdateTreeButton                matlab.ui.control.Button
        SelectDeselectallButton         matlab.ui.control.Button
        POSTPROCESSPanel                matlab.ui.container.Panel
        ComputeSourcesButton            matlab.ui.control.Button
        AverageButton                   matlab.ui.control.Button
        SelectGoodTrialButton           matlab.ui.control.Button
        RejectTrialsButton              matlab.ui.control.Button
        ImportinDataBaseButton          matlab.ui.control.Button
        Hyperlink2_2                    matlab.ui.control.Hyperlink
        Hyperlink2                      matlab.ui.control.Hyperlink
        Hyperlink                       matlab.ui.control.Hyperlink
        Tree                            matlab.ui.container.CheckBoxTree
        PIPELINEPanel                   matlab.ui.container.Panel
        RunPipelineButton               matlab.ui.control.Button
        ImportPipelineButton            matlab.ui.control.Button
        ClearPipelineButton             matlab.ui.control.Button
        OpenPipelineBuilderButton       matlab.ui.control.Button
        UpdateEventsButton              matlab.ui.control.Button
        UpdateHistoryButton             matlab.ui.control.Button
        ClearEventsButton               matlab.ui.control.Button
        ClearHistoryButton              matlab.ui.control.Button
        PipelineTextArea                matlab.ui.control.TextArea
        EVENTSPanel                     matlab.ui.container.Panel
        HISTORYPanel                    matlab.ui.container.Panel
        FREQUENCYPanel                  matlab.ui.container.Panel
        PowerSpectrumDensityButton      matlab.ui.control.Button
        BandpassFilterButton            matlab.ui.control.Button
        NotchFilterButton               matlab.ui.control.Button
        TabGroup1                       matlab.ui.container.TabGroup
        EEGTabGr1                       matlab.ui.container.Tab
        ImportEventsButton              matlab.ui.control.Button
        DetectHeartbeatsButton          matlab.ui.control.Button
        ProjectElectrodesonScalpButton  matlab.ui.control.Button
        RefineRegistrationButton        matlab.ui.control.Button
        AddEEGPositionsButton           matlab.ui.control.Button
        MEGTabGr1                       matlab.ui.container.Tab
        ConvertEpochstoContinueButton   matlab.ui.control.Button
        TabGroup2                       matlab.ui.container.TabGroup
        EEGTabGr2                       matlab.ui.container.Tab
        ICAEEGButton                    matlab.ui.control.Button
        AverageReferenceButton          matlab.ui.control.Button
        MEGTabGr2                       matlab.ui.container.Tab
        ICAMEGButton                    matlab.ui.control.Button
        SSPButton                       matlab.ui.control.Button
        DetectArtifactButton            matlab.ui.control.Button
        RemoveSimultaneousEventsButton  matlab.ui.control.Button
    end


    properties (Access = public)

        % Global variables initialized as empty.
        % Global Variable
        AnalysisType = "";
        AnatomyFileFormat = "";
        PipelineSelected = struct();
        userAnswer = '';
        Testing = false;
        %BasicFct = BasicBstFunctions;
        %EegFct = EegBstFunctions;
        %MegFct = MegBstFunctions;
        %AppFct = AppFunctions;
        BasicFct = "";
        EegFct = "";
        MegFct = "";
        AppFct = "";
        
        % Folder Name
        AppFolderName = "__APP__";
        
        % Paths
        %Alab1Path = "/mnt/3b5a15cf-20ff-4840-8d84-ddbd428344e9/ALAB1/";
        BsToolboxPath = "Brainstorm_Corentin/brainstorm3/";
        BsDatabasePath = "Brainstorm_Corentin/bs_db/";
        BaseSearchPath = '/mnt';
        ClassPath = "classes/";
        ImagesPath = "images/";
        RawDataPath = pwd; % By default
        PipelinePath = pwd; % By default
        WorkingFolderPath = pwd; % Default Working Folder
        ProtocolPath = "";
    end        

    methods (Access = public)
        function answer = returnTextInputValues(app, answer)
            app.userAnswer = answer;
        end
    end
    
    methods (Static)
        
        function BrainstormStudyPath = getBrainstormStudyPathFromSFile(sFile)
            fileName = sFile.FileName;
            fileNameSplit = split(fileName, '/');
            BrainstormStudyPath = fullfile(fileNameSplit{1}, fileNameSplit{2}, 'brainstormstudy.mat');
        end
        
        function allProtocols = getAllProtocols()
            % Return cell of the protocol structure for every protocol
            
            % Save index of current Protocol
            originalProtocol = bst_get('iProtocol');
            
            allProtocols = cell(1, length(dir(bst_get('BrainstormDbDir'))));
            i = 1;
            while true
                
                lastwarn('');
                bst_set('iProtocol', i);
                protocol = bst_get('ProtocolInfo');
                
                [warnMsg, ~] = lastwarn();
                if ~isempty(warnMsg)
                    break
                end
                %if isempty(protocol)
                 %   break
                %end
                allProtocols{i} = protocol.Comment;
                i = i + 1;
            end
            allProtocols = allProtocols(~cellfun('isempty', allProtocols));
            % Set original protocol.
            bst_set('iProtocol', originalProtocol);
        end
         
        function rep = monthConversionStrNum(input)
            
            % Return a STRING
            monthsName = {'JAN', 'FEB', 'MAR', 'APR', 'MAY', 'JUN', 'JUL', 'AUG', 'SEP', 'OCT', 'NOV', 'DEC'};
            monthsNum = {'01', '02', '03', '04', '05', '06', '07', '08', '09', '10', '11', '12'};
            monthStruct = cell2struct(monthsNum, monthsName, 2);

            if isnumeric(input)
                rep = monthsName{input};
                
            elseif isnan(str2double(input))
                strMonth = upper(input);
                rep = monthStruct.(strMonth);

            else
                rep = monthsName{str2double(input)};
                
            end
            
            if length(rep) == 1
                rep = rep{1};
            end
        end
        
        function openFileWithButtonPushed(~, path)
            open(path)
        end
        
        function switchColorModeForChildren(obj, backColor, textColor, textColorHyperLink)
            cls = class(obj);
            
            switch cls
                case 'matlab.ui.control.Button'
                    obj.FontColor = textColor;
                    obj.BackgroundColor = backColor;
        
%                 case 'matlab.ui.container.Panel'
%                     %obj.BackgroundColor = backColor;
%                     %obj.ForegroundColor = textColor;
%                     %buttonIntab = obj.Children;
%                     
                case 'matlab.ui.control.Hyperlink'
                     obj.VisitedColor = textColor;
                     obj.BackgroundColor = backColor;
                     obj.FontColor = textColorHyperLink;
                            
                case 'matlab.ui.control.Label'
                    obj.BackgroundColor = backColor;
                    obj.FontColor = textColor;
                    
                case 'matlab.ui.control.TextArea'
                    obj.FontColor = textColor;
                    obj.BackgroundColor = backColor;
                    
                case 'matlab.ui.control.EditField'
                    
                case 'matlab.ui.control.DropDown'
                    obj.FontColor = textColor;
                    obj.BackgroundColor = backColor;
                    
%                 case 'matlab.ui.control.CheckBoxTree'
%                     obj.FontColor = textColor;
        
            end
        end
        
        % The next function has been taken here:
        % https://www.mathworks.com/matlabcentral/fileexchange/77284-structure-and-object-to-json?s_tid=srchtitle

        function saveAsJSON(data, jsonFileName)
            % saves the values in the structure 'data' to a file in JSON format.
            % Based on the work of Lior Kirsch at: https://uk.mathworks.com/matlabcentr
            % al/fileexchange/50965-structure-to-json
            %
            % Modification by Arthur Y.C. Liu 24/06/2020
            %
            % Example:
            %     data.name = 'chair';
            %     data.color = 'pink';
            %     data.eye = eye(3);
            %     data.metrics.imageSize = [1920; 1080];
            %     data.metrics.height = 0.3;
            %     data.metrics.width = 1.3;
            %     saveJSONfile(data, 'out.json');
            %
            % Output 'out.json':
            % {
            % 	"name" : "chair",
            % 	"color" : "pink",
            % 	"eye" :
            % 	[
            % 		1,
            % 		0,
            % 		0,
            % 		0,
            % 		1,
            % 		0,
            % 		0,
            % 		0,
            % 		1
            % 	],
            % 	"metrics" :
            % 	{
            % 		"imageSize" :
            % 		[
            % 			1920,
            % 			1080
            % 		],
            % 		"height" : 0.3,
            % 		"width" : 1.3
            % 	}
            % }
            fid = fopen(jsonFileName,'w');
            if isobject(data)
                data = toStruct(data);
            end
            writeElement(fid, data, '', true);
            fprintf(fid,'\n');
            fclose(fid);


            function writeElement(fid, data, tabs, isFirstLine)
                namesOfFields = fieldnames(data);
                numFields = length(namesOfFields);
                tabs = sprintf('%s\t', tabs);
                if nargin == 4
                    if isFirstLine
                        fprintf(fid,'%s{\n%s', tabs(1:end-1), tabs);
                    end
                else
                    fprintf(fid,'\n%s{\n%s', tabs(1:end-1), tabs);
                end
                for i = 1:numFields
                    currentField = namesOfFields{i};
                    currentElementValue = data.(currentField);
                    writeSingleElement(fid, currentField, currentElementValue, tabs);
                    if i == numFields
                        fprintf(fid,'\n%s}',  tabs(1:end-1));
                    else
                        fprintf(fid,',\n%s', tabs);
                    end
                end
            end

            function writeSingleElement(fid, currentField, currentElementValue, tabs)
                % if this is an array/matrix and not a string then iterate on every
                % element, if this is a single element write it
                if ~isstruct(currentElementValue) &&...
                        length(currentElementValue) > 1 && ~ischar(currentElementValue)
                    fprintf(fid,'"%s" : \n%s[\n%s\t',currentField, tabs, tabs);
                    tabs = sprintf('%s\t', tabs);
                    valLength = length(currentElementValue(:));
                    for m = 1:valLength
                        fprintf(fid,'%g' , currentElementValue(m));
                        if m == valLength
                            fprintf(fid,'\n%s]', tabs(1:end-1));
                        else
                            fprintf(fid,',\n%s', tabs);
                        end
                    end
                elseif isstruct(currentElementValue) &&...
                        length(currentElementValue) > 1 && ~ischar(currentElementValue)
                    fprintf(fid,'"%s" : \n%s[',currentField, tabs);
                    tabs = sprintf('%s\t', tabs);
                    valLength = length(currentElementValue(:));
                    for m = 1:valLength
                        writeElement(fid, currentElementValue(m), tabs);
                        if m == valLength
                            fprintf(fid,'\n%s]', tabs(1:end-1));
                        else
                            fprintf(fid,',');
                        end
                    end
                elseif isstruct(currentElementValue)
                    fprintf(fid,'"%s" : ',currentField);
                    writeElement(fid, currentElementValue, tabs);
                elseif isnumeric(currentElementValue) || islogical(currentElementValue)
                    fprintf(fid,'"%s" : %g' , currentField, currentElementValue);
                elseif isempty(currentElementValue)
                    fprintf(fid,'"%s" : "null"' , currentField);
                elseif isobject(currentElementValue)
                    currentElementValue = toStruct(currentElementValue);
                    fprintf(fid,'"%s" : ',currentField);
                    writeElement(fid, currentElementValue, tabs);
                else %ischar or something else ...
                    fprintf(fid,'"%s" : "%s"' , currentField, currentElementValue);
                end
            end

        end

    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Add Paths
            addpath(app.ClassPath);
            addpath(app.ImagesPath);
            addpath(app.BsToolboxPath);
            addpath(app.BsDatabasePath);
            
            % Create Objects
            app.BasicFct = BasicBstFunctions;
            app.EegFct = EegBstFunctions;
            app.MegFct = MegBstFunctions;
            app.AppFct = AppFunctions;


            % Input for Type of Analysis
            if ~(exist('resultTest', 'var'))
                choice = ["EEG", "MEG"];
                rep = string(uiconfirm(app.AnalysisToolUIFigure, 'What type of analysis do you want to conduct ?', 'Type of analysis', 'Options', choice));
                app.AppFct.switchAnalysisTab(app, rep);
                
                if rep == "EEG"
                    app.EEGMenu.Checked = true;
                    app.MEGMenu.Checked = false;
                elseif rep == "MEG"
                    app.EEGMenu.Checked = false;
                    app.MEGMenu.Checked = true;
                end
            end
 
            % Pick brainstorm3
%             title = "Pick the brainstorm3 folder"; 
%             pathBrainstorm3 = uigetdir(app.BaseSearchPath, title);
%             addpath(pathBrainstorm3);
            
            
            % Define Working Folder
            title = "Select your Working Folder"; 
            wf = uigetdir(app.BaseSearchPath, title);
            
            if ~isequal(wf, 0)
                app.WorkingFolderPath = wf;
            else
                % If wf is empty, we keep the default Working Folder.
                uiconfirm(app.AnalysisToolUIFigure, strcat("Default Working Folder will be : ", app.WorkingFolderPath),...
                    'Default Working Folder');
            end
            
            
            [~, folderName, ~] = fileparts(app.WorkingFolderPath);
            if ~strcmp(folderName, app.AppFolderName)
                app.WorkingFolderPath = fullfile(app.WorkingFolderPath, app.AppFolderName);
                mkdir(app.WorkingFolderPath)
            end

            % Changing Directory
            %cd(app.WorkingFolderPath);
            
            % Start Brainstorm without interface
            if ~brainstorm('status')
                brainstorm nogui;
            end
     
        end

        % Button pushed function: OpenPipelineBuilderButton
        function OpenPipelineBuilderButtonPushed(app, event)
            % Open Pipeline Builder
            run('Pipeline_Builder.mlapp');
        end

        % Button pushed function: ImportPipelineButton
        function ImportPipelineButtonPushed(app, event)
            
            % Input for Pipeline (.mat)
            [fileName, folderPath] = uigetfile('*.mat', 'Select MAT file', app.PipelinePath);
            
            if (fileName == 0)
                return
            end
            
            app.PipelineSelected = load(strcat(folderPath, fileName));

            % Modify Pipeline Path for future search
            app.PipelinePath = app.PipelineSelected.Folder;
            
            % Display Pipeline Info

            name = app.PipelineSelected.Name;
            folder = app.PipelineSelected.Folder;
            date = app.PipelineSelected.DateOfCreation;
            strDate = strcat(num2str(date(1)), '-', app.monthConversionStrNum(date(2)), '-', ...
                num2str(date(3)), '__', num2str(date(4)), 'h', num2str(date(5)), 'm', ...
                num2str(date(6)), 's');
            type = app.PipelineSelected.Type;
            numberOfProcess = app.PipelineSelected.NumberOfProcesses;
            
            nameFormat = ['NAME', newline, name];
            folderFormat = ['FOLDER', newline, folder];
            dateFormat = ['DATE OF CREATION', newline, strDate];
            typeFormat = ['TYPE', newline, type];
            nbProcessFormat = ['NUMBER OF PROCESS', newline, num2str(numberOfProcess)];

            processes = string.empty(0, app.PipelineSelected.NumberOfProcesses); 
            fields = fieldnames(app.PipelineSelected.Processes);
            for i = 1:length(fields)
                param = "";
                if fields(i) == "NotchFilter"
                    param = " (" + strjoin(string(app.PipelineSelected.Processes.NotchFilter.Frequence), ', ') + " Hz)";
    
                elseif fields(i) == "BandPassFilter"
                    param = " (" + strjoin(string(app.PipelineSelected.Processes.BandPassFilter.Frequence), ', ') + " Hz)";
                    
                elseif fields(i) == "AddEEGPosition"
                    param = " (" + string(app.PipelineSelected.Processes.AddEEGPosition.Cap) + ')';
                    
                elseif fields(i) == "ICA"
                    param = " (Nb Components: " + string(app.PipelineSelected.Processes.ICA.NumberOfComponents) + ')';
                
                elseif fields(i) == "DetectArtifact"
                    event = "";
                    if (isfield(app.PipelineSelected.Processes.DetectArtifact,'Cardiac'))
                       event = event + app.PipelineSelected.Processes.DetectArtifact.Cardiac.EventName + ", ";
                    end
                    if (isfield(app.PipelineSelected.Processes.DetectArtifact,'Blink'))
                       event = event + app.PipelineSelected.Processes.DetectArtifact.Blink.EventName + ", ";
                    end
                    if (isfield(app.PipelineSelected.Processes.DetectArtifact,'Other'))
                       if (isfield(app.PipelineSelected.Processes.DetectArtifact.Other,'LowFrequence'))
                            event = event + 'Low Frequence; ';
                       end
                       if (isfield(app.PipelineSelected.Processes.DetectArtifact.Other,'HighFrequence'))
                            event = event + 'High Frequence; ';
                       end
                    end
                   param = " (" + event + ')';
                
                elseif fields(i) == "SSP"
                    
                   if (isfield(app.PipelineSelected.Processes.SSP,'Cardiac'))
                       event = app.PipelineSelected.Processes.SSP.Cardiac.EventName;
                   elseif (isfield(app.PipelineSelected.Processes.SSP,'Blink'))
                       event = app.PipelineSelected.Processes.SSP.Blink.EventName;
                   elseif (isfield(app.PipelineSelected.Processes.SSP,'Generic'))
                       event = app.PipelineSelected.Processes.SSP.Generic.EventName;
                   end 
                   param = " (" + event + ')';
                
                elseif fields(i) == "RemoveSimultaneousEvents"
                    param = " (Remove: " + string(app.PipelineSelected.Processes.RemoveSimultaneousEvents.EventToRemove) + ...
                        ', ' + 'Target: ' + string(app.PipelineSelected.Processes.RemoveSimultaneousEvents.TargetEvent) + ')';
                
                elseif fields(i) == "ConvertToBIDS"
                    param = " (" + app.PipelineSelected.Processes.ConvertToBIDS.BIDSpath + ')';
                end
                
                processes(i) = fields(i) + param;
            end

            ListProcesses = ['LIST OF PROCESS', processes];
            
            matToDisplay = [nameFormat, newline, folderFormat, newline, dateFormat, newline, ...
                            typeFormat, newline, nbProcessFormat, newline, ListProcesses];

            app.PipelineTextArea.Value = matToDisplay;
        end

        % Button pushed function: ClearPipelineButton
        function ClearPipelineButtonPushed(app, event)
            app.PipelineTextArea.Value = "NO PIPELINE LOADED";
            
            app.PipelineSelected = struct();
        end

        % Button pushed function: RunPipelineButton
        function RunPipelineButtonPushed(app, event)
            sFiles = app.AppFct.selectedStudies(app);
            
            if app.PipelineSelected.Type == "EEG"

                app.EegFct.runPipeline(sFiles, app.PipelineSelected.Processes);
                
            elseif app.PipelineSelected.Type == "MEG"
                
                app.MegFct.runPipeline(sFiles, app.PipelineSelected.Processes);
                
            else
                uialert(app.AnalysisToolUIFigure, "Type of Pipeline is incorrect (EEG or MEG)", "ERROR");
            end
            
            app.AppFct.updateTreeAndCheckNewStudies(app);
            
        end

        % Button pushed function: NotchFilterButton
        function NotchFilterButtonPushed(app, event)

            sFiles = app.AppFct.selectedStudies(app);
            
            % Input for Frequences
            choice = ["I am in North America (60 Hz, 120 Hz, 180 Hz, ...)", ...
                "I am in Europe (50 Hz, 100 Hz, 150 Hz, ...)", "Cancel"];
            rep = uiconfirm(app.AnalysisToolUIFigure, 'Where has your protocol been conducted ?', ...
                'Notch filter', 'Options', choice);

            switch rep
                case choice(1)
                    frequence = [60 120 180 240];
                case choice(2)
                    frequence = [50 100 150 200];
                case choice(3)
                    return
            end
            
            % Get date from original file
            date = app.AppFct.getDateFromBrainstormStudyMAT(app, sFiles);
            
            % Notch Filter
            if app.AnalysisType == "EEG"
                sFiles = app.EegFct.notchFilter(sFiles, frequence);
            elseif app.AnalysisType == "MEG"
                sFiles = app.MegFct.notchFilter(sFiles, frequence);
            end
            
            % Modify date in Brainstormstudy.mat of new file
            app.AppFct.modifyBrainstormStudyMATDate(app, sFiles, date);
            
            % Update tree and Check new studies
            app.AppFct.updateTreeAndCheckNewStudies(app);
   
        end

        % Button pushed function: BandpassFilterButton
        function BandpassFilterButtonPushed(app, event)

            sFiles = app.AppFct.selectedStudies(app);

            % Input for Frequences
            question = 'Lower cut off frequency (Hz): ';
            app.AppFct.ask_user(app, question);
            LowPass = app.userAnswer;

            question = 'Higher cut off frequency (Hz): ';
            app.AppFct.ask_user(app, question);
            HighPass = app.userAnswer;

            if isempty(HighPass) || isempty(LowPass)
                uialert(app.AnalysisToolUIFigure, "Please enter an upper and lower frequency cutoff points.", ...
                    'Error')
                return
            end

            frequence = [str2double(LowPass) str2double(HighPass)];
            
            % Get date from original file
            date = app.AppFct.getDateFromBrainstormStudyMAT(app, sFiles);
            
            if app.AnalysisType == "EEG"
                sFiles = app.EegFct.bandPassFilter(sFiles, frequence);
            elseif app.AnalysisType == "MEG"
                sFiles = app.MegFct.bandPassFilter(sFiles, frequence);
            end

            % Modify date in Brainstormstudy.mat of new file
            app.AppFct.modifyBrainstormStudyMATDate(app, sFiles, date);
            
            app.AppFct.updateTreeAndCheckNewStudies(app);         
            
        end

        % Button pushed function: PowerSpectrumDensityButton
        function PowerSpectrumDensityButtonPushed(app, event)

            sFiles = app.AppFct.selectedStudies(app);
            
            if app.AnalysisType == "EEG"
                app.EegFct.powerSpectrumDensity(sFiles); % Window Length to 10 by default
            elseif app.AnalysisType == "MEG"
                app.MegFct.powerSpectrumDensity(sFiles); % Window Length to 4 by default
            end
            
        end

        % Button pushed function: ConvertEpochstoContinueButton
        function ConvertEpochstoContinueButtonPushed(app, event)
            sFiles = app.AppFct.selectedStudies(app);
            app.MegFct.convertEpochsToContinue(sFiles);
        end

        % Button pushed function: DetectArtifactButton
        function DetectArtifactButtonPushed(app, event)
            sFiles = app.AppFct.selectedStudies(app);
             
            % Input for type of artifact
            choice = ["Cardiac", "Blink", "Low Frequency Artifact (1-7 Hz; eye/teeth movement)", ...
                        "High Frequency Artifact (40-240 Hz; muscle movement/sensor artifacts)"];
            question = 'Choose Artifact';
            app.AppFct.ask_user_list(app, choice, question, 'on');
            artifacts = app.userAnswer;
            if isempty(artifacts)
                return
            end
            
            lowFrequence = 0;
            highFrequence = 0;
            if (any(choice(1) == artifacts))
                channelName = 'ECG';
                app.AppFct.checkIfChannelIsInChannelFile(app, sFiles, channelName);
                app.MegFct.detectCardiacArtifact(sFiles, channelName, 'cardiac');
            end
            
            if (any(choice(2) == artifacts))
                channelName = 'VEOG';
                app.AppFct.checkIfChannelIsInChannelFile(app, sFiles, channelName);
                app.MegFct.detectBlinkArtifact(sFiles, channelName, 'blink');
            end
            
            if (any(choice(3) == artifacts))
                    lowFrequence = 1;
            end
            
            if (any(choice(4) == artifacts))
                    highFrequence = 1;
            end
            
            if (lowFrequence ~= 0 || highFrequence ~= 0)
                app.MegFct.detectOtherArtifact(sFiles, lowFrequence, highFrequence);
            end
        end

        % Button pushed function: RemoveSimultaneousEventsButton
        function RemoveSimultaneousEventsButtonPushed(app, event)
            sFiles = app.AppFct.selectedStudies(app);

            % Display all Events
            choice = app.AppFct.getEvents(app, sFiles);

            app.AppFct.ask_user_list(app, choice, 'Remove the event named: ', 'off');
            eventRename = app.userAnswer;
            
            app.AppFct.ask_user_list(app, choice, 'When it is too close to the event: ', 'off');
            eventTarget = app.userAnswer;
            
            if isempty(eventRename) || isempty(eventTarget)
                waitfor(errordlg("Missing event to remove or to targert."))
                return
            end
            
            app.MegFct.removeSimultaneaousEvents(sFiles, eventRename, eventTarget);
            
        end

        % Button pushed function: SSPButton
        function SSPButtonPushed(app, event)
            sFiles = app.AppFct.selectedStudies(app);

            % Display all Events
            choice = app.AppFct.getEvents(app, sFiles);

            app.AppFct.ask_user_list(app, choice, 'Which event do you want to use to compute the SSP ?:', ...
                'off');
            eventName = app.userAnswer;
            
            if isempty(eventName)
                waitfor(errordlg("Missing event to remove or to targert."))
                return
            end
            
            
            if (eventName == "cardiac")
                app.MegFct.sspCardiac(sFiles);
            
            elseif (eventName == "blink")
                app.MegFct.sspBlink
            
            else
                app.MegFct.sspGeneric(sFiles, eventName);
            end
            
        end

        % Button pushed function: AddEEGPositionsButton
        function AddEEGPositionsButtonPushed(app, event)
            sFiles = app.AppFct.selectedStudies(app);

            % Input for either Default Pattern or Import File
            choice = ["Use default pattern", "Import a file ", "Cancel"];
            rep = uiconfirm(app.AnalysisToolUIFigure, 'Import a file or use a default ?', ...
                'EEG position', 'Options', choice);
            
            switch rep
                case choice(1)
                    % List of available cap
                    capTypes = struct();
                    capTypes.Colin27_BrainProducts_EasyCap_128 = 22;
                    app.AppFct.ask_user_list(app, fields(capTypes), 'Choose your Cap!', 'off');
                    cap = app.userAnswer;
                    
                    if isempty(cap)
                        return
                    end
                    
                    % Selecting Cap
                    capNumber = capTypes.(cap);
                    app.EegFct.addEegPosition(sFiles, "Use Default Pattern", capNumber, '');
                    
                case choice(2)
                    % Select electrode file
                    title = "Select the electrode file (it should have the *.elc extension)";
                    extension = {'*.elc'};
                    [file, ~] = app.AppFct.getFilePath(app, title, extension);
                    
                    app.EegFct.addEegPosition(sFiles, 'Import', file, 1);
                    
                otherwise
                    return
            end
        end

        % Button pushed function: RefineRegistrationButton
        function RefineRegistrationButtonPushed(app, event)
            sFiles = app.AppFct.selectedStudies(app);
            app.EegFct.refineRegistration(sFiles);
        end

        % Button pushed function: ProjectElectrodesonScalpButton
        function ProjectElectrodesonScalpButtonPushed(app, event)
            sFiles = app.AppFct.selectedStudies(app);
            app.EegFct.projectElectrodesOnScalp(sFiles);
        end

        % Button pushed function: DetectHeartbeatsButton
        function DetectHeartbeatsButtonPushed(app, event)
            sFiles = app.AppFct.selectedStudies(app);
            channelName = 'ECG';
                
            app.AppFct.checkIfChannelIsInChannelFile(app, sFiles, channelName);
            app.EegFct.detectCardiacArtifact(sFiles, channelName, 'cardiac');
        end

        % Button pushed function: ImportEventsButton
        function ImportEventsButtonPushed(app, event)

            sFiles = app.AppFct.selectedStudies(app);

            title = "Select the event file (it should have the *.txt extension)";
            extension = {'*.txt'};
            
            [filePath, ~] = app.AppFct.getFilePath(app, title, extension);
            
            question = 'Name of the imported events:';
            app.ask_user(question);
            eventName = app.userAnswer;

            app.BasicFct.importEventFromFile(sFiles, filePath, eventName);

        end

        % Button pushed function: AverageReferenceButton
        function AverageReferenceButtonPushed(app, event)
            sFiles = app.AppFct.selectedStudies(app);
            app.EegFct.averageReference(sFiles); 
        end

        % Button pushed function: ICAEEGButton
        function ICAEEGButtonPushed(app, event)

            sFiles = app.AppFct.selectedStudies(app);

            % Input for number of components
            question = 'How many ICA components (nb electrode / 2): ';
            app.AppFct.ask_user(app, question);
            ICAcomponents = app.userAnswer;

            if isempty(ICAcomponents)
                return
            end

            nbComponents = str2double(ICAcomponents);
            
            
            try
                event.Source
            catch
                app.MegFct.ica(sFiles, nbComponents);
                return
            end
            app.EegFct.ica(sFiles, nbComponents);
        end

        % Button pushed function: ICAMEGButton
        function ICAMEGButtonPushed(app, event)
            app.ICAEEGButtonPushed();
        end

        % Selection change function: TabGroup1
        function TabGroup1SelectionChanged(app, event)
            app.AppFct.switchAnalysisTab(app, 'TabGroup1');
        end

        % Selection change function: TabGroup2
        function TabGroup2SelectionChanged(app, event)
            app.AppFct.switchAnalysisTab(app, "TabGroup2");
        end

        % Button pushed function: SelectDeselectallButton
        function SelectDeselectallButtonPushed(app, event)
            app.AppFct.selectAllStudies(app);
        end

        % Button pushed function: UpdateTreeButton
        function UpdateTreeButtonPushed(app, event)
            app.AppFct.updateTreeAndCheckNewStudies(app);
        end

        % Button pushed function: UpdateEventsButton
        function UpdateEventsButtonPushed(app, event)
            sFiles = app.AppFct.selectedStudies(app);
            delete(app.EVENTSPanel.Children());
            app.AppFct.createHistoryEventButton(app, sFiles, 'event')
        end

        % Button pushed function: UpdateHistoryButton
        function UpdateHistoryButtonPushed(app, event)
            sFiles = app.AppFct.selectedStudies(app);
            delete(app.HISTORYPanel.Children());
            app.AppFct.createHistoryEventButton(app, sFiles, 'history')
        end

        % Button pushed function: ClearHistoryButton
        function ClearHistoryButtonPushed(app, event)
            delete(app.HISTORYPanel.Children);
        end

        % Button pushed function: ClearEventsButton
        function ClearEventsButtonPushed(app, event)
            delete(app.EVENTSPanel.Children);
        end

        % Button pushed function: ImportinDataBaseButton
        function ImportinDataBaseButtonPushed(app, event)
            
            sFiles = app.AppFct.selectedStudies(app);
            
            % Select Event
            allEvents = app.AppFct.getEvents(app, sFiles);
            app.AppFct.ask_user_list(app, allEvents, 'Import Events', 'on');
            eventCell = app.userAnswer;
            
            eventStr = '';
            for i = 1:length(eventCell)
                eventStr = strcat(eventStr, eventCell{i}, ',');
            end
            eventStr = eventStr(1:end-1);
            
            if isempty(event)
                waitfor(errordlg("Please select at least one event"))
                return
            end    
            
            % Input for Epoch Time
            question = 'Epoch Time in seconds (format -X.X ; X.X): ';
            app.AppFct.ask_user(app, question);
            epochTimeStr = app.userAnswer;
            epochTime = str2double(split(epochTimeStr, ';'));
            
            
            for i = 1:length(sFiles)
                subjectName = sFiles(i).SubjectName;
                app.BasicFct.importEvents(sFiles(i), subjectName, eventStr, epochTime);
            end
            
            app.AppFct.updateTreeAndCheckNewStudies(app);
            
        end

        % Button pushed function: RejectTrialsButton
        function RejectTrialsButtonPushed(app, event)

            sFiles = app.AppFct.selectedStudies(app);

            options = ["MEG gradio", "MEG magneto", "EEG", "SEEG/ECOG", "EOG", "ECG"];
            app.AppFct.ask_user_list(app, options, 'Signal Detection Type', 'on');
            selectedOptions = app.userAnswer;
            
            if isempty(selectedOptions)
                return
            end
            
            if (contains(options(1), selectedOptions))
                
                question = 'MEG gradio minimum (fT): ';
                app.AppFct.ask_user(app, question);
                MEGgradMin = app.userAnswer;
    
                question = 'MEG gradio maximum (fT): ';
                app.AppFct.ask_user(app, question);
                MEGgradMax = app.userAnswer;
                
                MEGgrad = [MEGgradMin MEGgradMax];
               
            end
            
            if (contains(options(2), selectedOptions))
                
                question = 'MEG magneto minimum (fT): ';
                app.AppFct.ask_user(app, question);
                MEGmagnetoMin = app.userAnswer;
                
                question = 'MEG magneto maximum (fT): ';
                app.AppFct.ask_user(app, question);
                MEGmagnetoMax = app.userAnswer;
                
                MEGmagneto = [MEGmagnetoMin MEGmagnetoMax];
                
            end
            
            if (contains(options(3), selectedOptions))
                
                question = 'EEG minimum (µV): ';
                app.AppFct.ask_user(app, question);
                EEGmin = app.userAnswer;
                
                question = 'EEG maximum (µV): ';
                app.AppFct.ask_user(app, question);
                EEGmax = app.userAnswer;
                
                EEG = [EEGmin EEGmax];
                
            end
            
            if (contains(options(4), selectedOptions))
                
                question = 'SEEG/ECOG minimum (µV): ';
                app.AppFct.ask_user(app, question);
                SEEG_ECOGmin = app.userAnswer;
                
                question = 'SEEG/ECOG maximum (µV): ';
                app.AppFct.ask_user(app, question);
                SEEG_ECOGmax = app.userAnswer;
                
                SEEG_ECOG = [SEEG_ECOGmin SEEG_ECOGmax];
                
            end
            
            if (contains(options(5), selectedOptions))
                
                question = 'EOG minimum (µV): ';
                app.AppFct.ask_user(app, question);
                EOGmin = app.userAnswer;
                
                question = 'EOG maximum (µV): ';
                app.AppFct.ask_user(app, question);
                EOGmax = app.userAnswer;
                
                EOG = [EOGmin EOGmax];
                
            end
            
            if (contains(options(6), selectedOptions))
                
                question = 'ECG minimum (mV): ';
                app.AppFct.ask_user(app, question);
                ECGmin = app.userAnswer;
                
                question = 'ECG maximum (mV): ';
                app.AppFct.ask_user(app, question);
                ECGmax = app.userAnswer;
                
                ECG = [ECGmin ECGmax];
                 
            end
            
            app.BasicFct.rejectBadTrials(sFiles, MEGgrad, ...
                            MEGmagneto, EEG, SEEG_ECOG, EOG, ECG);

        end

        % Button pushed function: SelectGoodTrialButton
        function SelectGoodTrialButtonPushed(app, event)
            sFiles = app.AppFct.selectedStudies(app);
            app.AppFct.updateTreeAndCheckNewStudies(app);
            assignin("base", "sFiles", sFiles);
        end

        % Button pushed function: AverageButton
        function AverageButtonPushed(app, event)
            
            sFiles = app.AppFct.selectedStudies(app);
            
            averageTypes = ["Everything", "By Subject", "By Folder (subject average)", ...
                            "By Folder (grand average)", "By Trial Group (folder average)", ...
                            "By Trial Group (subject average)", "By Trial Group (grand average)"];
            app.AppFct.ask_user_list(app, averageTypes, 'Choose type', 'off');
            averageType = app.userAnswer;
            
            if isempty(averageType)
                return
            end
            
            functionTypes = ["Arithmetic Average", "Average Absolute Values", "Root Mean Square", ...
                            "Standard Deviation", "Standard Error", ...
                            "Arithmetic Average + Standard Deviation", ...
                            "Arithmetic Average + Standard Error", "Median"];
            app.AppFct.ask_user_list(app, functionTypes, 'Choose type', 'off');
            functionType = app.userAnswer;
            
            if isempty(functionType)
                return
            end
            
            app.BasicFct.average(sFiles, averageType, functionType);
            
        end

        % Menu selected function: CreateProtocolMenu
        function CreateProtocolMenuSelected(app, event)
            % Input for Protocol Name
            app.AppFct.ask_user(app, 'Enter the Protocol Name')
            ProtocolName = app.userAnswer;
            
            % Do nothing if Protocol Name is empty
            if isempty(ProtocolName)
                return
            end
            
            % If Protocol with same name has already been created
            allProtocols = app.getAllProtocols;
            if (any(strcmp(allProtocols, ProtocolName)))
                message = 'Cannot create Protocol as another Protocol has the same name';
                title = 'Cannot create Protocol';
                waitfor(uiconfirm(app.AnalysisToolUIFigure, message, title));
                return
            end
            
            % Create Protocol
            app.BasicFct.createProtocol(ProtocolName);
            
            % Create Protocol Path
            app.ProtocolPath = fullfile(app.BsDatabasePath, ProtocolName);
            
            % Update Tree and Load Current Protocol (it refreshes the
            % brainstorm interface)
            app.AppFct.updateTree(app);
            app.ReloadCurrentProtocolMenuSelected;
            
            return            
        end

        % Menu selected function: ReloadCurrentProtocolMenu
        function ReloadCurrentProtocolMenuSelected(app, event)
            db_reload_database(bst_get('iProtocol'));
        end

        % Menu selected function: LoadProtocolMenu
        function LoadProtocolMenuSelected(app, event)
            % Display all Protocol
            allProtocols = app.getAllProtocols;
            question = 'Choose Protocol to Load';
            app.AppFct.ask_user_list(app, allProtocols, question, 'off');
            protocolName = app.userAnswer;
            
            if isempty(protocolName)
                return
            end
            protocolIndex = bst_get('Protocol', protocolName);
            
            % Set Protocol
            bst_set('iProtocol', protocolIndex);
            gui_brainstorm('SetCurrentProtocol', protocolIndex);
            
            % Update Protocol Path
            app.ProtocolPath = fullfile(app.BsDatabasePath, protocolName);
            
            % Update Tree
            app.AppFct.updateTree(app);
        end

        % Menu selected function: DeleteProtocolMenu
        function DeleteProtocolMenuSelected(app, event)
             % Display all Protocols
            allProtocols = app.getAllProtocols;
            question = 'Protocol to delete';
            app.AppFct.ask_user_list(app, allProtocols, question, 'on');
            protocolName = app.userAnswer;
            
            if isempty(protocolName)
                return
            end
            message = "You're about to delete the following protocol: ";
            for i = 1:length(protocolName)
                if i ~= 1
                    message = message + ', ';
                end
                message = message + protocolName{i};
            end
            selection = uiconfirm(app.AnalysisToolUIFigure, message, "Protocol Delete");
            
            % Delete Protocol
            if selection == "OK"
                for i = 1:length(protocolName)
                    app.BasicFct.deleteProtocol(protocolName{i});
                end
                uiconfirm(app.AnalysisToolUIFigure, "Protocol has been deleted", "Protocol Delete");         
            end

            % Update Tree
            app.AppFct.updateTree(app);
        end

        % Menu selected function: CreateSubjectMenu
        function CreateSubjectMenuSelected(app, event)
            % Input for Subject Name
            question = 'Subject Name: ';
            app.AppFct.ask_user(app, question);
            subjectName = app.userAnswer;

            if isempty(subjectName)
                return
            end
            
            % Input for type of Anatomy
            choice = ["Specify anatomy folder", "Use default anatomy", "Cancel"];
            rep = uiconfirm(app.AnalysisToolUIFigure, 'Do you have a specific anatomy folder for your subjet or do you want to use the default anatomy ?', ...
                'Create Subject', 'Options', choice);
            
            switch rep
                case choice(1)  % Specific Anatomy
                    title = "Choose the anatomy folder";
                    AnatomyPath = uigetdir(pwd, title);

                    if isequal(AnatomyPath, 0)
                        return
                    end
                    app.BasicFct.importAnatomy(subjectName, AnatomyPath, app.AnatomyFileFormat);
                    
                case choice(2)  % Default Anatomy
                    db_add_subject(subjectName, [], 1, 0);

                case choice(3)  % Cancel
                    return
            end

            % Update Tree
            app.AppFct.updateTree(app);
        end

        % Menu selected function: DeleteSubjectMenu
        function DeleteSubjectMenuSelected(app, event)
            sFiles = app.AppFct.selectedStudies(app);
            subjects = unique({sFiles.SubjectName});
            
            message = "You're about to delete the following subject: ";
            for i = 1:length(subjects)
                if i ~= 1
                    message = message + ', ';
                end
                message = message + subjects{i};
            end
            rep = uiconfirm(app.AnalysisToolUIFigure, message, 'Subject to delete');
            
            if rep == "OK"
                del = 3;
                bst_process('CallProcess', 'process_delete', sFiles, [], ...
                'target', del); % 1: fichier 2: folder (recommended) 3: subjects
            end
                
            
            app.AppFct.updateTree(app);
        end

        % Menu selected function: ReviewRawFilesMenu
        function ReviewRawFilesMenuSelected(app, event)
            Infos = app.AppFct.getCurrentInformations(app);
            
            % List of all Subjects
            subjects = cell(1, length(Infos.Subjects.Subject));
            for i = 1:length(Infos.Subjects.Subject)
                subjects{i} = Infos.Subjects.Subject(i).Name;
            end
            
            % Select Subject associated with the dataset
            app.AppFct.ask_user_list(app, subjects, 'Select Subject', 'off');
            subjectName = app.userAnswer;
            if isempty(subjectName)
                return
            end
            
            % MEG Review Raw Files
            if app.AnalysisType == "MEG"
                 
                 app.AppFct.MEGReviewRawFiles(app, subjectName);
    
            % EEG Review Raw Files
            elseif app.AnalysisType == "EEG"
                
                app.AppFct.EEGReviewRawFiles(app, subjectName);

            end

            app.AppFct.updateTreeAndCheckNewStudies(app);
        end

        % Menu selected function: DeleteStudyMenu
        function DeleteStudyMenuSelected(app, event)
            sFiles = app.AppFct.selectedStudies(app);
            studies = unique({sFiles.Condition});
            
            message = "You're about to delete the following studies: ";
            for i = 1:length(studies)
                if i ~= 1
                    message = message + ', ';
                end
                message = message + studies{i};
            end
            
            rep = uiconfirm(app.AnalysisToolUIFigure, message, 'Subject to delete');
            
            if rep == "OK"
                del = 2;
                bst_process('CallProcess', 'process_delete', sFiles, [], ...
                'target', del); % 1: fichier 2: folder (recommended) 3: subjects
            end
            
            app.AppFct.updateTree(app);
        end

        % Menu selected function: RenameEventMenu
        function RenameEventMenuSelected(app, event)
            sFiles = app.AppFct.selectedStudies(app);

            % Display all Events
            events = app.AppFct.getEvents(app, sFiles);
            question = 'Chose the Event to rename';
            app.AppFct.ask_user_list(app, events, question, 'off');
            oldName = app.userAnswer;
            
            if isempty(oldName)
                return
            end

            % Input for New Name
            question = 'Enter the new Event Name';
            app.AppFct.ask_user(app, question);
            newName = app.userAnswer;

            if isempty(newName)
                waitfor(errordlg("Please enter a new event name."))
                return
            end
            
            % Renaming Event
            app.BasicFct.renameEvent(sFiles, oldName, newName);
        end

        % Menu selected function: DeleteEventMenu
        function DeleteEventMenuSelected(app, event)
            sFiles = app.AppFct.selectedStudies(app);

            % Display all Events
            events = app.AppFct.getEvents(app, sFiles);
            question = 'Select the Event to delete:';
            app.AppFct.ask_user_list(app, events, question, 'on');
            eventToDelete = app.userAnswer;
            
            if isempty(eventToDelete)
                msgbox("Missing event to delete.")
                return
            end

            % Deleting Events
            for i = 1:length(eventToDelete)
                app.BasicFct.deleteEvent(sFiles, eventToDelete{i});
            end
        end

        % Menu selected function: ConverttoBIDSMenu
        function ConverttoBIDSMenuSelected(app, event)
            sFiles = app.AppFct.selectedStudies(app);
            
            % Input for BIDS folder
            question = 'Name of BIDS folder';
            app.AppFct.ask_user(app, question);
            BIDSfolderName = app.userAnswer;
            
            if isempty(BIDSfolderName)
                return
            end
            
            path = uigetdir(pwd, "Where do you want to save the BIDS folder");
            
            if isequal(path, 0)
                return
            end
            
            BIDSpath = fullfile(path, BIDSfolderName, '/');
            
            if app.AnalysisType == "EEG"
                app.EEGConvertToBIDS(sFiles, BIDSpath);
            elseif app.AnalysisType == "MEG"
                app.MEGConvertToBIDS(sFiles, BIDSpath);
            
            end
        end

        % Menu selected function: ConverttoEDFMenu
        function ConverttoEDFMenuSelected(app, event)
            sFiles = app.AppFct.selectedStudies(app);
            
            path = uigetdir(pwd, "Where do you want to save the EDF file");
            
            if isequal(path, 0)
                return
            end
            
            for i = 1:length(sFiles)
                app.AppFct.exportToEDF(app, sFiles(i), path);
            end
        end

        % Menu selected function: OpenBrainstormMenu
        function OpenBrainstormMenuSelected(app, event)
            brainstorm;
        end

        % Menu selected function: AddTagMenu
        function AddTagMenuSelected(app, event)
            sFiles = app.AppFct.selectedStudies(app);
            
            question = 'Tag name to add: ';
            app.AppFct.ask_user(app, question);
            tag = app.userAnswer;
            
            if isempty(Tag)
                return
            end

            choice = ["Add Tag to the file Name", "Add tag to the file path", "Cancel"];

            rep = uiconfirm(app.AnalysisToolUIFigure, 'where do you want to add the tag ?', ...
                'Add Tag', 'Options', choice);

            switch rep
                case choice(1)
                    output = 1;
                case choice(2)
                    output = 2;
                case choice(3)
                    return    
            end
                    
            bst_process('CallProcess', 'process_add_tag', sFiles, [], ...
                    'tag',           tag, ...
                    'output',        output); 
        end

        % Menu selected function: ColorModeMenu
        function ColorModeMenuSelected(app, event)
            
            if app.ColorModeMenu.Text == "Dark Mode"
                backColor = [0 0 0];
                textColor = [1 1 1];
                textColorHyperLink = [0 0.8 1];
                app.ColorModeMenu.Text = "Bright Mode";
                app.cervo.ImageSource = 'CERVO_Dark.png';
                app.braincanada.ImageSource = 'BrainCanada_Dark.png';
                app.ulaval.ImageSource = 'Ulaval_Dark.png';
            elseif app.ColorModeMenu.Text == "Bright Mode"
                backColor = [1 1 1];
                textColor = [0 0 0];
                textColorHyperLink = [0 0.4 0.8];
                app.ColorModeMenu.Text = "Dark Mode";            
                app.cervo.ImageSource = 'CERVO_Bright.png';
                app.braincanada.ImageSource = 'BrainCanada_Bright.png';
                app.ulaval.ImageSource = 'Ulaval_Bright.png';
            end

            app.AppFct.switchColorModeForParent(app, app.AnalysisToolUIFigure, ...
                backColor, textColor, textColorHyperLink);
            
        end

        % Close request function: AnalysisToolUIFigure
        function AnalysisToolUIFigureCloseRequest(app, event)

            rep = uiconfirm(app.AnalysisToolUIFigure, 'Do you want to close the app ?', 'Close request');

            if rep == "OK"
                delete(app)
            end

        end

        % Menu selected function: SelectComponentsMenu
        function SelectComponentsMenuSelected(app, event)
            sFiles = app.AppFct.selectedStudies(app);
            app.BasicFct.viewComponents(sFiles);
        end

        % Menu selected function: EEGMenu
        function EEGMenuSelected(app, event)
            app.AppFct.switchAnalysisTab(app, 'EEG');
            app.EEGMenu.Checked = true;
            app.MEGMenu.Checked = false;
        end

        % Menu selected function: MEGMenu
        function MEGMenuSelected(app, event)
            app.AppFct.switchAnalysisTab(app, 'MEG');
            app.EEGMenu.Checked = false;
            app.MEGMenu.Checked = true;
        end

        % Button pushed function: ComputeSourcesButton
        function ComputeSourcesButtonPushed(app, event)
            sFiles = app.AppFct.selectedStudies(app);
            
            app.BasicFct.computeSources(sFiles);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AnalysisToolUIFigure = uifigure('Visible', 'off');
            app.AnalysisToolUIFigure.Name = 'Analysis Tool :)';
            app.AnalysisToolUIFigure.CloseRequestFcn = createCallbackFcn(app, @AnalysisToolUIFigureCloseRequest, true);
            app.ProtocolMenu = uimenu(app.AnalysisToolUIFigure);
            app.ProtocolMenu.Text = 'Protocol';
            app.CreateProtocolMenu = uimenu(app.ProtocolMenu);
            app.CreateProtocolMenu.MenuSelectedFcn = createCallbackFcn(app, @CreateProtocolMenuSelected, true);
            app.CreateProtocolMenu.Text = 'Create Protocol';
            app.ReloadCurrentProtocolMenu = uimenu(app.ProtocolMenu);
            app.ReloadCurrentProtocolMenu.MenuSelectedFcn = createCallbackFcn(app, @ReloadCurrentProtocolMenuSelected, true);
            app.ReloadCurrentProtocolMenu.Text = 'Reload Current Protocol';
            app.LoadProtocolMenu = uimenu(app.ProtocolMenu);
            app.LoadProtocolMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadProtocolMenuSelected, true);
            app.LoadProtocolMenu.Text = 'Load Protocol';
            app.DeleteProtocolMenu = uimenu(app.ProtocolMenu);
            app.DeleteProtocolMenu.MenuSelectedFcn = createCallbackFcn(app, @DeleteProtocolMenuSelected, true);
            app.DeleteProtocolMenu.Text = 'Delete Protocol';
            app.SubjectMenu = uimenu(app.AnalysisToolUIFigure);
            app.SubjectMenu.Text = 'Subject';
            app.CreateSubjectMenu = uimenu(app.SubjectMenu);
            app.CreateSubjectMenu.MenuSelectedFcn = createCallbackFcn(app, @CreateSubjectMenuSelected, true);
            app.CreateSubjectMenu.Text = 'Create Subject';
            app.DeleteSubjectMenu = uimenu(app.SubjectMenu);
            app.DeleteSubjectMenu.MenuSelectedFcn = createCallbackFcn(app, @DeleteSubjectMenuSelected, true);
            app.DeleteSubjectMenu.Text = 'Delete Subject';
            app.StudyMenu = uimenu(app.AnalysisToolUIFigure);
            app.StudyMenu.Text = 'Study';
            app.ReviewRawFilesMenu = uimenu(app.StudyMenu);
            app.ReviewRawFilesMenu.MenuSelectedFcn = createCallbackFcn(app, @ReviewRawFilesMenuSelected, true);
            app.ReviewRawFilesMenu.Text = 'Review Raw Files';
            app.SelectComponentsMenu = uimenu(app.StudyMenu);
            app.SelectComponentsMenu.MenuSelectedFcn = createCallbackFcn(app, @SelectComponentsMenuSelected, true);
            app.SelectComponentsMenu.Text = 'Select Components';
            app.DeleteStudyMenu = uimenu(app.StudyMenu);
            app.DeleteStudyMenu.MenuSelectedFcn = createCallbackFcn(app, @DeleteStudyMenuSelected, true);
            app.DeleteStudyMenu.Text = 'Delete Study';
            app.EventMenu = uimenu(app.AnalysisToolUIFigure);
            app.EventMenu.Text = 'Event';
            app.RenameEventMenu = uimenu(app.EventMenu);
            app.RenameEventMenu.MenuSelectedFcn = createCallbackFcn(app, @RenameEventMenuSelected, true);
            app.RenameEventMenu.Text = 'Rename Event';
            app.DeleteEventMenu = uimenu(app.EventMenu);
            app.DeleteEventMenu.MenuSelectedFcn = createCallbackFcn(app, @DeleteEventMenuSelected, true);
            app.DeleteEventMenu.Text = 'Delete Event';
            app.ConversionMenu = uimenu(app.AnalysisToolUIFigure);
            app.ConversionMenu.Text = 'Conversion';
            app.ConverttoEDFMenu = uimenu(app.ConversionMenu);
            app.ConverttoEDFMenu.MenuSelectedFcn = createCallbackFcn(app, @ConverttoEDFMenuSelected, true);
            app.ConverttoEDFMenu.Text = 'Convert to EDF';
            app.ConverttoBIDSMenu = uimenu(app.ConversionMenu);
            app.ConverttoBIDSMenu.MenuSelectedFcn = createCallbackFcn(app, @ConverttoBIDSMenuSelected, true);
            app.ConverttoBIDSMenu.Text = 'Convert to BIDS';
            app.OptionsMenu = uimenu(app.AnalysisToolUIFigure);
            app.OptionsMenu.Text = 'Options';
            app.AnalysisTypeMenu = uimenu(app.OptionsMenu);
            app.AnalysisTypeMenu.Text = 'Analysis Type';
            app.EEGMenu = uimenu(app.AnalysisTypeMenu);
            app.EEGMenu.MenuSelectedFcn = createCallbackFcn(app, @EEGMenuSelected, true);
            app.EEGMenu.Separator = 'on';
            app.EEGMenu.Text = 'EEG';
            app.MEGMenu = uimenu(app.AnalysisTypeMenu);
            app.MEGMenu.MenuSelectedFcn = createCallbackFcn(app, @MEGMenuSelected, true);
            app.MEGMenu.Checked = 'on';
            app.MEGMenu.Text = 'MEG';
            app.OpenBrainstormMenu = uimenu(app.OptionsMenu);
            app.OpenBrainstormMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenBrainstormMenuSelected, true);
            app.OpenBrainstormMenu.Text = 'Open Brainstorm';
            app.AddTagMenu = uimenu(app.OptionsMenu);
            app.AddTagMenu.MenuSelectedFcn = createCallbackFcn(app, @AddTagMenuSelected, true);
            app.AddTagMenu.Text = 'Add Tag';
            app.ColorModeMenu = uimenu(app.OptionsMenu);
            app.ColorModeMenu.MenuSelectedFcn = createCallbackFcn(app, @ColorModeMenuSelected, true);
            app.ColorModeMenu.Text = 'Dark Mode';
            app.TabGroup2 = uitabgroup(app.AnalysisToolUIFigure);
            app.TabGroup2.SelectionChangedFcn = createCallbackFcn(app, @TabGroup2SelectionChanged, true);
            app.EEGTabGr2 = uitab(app.TabGroup2);
            app.EEGTabGr2.Title = 'EEG';
            app.AverageReferenceButton = uibutton(app.EEGTabGr2, 'push');
            app.AverageReferenceButton.ButtonPushedFcn = createCallbackFcn(app, @AverageReferenceButtonPushed, true);
            app.AverageReferenceButton.Text = 'Average Reference';
            app.ICAEEGButton = uibutton(app.EEGTabGr2, 'push');
            app.ICAEEGButton.ButtonPushedFcn = createCallbackFcn(app, @ICAEEGButtonPushed, true);
            app.ICAEEGButton.Text = 'ICA';
            app.MEGTabGr2 = uitab(app.TabGroup2);
            app.MEGTabGr2.Title = 'MEG';
            app.RemoveSimultaneousEventsButton = uibutton(app.MEGTabGr2, 'push');
            app.RemoveSimultaneousEventsButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveSimultaneousEventsButtonPushed, true);
            app.RemoveSimultaneousEventsButton.Text = 'Remove Simultaneous Events';
            app.DetectArtifactButton = uibutton(app.MEGTabGr2, 'push');
            app.DetectArtifactButton.ButtonPushedFcn = createCallbackFcn(app, @DetectArtifactButtonPushed, true);
            app.DetectArtifactButton.Text = 'Detect Artifact';
            app.SSPButton = uibutton(app.MEGTabGr2, 'push');
            app.SSPButton.ButtonPushedFcn = createCallbackFcn(app, @SSPButtonPushed, true);
            app.SSPButton.Text = 'SSP';
            app.ICAMEGButton = uibutton(app.MEGTabGr2, 'push');
            app.ICAMEGButton.ButtonPushedFcn = createCallbackFcn(app, @ICAMEGButtonPushed, true);
            app.ICAMEGButton.Text = 'ICA';
            app.TabGroup1 = uitabgroup(app.AnalysisToolUIFigure);
            app.TabGroup1.SelectionChangedFcn = createCallbackFcn(app, @TabGroup1SelectionChanged, true);
            app.EEGTabGr1 = uitab(app.TabGroup1);
            app.EEGTabGr1.Title = 'EEG';
            app.AddEEGPositionsButton = uibutton(app.EEGTabGr1, 'push');
            app.AddEEGPositionsButton.ButtonPushedFcn = createCallbackFcn(app, @AddEEGPositionsButtonPushed, true);
            app.AddEEGPositionsButton.Text = 'Add EEG Positions';
            app.RefineRegistrationButton = uibutton(app.EEGTabGr1, 'push');
            app.RefineRegistrationButton.ButtonPushedFcn = createCallbackFcn(app, @RefineRegistrationButtonPushed, true);
            app.RefineRegistrationButton.Text = 'Refine Registration';
            app.ProjectElectrodesonScalpButton = uibutton(app.EEGTabGr1, 'push');
            app.ProjectElectrodesonScalpButton.ButtonPushedFcn = createCallbackFcn(app, @ProjectElectrodesonScalpButtonPushed, true);
            app.ProjectElectrodesonScalpButton.Text = 'Project Electrodes on Scalp';
            app.DetectHeartbeatsButton = uibutton(app.EEGTabGr1, 'push');
            app.DetectHeartbeatsButton.ButtonPushedFcn = createCallbackFcn(app, @DetectHeartbeatsButtonPushed, true);
            app.DetectHeartbeatsButton.Text = 'Detect Heartbeats';
            app.ImportEventsButton = uibutton(app.EEGTabGr1, 'push');
            app.ImportEventsButton.ButtonPushedFcn = createCallbackFcn(app, @ImportEventsButtonPushed, true);
            app.ImportEventsButton.Text = 'Import Events';
            app.MEGTabGr1 = uitab(app.TabGroup1);
            app.MEGTabGr1.Title = 'MEG';
            app.ConvertEpochstoContinueButton = uibutton(app.MEGTabGr1, 'push');
            app.ConvertEpochstoContinueButton.ButtonPushedFcn = createCallbackFcn(app, @ConvertEpochstoContinueButtonPushed, true);
            app.ConvertEpochstoContinueButton.Text = 'Convert Epochs to Continue';
            app.FREQUENCYPanel = uipanel(app.AnalysisToolUIFigure);
            app.FREQUENCYPanel.Title = 'FREQUENCY';
            app.NotchFilterButton = uibutton(app.FREQUENCYPanel, 'push');
            app.NotchFilterButton.ButtonPushedFcn = createCallbackFcn(app, @NotchFilterButtonPushed, true);
            app.NotchFilterButton.Text = 'Notch Filter';
            app.BandpassFilterButton = uibutton(app.FREQUENCYPanel, 'push');
            app.BandpassFilterButton.ButtonPushedFcn = createCallbackFcn(app, @BandpassFilterButtonPushed, true);
            app.BandpassFilterButton.Text = 'Band-pass Filter';
            app.PowerSpectrumDensityButton = uibutton(app.FREQUENCYPanel, 'push');
            app.PowerSpectrumDensityButton.ButtonPushedFcn = createCallbackFcn(app, @PowerSpectrumDensityButtonPushed, true);
            app.PowerSpectrumDensityButton.Text = 'Power Spectrum Density';
            app.HISTORYPanel = uipanel(app.AnalysisToolUIFigure);
            app.HISTORYPanel.Title = 'HISTORY';
            app.EVENTSPanel = uipanel(app.AnalysisToolUIFigure);
            app.EVENTSPanel.Title = 'EVENTS';
            app.PipelineTextArea = uitextarea(app.AnalysisToolUIFigure);
            app.PipelineTextArea.Value = {'NO PIPELINE LOADED'};
            app.ClearHistoryButton = uibutton(app.AnalysisToolUIFigure, 'push');
            app.ClearHistoryButton.ButtonPushedFcn = createCallbackFcn(app, @ClearHistoryButtonPushed, true);
            app.ClearHistoryButton.Text = 'Clear History';
            app.ClearEventsButton = uibutton(app.AnalysisToolUIFigure, 'push');
            app.ClearEventsButton.ButtonPushedFcn = createCallbackFcn(app, @ClearEventsButtonPushed, true);
            app.ClearEventsButton.Text = 'Clear Events';
            app.UpdateHistoryButton = uibutton(app.AnalysisToolUIFigure, 'push');
            app.UpdateHistoryButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateHistoryButtonPushed, true);
            app.UpdateHistoryButton.Text = 'Update History';
            app.UpdateEventsButton = uibutton(app.AnalysisToolUIFigure, 'push');
            app.UpdateEventsButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateEventsButtonPushed, true);
            app.UpdateEventsButton.Text = 'Update Events';
            app.PIPELINEPanel = uipanel(app.AnalysisToolUIFigure);
            app.PIPELINEPanel.Title = 'PIPELINE';
            app.OpenPipelineBuilderButton = uibutton(app.PIPELINEPanel, 'push');
            app.OpenPipelineBuilderButton.ButtonPushedFcn = createCallbackFcn(app, @OpenPipelineBuilderButtonPushed, true);
            app.OpenPipelineBuilderButton.Text = 'Open Pipeline Builder';
            app.ClearPipelineButton = uibutton(app.PIPELINEPanel, 'push');
            app.ClearPipelineButton.ButtonPushedFcn = createCallbackFcn(app, @ClearPipelineButtonPushed, true);
            app.ClearPipelineButton.Text = 'Clear Pipeline';
            app.ImportPipelineButton = uibutton(app.PIPELINEPanel, 'push');
            app.ImportPipelineButton.ButtonPushedFcn = createCallbackFcn(app, @ImportPipelineButtonPushed, true);
            app.ImportPipelineButton.Text = 'Import Pipeline';
            app.RunPipelineButton = uibutton(app.PIPELINEPanel, 'push');
            app.RunPipelineButton.ButtonPushedFcn = createCallbackFcn(app, @RunPipelineButtonPushed, true);
            app.RunPipelineButton.Text = 'Run Pipeline';
            app.Tree = uitree(app.AnalysisToolUIFigure, 'checkbox');
            app.Hyperlink = uihyperlink(app.AnalysisToolUIFigure);
            app.Hyperlink.URL = 'https://github.com/CorentinLabelle/EEG-MEG-Analysis-Tool';
            app.Hyperlink.Text = 'GitHub/CorentinLabelle/AnalysisTool';
            app.Hyperlink2 = uihyperlink(app.AnalysisToolUIFigure);
            app.Hyperlink2.URL = 'https://neuroimage.usc.edu/brainstorm/Tutorials';
            app.Hyperlink2.Text = 'Brainstorm MEG Tutorial';
            app.Hyperlink2_2 = uihyperlink(app.AnalysisToolUIFigure);
            app.Hyperlink2_2.URL = 'https://neuroimage.usc.edu/brainstorm/Tutorials/Epilepsy';
            app.Hyperlink2_2.Text = 'Brainstorm EEG Tutorial';
            app.ImportinDataBaseButton = uibutton(app.AnalysisToolUIFigure, 'push');
            app.ImportinDataBaseButton.ButtonPushedFcn = createCallbackFcn(app, @ImportinDataBaseButtonPushed, true);
            app.ImportinDataBaseButton.Text = 'Import in DataBase';
            app.POSTPROCESSPanel = uipanel(app.AnalysisToolUIFigure);
            app.POSTPROCESSPanel.Title = 'POST-PROCESS';
            app.RejectTrialsButton = uibutton(app.POSTPROCESSPanel, 'push');
            app.RejectTrialsButton.ButtonPushedFcn = createCallbackFcn(app, @RejectTrialsButtonPushed, true);
            app.RejectTrialsButton.Text = 'Reject Trials';
            app.SelectGoodTrialButton = uibutton(app.POSTPROCESSPanel, 'push');
            app.SelectGoodTrialButton.ButtonPushedFcn = createCallbackFcn(app, @SelectGoodTrialButtonPushed, true);
            app.SelectGoodTrialButton.Text = 'Select Good Trial';
            app.AverageButton = uibutton(app.POSTPROCESSPanel, 'push');
            app.AverageButton.ButtonPushedFcn = createCallbackFcn(app, @AverageButtonPushed, true);
            app.AverageButton.Text = 'Average';
            app.ComputeSourcesButton = uibutton(app.POSTPROCESSPanel, 'push');
            app.ComputeSourcesButton.ButtonPushedFcn = createCallbackFcn(app, @ComputeSourcesButtonPushed, true);
            app.ComputeSourcesButton.Text = 'Compute Sources';
            app.PROTOCOLPanel = uipanel(app.AnalysisToolUIFigure);
            app.PROTOCOLPanel.Title = 'PROTOCOL';
            app.SelectDeselectallButton = uibutton(app.PROTOCOLPanel, 'push');
            app.SelectDeselectallButton.ButtonPushedFcn = createCallbackFcn(app, @SelectDeselectallButtonPushed, true);
            app.SelectDeselectallButton.Text = 'Select/Deselect all';
            app.UpdateTreeButton = uibutton(app.PROTOCOLPanel, 'push');
            app.UpdateTreeButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateTreeButtonPushed, true);
            app.UpdateTreeButton.Text = 'Update Tree';
            app.CurrentProtocolEditField = uieditfield(app.PROTOCOLPanel, 'text');
            app.CurrentProtocolEditField.Tag = 'CurrentProto';
            app.CurrentProtocolEditField.Editable = 'off';
            app.CurrentProtocolLabel = uilabel(app.PROTOCOLPanel);
            app.CurrentProtocolLabel.Text = 'Current Protocol:';
            app.cervo = uiimage(app.AnalysisToolUIFigure);
            app.cervo.ImageSource = 'CERVO_Bright.png';
            app.braincanada = uiimage(app.AnalysisToolUIFigure);
            app.braincanada.ImageSource = 'BrainCanada_Bright.png';
            app.ulaval = uiimage(app.AnalysisToolUIFigure);
            app.ulaval.ImageSource = 'Ulaval_Bright.png';
            % Show the figure after all components are created
            app.AnalysisToolUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Analysis_Tool

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.AnalysisToolUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.AnalysisToolUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AnalysisToolUIFigure)
        end
    end
end