classdef EEG_MEG_ANALYSIS < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        bids_edfButton                  matlab.ui.control.Button
        OpenPIPBUILDButton              matlab.ui.control.Button
        Hyperlink                       matlab.ui.control.Hyperlink
        TESTINGButton                   matlab.ui.control.Button
        EventsDetectedPanel             matlab.ui.container.Panel
        HistoryPanel                    matlab.ui.container.Panel
        MEGHyperlink                    matlab.ui.control.Hyperlink
        EEGHyperlink                    matlab.ui.control.Hyperlink
        ProtocolPanel                   matlab.ui.container.Panel
        DeleteProtocolButton            matlab.ui.control.Button
        LoadProtocolButton              matlab.ui.control.Button
        CreateProtocolButton            matlab.ui.control.Button
        SubjectStudyPanel               matlab.ui.container.Panel
        ReviewRawFilesButton            matlab.ui.control.Button
        DeleteStudySubjectButton        matlab.ui.control.Button
        CreateSubjectButton             matlab.ui.control.Button
        Panel                           matlab.ui.container.Panel
        ColorMode                       matlab.ui.control.Button
        ImportinDataBaseButton          matlab.ui.control.Button
        UpdateHistoryButton             matlab.ui.control.Button
        ConverttoBIDSButton             matlab.ui.control.Button
        UpdateEventsButton              matlab.ui.control.Button
        AddTagButton                    matlab.ui.control.Button
        TypeofanalysisDropDownLabel     matlab.ui.control.Label
        TypeofanalysisDropDown          matlab.ui.control.DropDown
        OpenBrainstormButton            matlab.ui.control.Button
        FrequencyAnalysisPanel          matlab.ui.container.Panel
        PowerSpectrumDensityButton      matlab.ui.control.Button
        BandpassFilterButton            matlab.ui.control.Button
        NotchFilterButton               matlab.ui.control.Button
        TabGroup1                       matlab.ui.container.TabGroup
        EEGTabGr1                       matlab.ui.container.Tab
        ImportEventsButton              matlab.ui.control.Button
        DetectHeartbeatsButton          matlab.ui.control.Button
        ProjectElectrodesonScalpButton  matlab.ui.control.Button
        RefineRegistrationButton        matlab.ui.control.Button
        AddEEGPositionsButton           matlab.ui.control.Button
        MEGTabGr1                       matlab.ui.container.Tab
        ConvertEpochstoContinueButton   matlab.ui.control.Button
        SelectDeselectallButton         matlab.ui.control.Button
        TabGroup2                       matlab.ui.container.TabGroup
        EEGTabGr2                       matlab.ui.container.Tab
        ICAButton                       matlab.ui.control.Button
        AverageReferenceButton          matlab.ui.control.Button
        MEGTabGr2                       matlab.ui.container.Tab
        ICAButton_2                     matlab.ui.control.Button
        SSPButton                       matlab.ui.control.Button
        DetectArtifactButton            matlab.ui.control.Button
        RemoveSimultaneousEventsButton  matlab.ui.control.Button
        DeleteEventsButton              matlab.ui.control.Button
        RenameEventsButton              matlab.ui.control.Button
        CurrentSubjectLabel             matlab.ui.control.Label
        CurrentSubjectEditField         matlab.ui.control.EditField
        CurrentProtocolLabel            matlab.ui.control.Label
        CurrentProtocolEditField        matlab.ui.control.EditField
        UpdateTreeButton                matlab.ui.control.Button
        Tree                            matlab.ui.container.CheckBoxTree
    end


    properties (Access = private)

        % Global variables initialized as empty.

        AnalysisType = "";
        SensorType = "";
        FileFormat = "";
        AnatomyFileFormat = "";
        ProtocolPath = "";
        UseDefaultAnatomy = "";
        
        WorkingFolderPath = "/mnt/3b5a15cf-20ff-4840-8d84-ddbd428344e9/ALAB1/"; % This property is modified once the student 
                                                                               % select the working folder
        BrainstormPath = "/mnt/3b5a15cf-20ff-4840-8d84-ddbd428344e9/ALAB1/rg/toolboxes/brainstorm3/";
        bs_dbPath = "/mnt/3b5a15cf-20ff-4840-8d84-ddbd428344e9/ALAB1/rg/bs_db/";
        
        
        %AppFolderName = "/_AnalysisToolFiles";
        AppFolderName = "";
        AppFolderPath = "";

    end

    methods (Access = private)

        function []  = switchAnalysisTab(app, trigger)
            % This function is called when the type of
            % analysis is changed. When called, we specify which component triggered the function.
            
            % Input>    [string]: The component that triggered the function
        
            switch trigger
                case "TabGroup1"
                    analysisType = app.TabGroup1.SelectedTab.Title;
                case "TabGroup2"
                    analysisType = app.TabGroup2.SelectedTab.Title;
                case "DropDown"
                    analysisType = app.TypeofanalysisDropDown.Value;
                case "EEG"
                    analysisType = 'EEG';
                case "MEG"
                    analysisType = 'MEG';
            end

            switch analysisType
                case 'EEG'
                    app.TabGroup1.SelectedTab = app.EEGTabGr1;
                    app.TabGroup2.SelectedTab = app.EEGTabGr2;
                    app.TypeofanalysisDropDown.Value = 'EEG';

                    app.AnalysisType = 'EEG';
                    app.SensorType = 'EEG';
                    app.FileFormat = 'EEG-BRAINAMP';    %ou EEG-DELTAMED. Default value, it is modified when reviewing raw file.
                    app.AnatomyFileFormat = 'FreeSurfer-fast';

                case 'MEG'
                    app.TabGroup1.SelectedTab = app.MEGTabGr1;
                    app.TabGroup2.SelectedTab = app.MEGTabGr2;
                    app.TypeofanalysisDropDown.Value = 'MEG';

                    app.AnalysisType = 'MEG';
                    app.SensorType = 'MEG';
                    app.FileFormat = 'CTF';
                    app.AnatomyFileFormat = 'FreeSurfer';
            end
        end

        function Infos = getCurrentInformations(app)
            % Create (or update if already created) a structure with the
            % current informations
            
            % Output> [structure]: Current informations
            %   fields: CurrentSubject, ProtocolInfo, Studies, Subjects

            Infos.CurrentSubject = bst_get('Subject');
            Infos.ProtocolInfo = bst_get('ProtocolInfo');
            Infos.Studies = bst_get('ProtocolStudies');
            Infos.Subjects =  bst_get('ProtocolSubjects');

            % Check if there is an empty field.
            index = find(structfun(@isempty, Infos), 1);
            if isempty(index)
                return                
            end

            switch index
                case 1
                    errorMessage = 'CurrentSubject field is empty';
                case 2
                    errorMessage = 'ProtocolInfo field is empty';
                case 3
                    errorMessage = 'ProtocolStudies field is empty';
                case 4
                    errorMessage = 'ProtocolSubjects field is empty';
            end

            if any(structfun(@isempty, Infos))
                uialert(app.UIFigure, errorMessage, 'Error');
            end
        end

        function [] = updateTree(app)
            % Update Informations in Tree

            Infos = app.getCurrentInformations;

            % Delete current Tree
            app.Tree.Children.delete;

            % Create a temporary node
            temporaryNode = uitreenode(app.Tree);
            temporaryNode.Text = "Temporary Node";

            % Iterate on all Studies in Protocol
            for i = 1:length(Infos.Studies.Study)
                % Get Subject Nodes already in Tree
                subjectNodes = app.Tree.Children;
                subjectNames = {subjectNodes.Text};

                % Get Subject Name of Study
                subjectNameChar = strsplit(Infos.Studies.Study(i).BrainStormSubject, '/');
                subjectNameString = convertCharsToStrings(subjectNameChar{1});

                % Check if Subject Name is in Subject Nodes
                index = find(contains(subjectNames,subjectNameString));
                if isempty(index)
                    % Create Subject Node in Tree
                    nodeSubject = uitreenode(app.Tree);
                    nodeSubject.Text = subjectNameString;
                else
                    % Get Subject node from Tree
                    nodeSubject = subjectNodes(index);
                end
                % Create Study Node in Subject Node
                nodeStudy = uitreenode(nodeSubject);
                nodeStudy.Text = Infos.Studies.Study(i).Name;
            end
            % Delete Temporary Node
            temporaryNode.delete
            
            expand(app.Tree, "all");
            
            app.updateCurrentProtocolCurrentSubject;
            
        end

        function sFiles = selectedStudies(app)
            % Create (or update if already created) a cell containing the
            % current studies that are checked in the tree.
            
            % Output> [cell]: Paths to Raw Files (subject/study/data_0study...) that were copied in the Protocol Folder

            % Get all Checked Nodes
            sStudies_checked = app.Tree.CheckedNodes;
            
            sFiles = cell(1, length(sStudies_checked));
            for i = 1:length(sStudies_checked)

                % Remove Subject Nodes that are checked (we keep only Study
                % Nodes)
                if class(sStudies_checked(i).Parent) == "matlab.ui.container.CheckBoxTree"
                    continue
                end

                % Concatenation to create File Path
                study_path = strcat(sStudies_checked(i).Parent.Text, '/', sStudies_checked(i).Text);
                
                if contains(sStudies_checked(i).Text, 'intra') || contains(sStudies_checked(i).Text, 'default')
                    study_folder = strcat(study_path, '/brainstormstudy.mat');
                else
                    study_folder = strcat(study_path, '/data_0raw_', sStudies_checked(i).Text(5:end), '.mat');
                end

                % Save path in sFiles
                sFiles{i} = study_folder;
            end
            
            sFiles = sFiles(~cellfun(@isempty, sFiles));

            if isempty(sFiles)
                uialert(app.UIFigure, 'There no study selected, this might cause an error!', 'Error');
                uiwait(gcbf)
            end 
            
            
        end
        
        function [] = checkNodesFromSFiles(app, sFilesO)
            % Checks the new node in the the tree. sFilesO is the output of
            % a process that creates a new study (filters, etc.). This
            % function checks the new studies added to the tree.
            
            app.Tree.CheckedNodes = [];
            to_check = matlab.ui.container.TreeNode.empty();
            nbNodeToCheck = 0;            
            
            for i = 1:length(sFilesO)
                [subjectName, studyName] = app.getStudyNameFromsFile(sFilesO(i).FileName);

                subjectsNodes = app.Tree.Children;
                for j = 1:length(subjectsNodes)
                    
                    studyNodes = subjectsNodes(j).Children;
                    for k = 1:length(studyNodes)

                        if strcmp(studyNodes(k).Parent.Text, subjectName) && strcmp(studyNodes(k).Text(2:end), studyName)
                            
                            nbNodeToCheck = nbNodeToCheck + 1;
                            to_check(nbNodeToCheck) = studyNodes(k);
                            break  
                        end
                    end
                end
            end
            app.Tree.CheckedNodes = to_check;

        end
        
        function [] = updateCurrentProtocolCurrentSubject(app)
            % Update the Current Protocol and Current Subject values in the text boxes.
        
            Infos = app.getCurrentInformations;
        
            app.CurrentProtocolEditField.Value = Infos.ProtocolInfo.Comment;
        
            if ~isempty(Infos.CurrentSubject)
                app.CurrentSubjectEditField.Value = Infos.CurrentSubject.Name;
            end
        
            app.ProtocolPath = strcat('/mnt/3b5a15cf-20ff-4840-8d84-ddbd428344e9/ALAB1/rg/bs_db/', Infos.ProtocolInfo.Comment, '/');
        
        end 

        function [] = selectAllStudies(app)
            
            % Select or Deselect all the studies in the tree.
            
            
            if isempty(app.Tree.CheckedNodes)
                app.Tree.CheckedNodes = app.Tree.Children;
            else
                app.Tree.CheckedNodes = [];
            end

            
        end

        function Path = getFilePath(app, title, type, extension)
            % Open a file manager to select a file or a folder. 
            % The file managers only shows certain file/folder based on user input (extension variable). 
            
            % Input>    title [str]: Window title
            %           type [str]: Type of file (data or anatomy)
            %           extension [str]: File extension

            % Output>   Path [char]: File Path

            % If extension variable not given
            if ~exist('extension','var')
                extension = '*';
            end

            startingFolder = app.WorkingFolderPath;

            % Import data in MEG Analysis
            if type == "data" && app.AnalysisType == "MEG"
                folder = uigetdir(startingFolder, title);
                display(folder)
                if isequal(folder, 0)
                    uialert(app.UIFigure, 'No Path has been selected', 'Error');
                    uiwait(gcbf)
                end
                
                % Keep only data files (.ds)
                FilesName = dir([folder filesep '*.' 'ds']);
                
                % Saving FileName in a variable (Path)
                Path = cell(1, length(FilesName));
                for i = 1:length(FilesName)
                    Path(i) = {strcat(folder, '/', FilesName(i).name)};
                end

            % Import data in EEG Analysis
            elseif type == "data" && app.AnalysisType == "EEG"
                [File, folderPath] = uigetfile(extension, title, startingFolder);
                
                if isequal(File, 0)
                    uialert(app.UIFigure, 'No file has been selected!', 'Error');
                    uiwait(gcbf)
                end
                
                Path = fullfile(folderPath, File);

            elseif type == "anatomy"
                Path = uigetdir(startingFolder, title);

            elseif type == "studentFolder"
                assignin("base", "startingfolder", startingFolder)
                Path = uigetdir(startingFolder, title);
            end

        end

        function sFiles = reviewRawFiles(app, SubjectName, RawFilePath, ChannelAlign)
            % Create link to Raw Data. The Raw Data are copied in the
            % Protocol folder (in /bs_db/).
            
            % Input>    RawFilePath [cell]: Path to Raw Files
            %           ChannelAlign [double]: 0 or 1

            % Output>   RawImport [struct]: sFiles of Raw Files
           

            sFiles = bst_process('CallProcess', 'process_import_data_raw', [], [], ...
                'subjectname',  SubjectName, ...
                'datafile',     {RawFilePath, app.FileFormat}, ...
                'channelreplace', 1, ...
                'channelalign', ChannelAlign); % Align channel on MRI using fiducial points

        end

        function [] = importAnatomy(app, SubjectName, AnatomyPath)
            % Import the anatomy of the subject.
            
            % Input>    SubjectName [char]: Subject Name
            %           AnatomyPath [char]: Path to folder containing the
            %                               anatomy.
                

            if app.AnalysisType == "MEG"

                bst_process('CallProcess', 'process_import_anatomy', [], [], ...
                    'subjectname', SubjectName, ...
                    'mrifile', {AnatomyPath, app.AnatomyFileFormat}, ...
                    'nvertices', 15000, ...
                    'nas', [127, 213, 139], ...
                    'lpa', [52, 113, 96], ...
                    'rpa', [202, 113, 91], ...
                    'ac', [127 119 149], ...
                    'pc', [128 93 141], ...
                    'ih', [131 114 206]);

            elseif app.AnalysisType == "EEG"

                bst_process('CallProcess', 'process_import_anatomy', [], [], ...
                    'subjectname', SubjectName, ...
                    'mrifile', {AnatomyPath, app.AnatomyFileFormat}, ...
                    'nvertices', 15000);
            end
        end

        function [] = detectArtifact(app, sFiles, type, channel, name)
            % Detect artifact for the specified study.
            
            % Input>    sFiles [cell]: sFiles
            %           type [string]: type of artifact
            %           channel [string]: channel associated with type
            %           name [string]: name of the events detected
            
            
            if type == "cardiac"
                bst_process('CallProcess', 'process_evt_detect_ecg', sFiles, [], ...
                    'channelname', channel, ...
                    'timewindow',  [], ...
                    'eventname',   name);

            elseif type == "blink"
                bst_process('CallProcess', 'process_evt_detect_eog', sFiles, [], ...
                    'channelname', channel, ...
                    'timewindow',  [], ...
                    'eventname',   name);
                
            % If user wants to detect artifact other than 'Cardiac' or
            % 'Blink'.
            else
                choice = ["YES", "NO"];

                repL = uiconfirm(app.UIFigure, 'Do you want to detect artifact of low frequency (eye/teeth movement) ?', ...
                    'What to detect?', 'Options', choice);

                repH = uiconfirm(app.UIFigure, 'Do you want to detect artifact of high frequency (muscle movement/sensor artifacts) ?', ...
                    'What to detect?', 'Options', choice);

                LowFreq = 0;
                if repL == choice{1}
                    LowFreq = 1;
                end

                HighFreq = 0;
                if repH == choice{1}
                    HighFreq = 1;
                end

                bst_process('CallProcess', 'process_evt_detect_badsegment', sFiles, [], ...
                    'timewindow',  [], ...
                    'sensortypes', 'MEG', ...
                    'threshold',   3, ...
                    'isLowFreq',   LowFreq, ...   % 0 ou 1: detect eyes/teeth movements
                    'isHighFreq',  HighFreq);      % 0 ou 1: detect muscular/sensor artifacts
            end
        end

        function [sEvents, all_events] = getEvents(app, sFiles)
            % Extract all events for every study in sFiles (sEvents).
            
            % Input>    sFiles [struc]

            % Output>   sEvents [struct]: Structure with the Studies Name as Key
            %                             and the Event [cell] as values
            %           all_events [cell]: Cell with all events that are
            %                               in at least one study.

            all_events = cell(1, 1);
            compt = 1;

            for i = 1:length(sFiles)
                directory = strcat(app.ProtocolPath, 'data/', sFiles{i});
                assignin("base", "directory", directory)
                addpath(app.ProtocolPath);
                file = load(directory);
                [~, studyName] = app.getStudyNameFromsFile(sFiles(i));
                sEvents.(studyName) = file.F.events;

                for j = 1:length(file.F.events)
                    if ~any(strcmp(all_events, file.F.events(j).label))
                        all_events{compt} = file.F.events(j).label;
                        compt = compt + 1;
                    end
                end
            end
        end

        function [] = createHistoryEventButton(app, sFiles, type)
 
            switch type
                case "history"
                    app.createProvenanceFile(sFiles);
                    panel = app.HistoryPanel;
                    fileExtension = '_Provenance.json';
                    
                case "event"
                    app.createJsonEventFile(sFiles);
                    panel = app.EventsDetectedPanel;
                    fileExtension = '_Event_Metadata.json';
            end
            
            for i = 1:length(sFiles)
                 % Create Hyperlink
                 panelChildren = panel.Children;
                 [~, studyName] = app.getStudyNameFromsFile(sFiles{i});

                 link = strcat(app.AppFolderPath, '/', studyName, fileExtension); 
                 button = uibutton(panel, 'ButtonPushedFcn', @(button,event) app.openFileWithButtonPushed(button, link), 'Text', studyName);

                 
                 % Button Size and Position
                 
                 height = 20;
                 positionPanel = panel.Position;
                 if isempty(panelChildren)
                     position = [1 positionPanel(4)-height*2 positionPanel(3)-2 height];
                 else
                     position = panelChildren(1).Position + [0 -height 0 0];
                 end
                 button.Position = position;
            end
        end

        function [BIDSpath, EDFpath, TSVpath, PROVpath] = getBIDSpath(app, sFiles, BIDSfolderName)

            for i = 1:length(sFiles)
                 [subjectName, studyName] = app.getStudyNameFromsFile(sFiles{i});
%                 
%                 BIDSpath = convertStringsToChars(app.WorkingFolderPath + "/" + BIDSfolderName + "/");
%           
%                 % Get date of study
                 studiesFiles = app.loadFiles(sFiles(i));
                 months = struct('Jan', '1', 'Feb', '2', 'Mar', '3', 'Apr', '4', 'May', '5', 'Jun', '6', 'Jul', '7', 'Aug', '8', 'Sep', '9', 'Oct', '10', 'Nov', '11', 'Dec', '12');
                 chars = split(studiesFiles.(studyName){1}.DateOfStudy, '-');
                 date = strcat(chars{3}, months.(chars{2}), chars{1});
%                 
%                 % Get folder path to save .tsv file
%                 fileName = strcat(studyName, '.txt');
%                 TSVpath = strcat(BIDSpath, '/sub-0001/ses-', date, '/', lower(app.AnalysisType), '/', fileName);
%                 
%                 EDFpath = strcat(BIDSpath, '/', studyName, '.edf');
%                 
%                 PROVpath = BIDSpath;
                
                SubjectsInStudy =  bst_get('ProtocolSubjects');
                
                compt = 1;          
                for j = 1:length(SubjectsInStudy.Subject)
                    if SubjectsInStudy.Subject(j).Name == string(subjectName)
                       break
                    end
                    compt = compt + 1;
                end
                subNumber = compose("%04d", compt);
                
                BIDSpath = strcat('/mnt/3b5a15cf-20ff-4840-8d84-ddbd428344e9/ALAB1/corentin/', BIDSfolderName, '/');
                EDFpath = strcat(BIDSpath, 'sub-', subNumber, '/ses-', date, '/', lower(app.AnalysisType), '/');
                TSVpath = strcat(BIDSpath, 'sub-', subNumber, '/ses-', date, '/', lower(app.AnalysisType), '/');
                PROVpath = strcat(BIDSpath, 'sub-', subNumber, '/ses-', date, '/', lower(app.AnalysisType), '/');
            end
            
        end
        
        function [] = convertToBIDS(app, sFiles)
            % Create BIDS folder and populates it through sub-function
            
            % Input>    sFiles [cell]
            
            BIDSfolderName = char(inputdlg('Name of BIDS folder', 'Name of BIDS folder'));
            
            if isempty(BIDSfolderName)
                return
            end
            
            %BIDSfolderName = 'Protocol';
            mkdir(strcat('/mnt/3b5a15cf-20ff-4840-8d84-ddbd428344e9/ALAB1/corentin/', BIDSfolderName, '/'));

            for i = 1:length(sFiles)

                [BIDSpath, EDFpath, TSVpath, PROVpath] = app.getBIDSpath(sFiles(i), BIDSfolderName);
                
                assignin("base", "PROVpath", PROVpath);
                assignin("base", "EDFpath", EDFpath);

                bst_process('CallProcess', 'process_export_bids', sFiles{i}, [], ...
                    'bidsdir',       {BIDSpath, 'BIDS'}, ...
                    'subscheme',     2, ...  % Number index
                    'sesscheme',     1, ...  % Acquisition date
                    'emptyroom',     'emptyroom, noise', ...
                    'defacemri',     0, ...
                    'overwrite',     0, ...
                    'powerline',     2, ...  % 60 Hz
                    'dewarposition', 'Upright', ...
                    'eegreference',  'Cz', ...
                    'edit',          struct(...
                    'ProjName',    [], ...
                    'ProjID',      [], ...
                    'ProjDesc',    [], ...
                    'Categories',  [], ...
                    'JsonDataset', ['{' 10 '  "License": "PD"' 10 '}'], ...
                    'JsonMeg',     ['{' 10 '  "TaskDescription": "My task"' 10 '}']));


                tic;
                % Create .edf file
                app.exportToEDF(sFiles(i), EDFpath);
                toc;

                disp('EDF SAVED!');
                %uiconfirm(app.UIFigure, "EDF Saved!", "Edf Saved!");

                % Create .tsv file (Events)
                app.createTsvFile(sFiles(i), TSVpath);
                %disp('TSV created')
                %uiconfirm(app.UIFigure, "All Done", "All Done");
                
                % Create Json event description file
                app.createJsonEventFile(sFiles(i), PROVpath);
                
                % Create provenance file
                app.createProvenanceFile(sFiles(i), PROVpath);      
                
            end
            
            uiconfirm(app.UIFigure, 'BIDS created!', 'Files created');
        end

        function edfFile = exportToEDF(app, sFiles, destination)
            % Convert brut datas in EDF file format and saves it in BIDS
            % folder
            
            % Input>    sFiles [cell]
            %           destination [char]: path indicating where to save EDF file
            
            for i = 1:length(sFiles)

                [subjectName, studyName] = app.getStudyNameFromsFile(sFiles{i});
                channelFile = app.ProtocolPath + "data/" + subjectName + "/@" + studyName + "/";
    
                all_files = dir(channelFile);
    
                for j = 1:length(all_files)
                    if contains(all_files(j).name, 'channel')
                        channelFile = channelFile + all_files(j).name;
                        break
                    end
                end
                dest = convertStringsToChars(strcat(destination, studyName, '.edf'));
                assignin("base", "dest", dest);
                [edfFile] = export_data(sFiles{i}, channelFile, dest, []);
            end
        end
        
        function [] = createTsvFile(app, sFiles, destination)
            % Create TSV file that lists the events with time and duration.
            
            % Input>    sFiles [cell]
            %           destination [char]: path indicating where to save the TSV file 
            
            if ~exist('destination','var')
                destination = app.AppFolderPath;
            end
            
            for i = 1:length(sFiles)
                
                events = app.getEvents(sFiles(i));
                assignin("base", "events2", events);
    
                % Loop through all studies
                for j = 1:length(events)
    
                    % Initialiaze tsvFile
                    nb_events = 2; % Arbitrary number...
                    tsvFile = strings([nb_events,3]);
                    compt = 1;
                    tsvFile(compt,:) = ["onset" "duration" "trial type"];
                    compt = compt + 1;
    
                    % Get the study name to access the corresponding events in
                    % structure
                    [~, studyName] = app.getStudyNameFromsFile(sFiles{i});
    
                    % Loop through all event types
                    for k = 1:length(events.(studyName))
    
                        % Loop through all time (each occurence of an event)
                        for l = 1:length(events.(studyName)(k).times)
    
                            tsvFile(compt,:) = [events.(studyName)(k).times(l) 0 string((events.(studyName)(k).label))];
                            compt = compt + 1;
                        end
                    end
    
                    
                    %TSVpath = '/mnt/3b5a15cf-20ff-4840-8d84-ddbd428344e9/ALAB1/corentin/';
                    writematrix(tsvFile, strcat(destination, studyName, '_Events.txt'), 'Delimiter', 'tab');
    
                end
    

            end
        end

        function [] = createProvenanceFile(app, sFiles, destination)
            % Create Json file containing information about the study
            % (history of all functions ran on it)
            
            % Input>    sFiles [cell]
            %           destination [char]: path to save JSON file
            
            if ~exist('destination','var')
                destination = strcat(app.AppFolderPath);
            end
            
            for i = 1:length(sFiles)
                
                
                %[~, matFile] = app.loadFiles(sFiles{i});
                studiesFiles = app.loadFiles(sFiles(i));
 
                [~, studyName] = app.getStudyNameFromsFile(sFiles{i});
                matFile = studiesFiles.(studyName){2};
                
                for j = 1:height(matFile.History)
                    activity.id = matFile.History{j,2};
                    activity.label = matFile.History{j,3};
                    activity.command = 'button pushed';
                    activity.startedAtTime = matFile.History{j,1}; 
                   
                    provenance.(strcat('ActivityNo', num2str(j))) = activity; 
                end
   
                fileName = strcat(studyName, '_Provenance.json');
            
                app.saveAsJSON(provenance, fileName)                
                
                if string(destination) ~= string(pwd)
                    copyfile(fileName, destination);
                    delete(fileName);
                end
                
                %fields = {'id', 'label', 'command', 'startedAtTime'};
                %activity = rmfield(activity, fields);
                activity = rmfield(activity, fieldnames(activity));
                
            end
     
        end
        
        function [] = createJsonEventFile(app, sFiles, destination)
            % Create JSON file that describes every event.
            
            % Input>    sFiles [cell]
            %           destination [char]: path indicating where to save the JSON file 

            if ~exist('destination','var')
                destination = app.AppFolderPath;
            end
            
            for i = 1:length(sFiles)

                [~, all_events] = app.getEvents(sFiles(i));


                for j = 1:length(all_events)
                    new_event = strrep(all_events{j}, '-', '_');
                    new_event = strrep(new_event, ' ', '_');
                    
                    assignin("base", "all_events", all_events)
                    
                    s__.(new_event) = 'Description';
                end
    
                s_.LongName = 'Event category';
                s_.Description = 'Indicator of type of action that is expected';
                s_.Levels = s__;
    
                s.trial_type = s_;
    
                [~, studyName] = app.getStudyNameFromsFile(sFiles{i});
                fileName = strcat(studyName, '_Event_Metadata.json');
                app.saveAsJSON(s, fileName)
                
                % Copy the json file in a new destination and delete the old one
                if string(destination) ~= string(pwd)
                    copyfile(fileName, destination);
                    delete(fileName);
                end
                
            end
        end
        
        function studiesFiles = loadFiles(app, sFiles)
            % Load brainstormstudy.mat and the .MAT file of the study for
            % each study of sFiles
            
            % Input> sFiles [cell]
            
            % Output> studiesFiles [struct]: Strcuture with the study name
            % as key and a cell {brainstormstudy.mat, study.mat} as value

            Infos = app.getCurrentInformations;
            
            for i = 1:length(sFiles)
                [subjectName, studyName, studyMatFile] = app.getStudyNameFromsFile(sFiles{i});
                studyName = strrep(studyName, '-', '_');
                studyName = strrep(studyName, ' ', '_');
                Dir = app.bs_dbPath + '/' + Infos.ProtocolInfo.Comment + '/data/' + subjectName + '/@' + studyName + '/';
                addpath(Dir);
                studiesFiles.(studyName) = {load(Dir + 'brainstormstudy.mat'), load(Dir + studyMatFile)};
            end
        end
                
        function [] = switchParent(app, obj, backColor, textColor)
            try 
                enfants = obj.Children;
                cls = class(obj);
                
                switch cls
                    case 'matlab.ui.Figure'
                       obj.Color = backColor; 
                       
                    case 'matlab.ui.container.Panel'
                        obj.BackgroundColor = backColor;
                        obj.ForegroundColor = textColor;
                           
                    case 'matlab.ui.container.CheckBoxTree'
                        obj.BackgroundColor = backColor;
                        obj.FontColor = textColor;
                           
                    case 'matlab.ui.container.TabGroup'
                        
                        %obj.BackgroundColor = backColor;
                        %obj.ForegroundColor = textColor;
                        
                    case 'matlab.ui.container.Tab'
                        obj.BackgroundColor = backColor;
                        %obj.ForegroundColor = textColor;
                           
                end
        
        
                for i = 1:length(enfants)
                    app.switchParent(enfants(i), backColor, textColor);
                end
                
            catch
                
                app.switchChildren(obj, backColor, textColor);
                
            end
        end
        
    end

    methods(Static)
        
        function [subjectName, studyName, studyMatFile] = getStudyNameFromsFile(sFile)
            sFileSplit = split(sFile, '/');
            studyName = sFileSplit{2}(2:end);
            subjectName = sFileSplit{1};
            studyMatFile = sFileSplit{3}; 
        end
                   
        function openFileWithButtonPushed(~, path)
            open(path)
        end
        
        function [] = switchChildren(obj, backColor, textColor)
            cls = class(obj);

            switch cls
                case 'matlab.ui.control.Button'
                    obj.FontColor = textColor;
                    obj.BackgroundColor = backColor;
        
                case 'matlab.ui.container.Panel'
                    %obj.BackgroundColor = backColor;
                    %obj.ForegroundColor = textColor;
                    %buttonIntab = obj.Children;
                    
                case 'matlab.ui.control.Hyperlink'
                    obj.VisitedColor = textColor;
                    obj.BackgroundColor = backColor;
                    obj.FontColor = textColor;
                            
                case 'matlab.ui.control.Label'
                    obj.BackgroundColor = backColor;
                    obj.FontColor = textColor;
                    
                case 'matlab.ui.control.EditField'
                    
                case 'matlab.ui.control.DropDown'
                    obj.FontColor = textColor;
                    obj.BackgroundColor = backColor;
                    
                case 'matlab.ui.control.CheckBoxTree'
                    obj.FontColor = textColor;
        
            end
        end
        
        
        function [] = modifyEDFDate(edfFilePath)
            
            % Copy files TXT file
            copyfile b1.vmrk b1_vmrk.txt
            copyfile b1_resample.edf b1_resample_edf.txt
            
            % Extract date from VMRK file
            fileid = fopen('b1_vmrk.txt', 'r');
            for i = 0:10  
                fgetl(fileid);
            end
            
            textline = fgetl(fileid);
            debutDate = 24;
            date = textline(debutDate:debutDate+13);
            fclose(fileid);
            annee = date(1:4);
            mois = date(5:6);
            jour = date(7:8);
            heures = date(9:10);
            minutes = date(11:12);
            secondes = date(13:14);
            
            allMonth = {'JAN', 'FEB', 'MAR', 'APR', 'MAY', 'JUN', 'JUL', 'AUG', 'SEP', 'OCT', 'NOV', 'DEC'};
            strMonth = allMonth{str2double(mois)};


            % Extract line with wrong date from EDF file and replace date (at 2 places) with correct
            % date
            fileid = fopen('b1_resample_edf.txt', 'r');
            textline = fgetl(fileid);
            length(textline)
            index = strfind(textline, 'Startdate');
            
            % First replacement
            newline = strrep(textline, textline(index+10:index+10+10), strcat(jour, '-', strMonth, '-', annee));
            
            % Second replacement
            newline = strrep(newline, textline(169:184), strcat(jour, '.', mois, '.', annee(3:4), heures, '.', minutes, '.', secondes));
            length(newline)
            
            fclose(fileid);
            
            fileid = fopen('b1_resample_edf.txt', 'r+');
            fprintf(fileid, '%c', newline);
            fclose(fileid);

        end


        % The next function has been taken here:
        % https://www.mathworks.com/matlabcentral/fileexchange/77284-structure-and-object-to-json?s_tid=srchtitle

        function saveAsJSON(data, jsonFileName)
            % saves the values in the structure 'data' to a file in JSON format.
            % Based on the work of Lior Kirsch at: https://uk.mathworks.com/matlabcentr
            % al/fileexchange/50965-structure-to-json
            %
            % Modification by Arthur Y.C. Liu 24/06/2020
            %
            % Example:
            %     data.name = 'chair';
            %     data.color = 'pink';
            %     data.eye = eye(3);
            %     data.metrics.imageSize = [1920; 1080];
            %     data.metrics.height = 0.3;
            %     data.metrics.width = 1.3;
            %     saveJSONfile(data, 'out.json');
            %
            % Output 'out.json':
            % {
            % 	"name" : "chair",
            % 	"color" : "pink",
            % 	"eye" :
            % 	[
            % 		1,
            % 		0,
            % 		0,
            % 		0,
            % 		1,
            % 		0,
            % 		0,
            % 		0,
            % 		1
            % 	],
            % 	"metrics" :
            % 	{
            % 		"imageSize" :
            % 		[
            % 			1920,
            % 			1080
            % 		],
            % 		"height" : 0.3,
            % 		"width" : 1.3
            % 	}
            % }
            fid = fopen(jsonFileName,'w');
            if isobject(data)
                data = toStruct(data);
            end
            writeElement(fid, data, '', true);
            fprintf(fid,'\n');
            fclose(fid);


            function writeElement(fid, data, tabs, isFirstLine)
                namesOfFields = fieldnames(data);
                numFields = length(namesOfFields);
                tabs = sprintf('%s\t', tabs);
                if nargin == 4
                    if isFirstLine
                        fprintf(fid,'%s{\n%s', tabs(1:end-1), tabs);
                    end
                else
                    fprintf(fid,'\n%s{\n%s', tabs(1:end-1), tabs);
                end
                for i = 1:numFields
                    currentField = namesOfFields{i};
                    currentElementValue = data.(currentField);
                    writeSingleElement(fid, currentField, currentElementValue, tabs);
                    if i == numFields
                        fprintf(fid,'\n%s}',  tabs(1:end-1));
                    else
                        fprintf(fid,',\n%s', tabs);
                    end
                end
            end

            function writeSingleElement(fid, currentField, currentElementValue, tabs)
                % if this is an array/matrix and not a string then iterate on every
                % element, if this is a single element write it
                if ~isstruct(currentElementValue) &&...
                        length(currentElementValue) > 1 && ~ischar(currentElementValue)
                    fprintf(fid,'"%s" : \n%s[\n%s\t',currentField, tabs, tabs);
                    tabs = sprintf('%s\t', tabs);
                    valLength = length(currentElementValue(:));
                    for m = 1:valLength
                        fprintf(fid,'%g' , currentElementValue(m));
                        if m == valLength
                            fprintf(fid,'\n%s]', tabs(1:end-1));
                        else
                            fprintf(fid,',\n%s', tabs);
                        end
                    end
                elseif isstruct(currentElementValue) &&...
                        length(currentElementValue) > 1 && ~ischar(currentElementValue)
                    fprintf(fid,'"%s" : \n%s[',currentField, tabs);
                    tabs = sprintf('%s\t', tabs);
                    valLength = length(currentElementValue(:));
                    for m = 1:valLength
                        writeElement(fid, currentElementValue(m), tabs);
                        if m == valLength
                            fprintf(fid,'\n%s]', tabs(1:end-1));
                        else
                            fprintf(fid,',');
                        end
                    end
                elseif isstruct(currentElementValue)
                    fprintf(fid,'"%s" : ',currentField);
                    writeElement(fid, currentElementValue, tabs);
                elseif isnumeric(currentElementValue) || islogical(currentElementValue)
                    fprintf(fid,'"%s" : %g' , currentField, currentElementValue);
                elseif isempty(currentElementValue)
                    fprintf(fid,'"%s" : "null"' , currentField);
                elseif isobject(currentElementValue)
                    currentElementValue = toStruct(currentElementValue);
                    fprintf(fid,'"%s" : ',currentField);
                    writeElement(fid, currentElementValue, tabs);
                else %ischar or something else ...
                    fprintf(fid,'"%s" : "%s"' , currentField, currentElementValue);
                end
            end

        end

    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %app.UIFigure.WindowState = 'maximized';

            % Start Brainstorm without interface
            addpath(app.BrainstormPath);
            if ~brainstorm('status')
                brainstorm nogui;
            end

            choice = ["EEG", "MEG"];
            rep = string(uiconfirm(app.UIFigure, 'What type of analysis do you want to conduct ?', 'Type of analysis', 'Options', choice));
            app.switchAnalysisTab(rep);

            title = "Select the folder that contains your data and your anatomy";
            
            app.WorkingFolderPath = app.getFilePath(title, "studentFolder");
            
            app.AppFolderPath = strcat(app.WorkingFolderPath, app.AppFolderName);

            mkdir(app.AppFolderPath)
            
            
            if ~isequal(app.WorkingFolderPath, 0)
                cd(app.AppFolderPath);
                addpath(app.WorkingFolderPath);
            end

        end

        % Button pushed function: TESTINGButton
        function TESTINGButtonPushed(app, event)
            
         
            % Convert brut datas in EDF file format and saves it in BIDS
            % folder
            
            % Input>    sFiles [cell]
            %           destination [char]: path indicating where to save EDF file
            
            sFiles = app.selectedStudies;
            
            destination = '/mnt/3b5a15cf-20ff-4840-8d84-ddbd428344e9/ALAB1/corentin/';
            
            for i = 1:length(sFiles)

                [subjectName, studyName] = app.getStudyNameFromsFile(sFiles{i});
                channelFile = app.ProtocolPath + "data/" + subjectName + "/@" + studyName + "/";
    
                all_files = dir(channelFile);
    
                for j = 1:length(all_files)
                    if contains(all_files(j).name, 'channel')
                        channelFile = channelFile + all_files(j).name;
                        break
                    end
                end
                dest = convertStringsToChars(strcat(destination, studyName, '.edf'));
                assignin("base", "dest", dest);
                export_data(sFiles{i}, channelFile, dest, []);
            end
        end

        % Button pushed function: bids_edfButton
        function bids_edfButtonPushed(app, event)
            % Create BIDS folder and populates it through sub-function
            
            % Input>    sFiles [cell]
            
            %% STEPS to IMPLEMANT: 
            % 1. CONVERT OG STUDY TO EDF
            % 2. REVIEW EDF (CREATES EDF STUDY)
            % 3. CONVERT EDF STUDY TO BIDS
            % 4. CREATE SIDE FILES (EVENT/PROVENANCE) FROM THE OG
            % STUDY(!!!)

            BIDSfolderName = char(inputdlg('Name of BIDS folder', 'Name of BIDS folder'));
            if isempty(BIDSfolderName)
                return
            end
            mkdir(strcat('/mnt/3b5a15cf-20ff-4840-8d84-ddbd428344e9/ALAB1/corentin/', BIDSfolderName, '/'));
            

            sFiles = app.selectedStudies;
            for i = 1:length(sFiles)
                
                
                % EXPORT TO EDF
                destination = '/mnt/3b5a15cf-20ff-4840-8d84-ddbd428344e9/ALAB1/corentin/'; % Temporaire
                edfFilePath = app.exportToEDF(sFiles(i), destination);
                
                assignin("base", "edfFile", edfFilePath);
                
                %CHANGE DATE IN EDF FILE HERE!!!!!!
                         
                
                % Reimport in BST
                [subjectName] = app.getStudyNameFromsFile(sFiles);
                
                sFilesEDF = bst_process('CallProcess', 'process_import_data_raw', [], [], ...
                                    'subjectname',    subjectName, ...
                                    'datafile',       {edfFilePath, 'EEG-EDF'}, ...
                                    'channelreplace', 0, ...
                                    'channelalign',   0, ...
                                    'evtmode',        'value');


                assignin("base", "sFilesEDF", sFilesEDF);
                [BIDSpath, EDFpath, TSVpath, PROVpath] = app.getBIDSpath(sFiles(i), BIDSfolderName);
                assignin("base", "PROVpath", PROVpath);
                 assignin("base", "EDFpath", EDFpath);
                 assignin("base", "BIDSpath", BIDSpath) ;
                 assignin("base", "TSVpath", TSVpath) ;
                 

                bst_process('CallProcess', 'process_export_bids', sFilesEDF.FileName, [], ...
                     'bidsdir',       {BIDSpath, 'BIDS'}, ...
                     'subscheme',     2, ...  % Number index
                     'sesscheme',     1, ...  % Acquisition date
                     'emptyroom',     'emptyroom, noise', ...
                     'defacemri',     0, ...
                     'overwrite',     0, ...
                     'powerline',     2, ...  % 60 Hz
                     'dewarposition', 'Upright', ...
                     'eegreference',  'Cz', ...
                     'edit',          struct(...
                     'ProjName',    [], ...
                     'ProjID',      [], ...
                     'ProjDesc',    [], ...
                     'Categories',  [], ...
                     'JsonDataset', ['{' 10 '  "License": "PD"' 10 '}'], ...
                     'JsonMeg',     ['{' 10 '  "TaskDescription": "My task"' 10 '}']));

                
                % Create .tsv file (Events)
                app.createTsvFile(sFiles(i), TSVpath);
                
                % Create Json event description file
                app.createJsonEventFile(sFiles(i), PROVpath);
                
                % Create provenance file
                app.createProvenanceFile(sFiles(i), PROVpath);   

                
            end
        end

        % Button pushed function: CreateProtocolButton
        function CreateProtocolButtonPushed(app, event)

            title = 'Create Protocol';
            question = 'Name of the protocol to create: ';
            ProtocolName = char(inputdlg(question, title));

            if not(isempty(ProtocolName))
                
                gui_brainstorm('CreateProtocol', ProtocolName, 0, 0); % 0/1: Use default anatomy / Use default channel
                %bst_report('Start');
            end

            app.ProtocolPath = strcat(app.bs_dbPath, '/', ProtocolName);
        end

        % Button pushed function: DeleteProtocolButton
        function DeleteProtocolButtonPushed(app, event)
            
            allProtocols = cell(1, 30);    % 30 est choisi au hasard (supérieur au nombre de protocol)

            i = 1;
            while true
                bst_set('iProtocol', i);
                protocol = bst_get('ProtocolInfo');
                if isempty(protocol)
                    break
                end
                allProtocols{i} = protocol.Comment;
                i = i + 1;
            end
            allProtocols = allProtocols(~cellfun(@isempty, allProtocols));

            index = listdlg('Name', 'Protocol to delete', 'ListString', allProtocols);
            if isempty(index)
                return
            end
            
            selection = uiconfirm(app.UIFigure, strcat("You're about to delete the following protocol: ", allProtocols{index}), "Protocol Delete");
            
            if selection == "OK"
                gui_brainstorm('DeleteProtocol', allProtocols{index});
                uiconfirm(app.UIFigure, "Protocol has been delete", "Protocol Delete");         
            end

            
            app.updateTree();


        end

        % Button pushed function: LoadProtocolButton
        function LoadProtocolButtonPushed(app, event)

            allProtocols = cell(1, 30);    % 30 est choisi au hasard (supérieur au nombre de protocol)

            i = 1;
            while true
                bst_set('iProtocol', i);
                protocol = bst_get('ProtocolInfo');
                if isempty(protocol)
                    break
                end
                allProtocols{i} = protocol.Comment;
                i = i + 1;
            end
            allProtocols = allProtocols(~cellfun(@isempty, allProtocols));

            index = listdlg('Name', 'Choose Protocol to Load', 'ListString', allProtocols);
            if isempty(index)
                return
            end

            protocol_index = bst_get('Protocol', allProtocols{index});


            % After testing, this is the beat way to switch protocol...
            bst_set('iProtocol', protocol_index)
            %protocol_to_load = bst_get('ProtocolInfo');
            %sProtocolInfo = load(strcat(protocol_to_load.STUDIES, '/protocol.mat'));
            %bst_set('ProtocolInfo', sProtocolInfo)
            %bst_set('ProtocolInfo', sProtocolInfo.ProtocolInfo)
            %bst_set('ProtocolSubjects', sProtocolInfo.ProtocolSubjects)
            %bst_set('ProtocolStudies', sProtocolInfo.ProtocolStudies)

            app.updateTree
        end

        % Button pushed function: CreateSubjectButton
        function CreateSubjectButtonPushed(app, event)

            title = 'Name your subject';
            question = 'Subject Name: ';
            SubjectName = char(inputdlg(question, title));

            if isempty(SubjectName)
                return
            end

            choice = ["Sepecify anatomy folder", "Use default anatomy", "Cancel"];
            rep = uiconfirm(app.UIFigure, 'Do you have a specific anatomy folder for your subjet or do you want to use the default anatomy ?', ...
                'Create Subject', 'Options', choice);
            switch rep
                case choice(1)
                    set(app.ProjectElectrodesonScalpButton,'Enable', 'on');
                    title = "Choose the anatomy folder";
                    AnatomyPath = app.getFilePath(title, "anatomy");

                    if isempty(AnatomyPath)
                        return
                    end

                    app.importAnatomy(SubjectName, AnatomyPath);

                case choice(2)
                    set(app.ProjectElectrodesonScalpButton,'Enable', 'off');
                    db_add_subject(SubjectName, [], 1, 0);

                case choice(3)
                    return
            end

            app.updateTree;

        end

        % Button pushed function: ReviewRawFilesButton
        function ReviewRawFilesButtonPushed(app, event)
            Infos = app.getCurrentInformations;
            
            % Liste de tous les sujets actuels
            liste = cell(1, length(Infos.Subjects.Subject));
            for i = 1:length(Infos.Subjects.Subject)
                liste{i} = Infos.Subjects.Subject(i).Name;
            end

            index = listdlg('Name', 'Choose the Subject', 'ListString', liste);
            if isempty(index)
                return
            end

            SubjectName = liste{index};
            %[sSubject, iSubject] = bst_get('Subject', Nom);
            %bst_set('Subject', iSubject, sSubject)
            
            if app.AnalysisType == "MEG"

                choice = ["Data Files", "Noise Files", "Cancel"];
                type = uiconfirm(app.UIFigure, 'Wich type of file do you want to import ?', 'Files to import', 'Options', choice);
                switch type
                    case choice(1)
                        title = "Select the folder containing your data files";
                        ChannelAlign = 1;
                    case choice(2)
                        title = "Select the folder containing your noise files";
                        ChannelAlign = 0;
                    case choice(3)
                        return
                end


            elseif app.AnalysisType == "EEG"
                
                title = "Select your EEG data file with the *.bin extension.";
                ChannelAlign = 0;
                
            end

                
            RawFilePath = getFilePath(app, title, "data");
            splitPath = split(RawFilePath, '.');
            extension = splitPath{2};
            
            switch extension
                case 'bin'
                    app.FileFormat = 'EEG-DELTAMED';
                case 'eeg'
                    app.FileFormat = 'EEG-BRAINAMP';
            end
            
            sFiles = reviewRawFiles(app, SubjectName, RawFilePath, ChannelAlign);

            app.updateTree;
            app.checkNodesFromSFiles(sFiles)

        end

        % Button pushed function: NotchFilterButton
        function NotchFilterButtonPushed(app, event)

            choice = ["I am in North America (60 Hz, 120 Hz, 180 Hz, ...)", "I am in Europe (50 Hz, 100 Hz, 150 Hz, ...)", "Cancel"];
            rep = uiconfirm(app.UIFigure, 'Where has your protocol been conducted ?', 'Notch filter', 'Options', choice);

            disp(rep);
            switch rep
                case choice(1)
                    frequence = [60 120 180 240];
                case choice(2)
                    frequence = [50, 100, 150, 200];
                case choice(3)
                    return
            end

            sFiles = app.selectedStudies;

            sFiles = bst_process('CallProcess', 'process_notch', sFiles, [], ...
                'sensortypes', app.SensorType, ...
                'freqlist',    frequence, ...
                'cutoffW',     1, ...
                'useold',      0, ...
                'read_all',    0);
            
            app.updateTree;
            app.checkNodesFromSFiles(sFiles)

            %explanation = "The following frequencies will be removed due to the power line frequency: " + num2str(frequence) + " [Hz]";
            %app.createHitoryHlink('Notch Filter', sFiles, explanation);
        end

        % Button pushed function: BandpassFilterButton
        function BandpassFilterButtonPushed(app, event)

            sFiles = app.selectedStudies;

            title = 'Enter the frequencies cut off';

            question = 'Lower cut off frequency (Hz): ';
            LowPass = string(inputdlg(question, title));

            question = 'Higher cut off frequency (Hz): ';
            HighPass = string(inputdlg(question, title));

            if isempty(HighPass) || isempty(LowPass)
                uialert(app.UIFigure, "Please enter an upper and lower frequency cutoff points.", 'Error')
                return
            end
            
            sFiles = bst_process('CallProcess', 'process_bandpass', sFiles, [], ...
                'sensortypes', app.SensorType, ...
                'highpass',    str2double(LowPass), ...
                'lowpass',     str2double(HighPass), ...
                'tranband',    0, ...
                'attenuation', 'strict', ...  % 60dB
                'ver',         '2019', ...  % 2019
                'mirror',      0, ...
                'read_all',    0);

            app.updateTree;
            app.checkNodesFromSFiles(sFiles)

            %explanation = "The frequencies outside the range [" + LowPass + " " + HighPass + ...
                %"] Hz will be rejected (attenuated). The frequencies within that range will not be affected.";

            %app.createHitoryHlink('Band-Pass Filter', sFiles, explanation);


        end

        % Button pushed function: PowerSpectrumDensityButton
        function PowerSpectrumDensityButtonPushed(app, event)


            sFiles = app.selectedStudies;
            
            assignin("base", "sfiles", sFiles);
            
            if app.AnalysisType == "EEG"
                win_length = 10;
            elseif app.AnalysisType == "MEG"
                win_length = 4;
            end

            bst_process('CallProcess', 'process_psd', sFiles, [], ...
                'timewindow',  [], ...
                'win_length',  win_length, ...
                'win_overlap', 50, ...
                'clusters',    {}, ...
                'sensortypes', app.SensorType, ...
                'edit', struct(...
                'Comment',    'Power', ...
                'TimeBands',  [], ...
                'Freqs',      [], ...
                'ClusterFuncTime', 'none', ...
                'Measure',    'power', ...
                'Output',     'all', ...
                'SaveKernel', 0));
            
            app.updateTree;

            %explanation = "The Power Spectrum Density (PSD) represents the intensity (amplitude) of every frequency of a signal.";
            %app.createHitoryHlink('Power Spectrum Density', sFiles, explanation);
            
        end

        % Button pushed function: ConvertEpochstoContinueButton
        function ConvertEpochstoContinueButtonPushed(app, event)

            sFiles = app.selectedStudies;

            bst_process('CallProcess', 'process_ctf_convert', sFiles, [], ...
                'rectype', 2);  % 2: To Continue

            %explanation = "The function converts Epochs files to Continue files.";
            %app.createHitoryHlink('Conversion Epochs to Continue', sFiles, explanation);
        end

        % Button pushed function: DetectArtifactButton
        function DetectArtifactButtonPushed(app, event)
            sFiles = app.selectedStudies;
            
            choice = ["Cardiac", "Blink", "Other", "Cancel"];
            rep = uiconfirm(app.UIFigure, 'What to detect?', ...
                'Artifact detection', 'Options', choice);
            
            disp(rep);
            switch rep
                case choice(1)
                    type = "cardiac";
                    channel = 'ECG';
                    name = 'cardiac';
                case choice(2)
                    type = "blink";
                    channel = 'VEOG';
                    name = 'blink';
                case choice(3)
                    type = "other (teeth/muscle)";
                    channel = "";
                    name = "";
                case choice(4)
                    return
            end

            app.detectArtifact(sFiles, type, channel, name);

            %app.createHitoryHlink('Artifact Detection', sFiles);
        end

        % Button pushed function: RenameEventsButton
        function RenameEventsButtonPushed(app, event)

            sFiles = app.selectedStudies;

            [~, choice] = app.getEvents(sFiles);

            index = listdlg('ListString', choice, 'PromptString', 'Rename?');

            if length(index) ~= 1
                waitfor(errordlg("Choose one and only one event to rename."))
                return
            end

            old_name = choice{index};

            title = 'Nouveau nom d''évenement';
            question = 'Nouveau nom ?';
            new_name = char(inputdlg(question, title));

            if isempty(new_name)
                waitfor(errordlg("Please enter a new event name."))
                return
            end

            bst_process('CallProcess', 'process_evt_rename', sFiles, [], ...
                'src',  old_name, ...
                'dest', new_name);

            %app.createHitoryHlink('Rename Event', sFiles);
        end

        % Button pushed function: DeleteEventsButton
        function DeleteEventsButtonPushed(app, event)
            sFiles = app.selectedStudies;

            [~, choice] = app.getEvents(sFiles);

            index = listdlg('ListString', choice, 'PromptString', 'delete?');

            if isempty(index)
                msgbox("Missing event to delete.")
                return
            end

            for i = 1:length(index)

                bst_process('CallProcess', 'process_evt_delete', sFiles, [], ...
                    'eventname', choice{index(i)});
            end

            %app.createHitoryHlink('Delete Event', sFiles);
        end

        % Button pushed function: RemoveSimultaneousEventsButton
        function RemoveSimultaneousEventsButtonPushed(app, event)
            sFiles = app.selectedStudies;

            [~, choice] = app.getEvents(sFiles);

            indexR = listdlg('ListString', choice, 'PromptString', 'Remove the event named: ');
            indexT = listdlg('ListString', choice, 'PromptString', 'When it is too close to the event: ');

            if length(indexR) > 1 || length(indexT) > 1
                waitfor(errordlg("Choose only one event."))
                return
            elseif isempty(indexR) || isempty(indexT)
                waitfor(errordlg("Missing event to remove or to targert."))
                return
            end

            remove = choice{indexR};
            target = choice{indexT};

            bst_process('CallProcess', 'process_evt_remove_simult', sFiles, [], ...
                'remove', remove, ...
                'target', target, ...
                'dt',     0.25, ...
                'rename', 0);

            %app.createHitoryHlink('Remove Simultaneous Event', sFiles);
        end

        % Button pushed function: SSPButton
        function SSPButtonPushed(app, event)
            sFiles = app.selectedStudies;

            [~, choice] = app.getEvents(sFiles);

            index = listdlg('ListString', choice, 'PromptString', 'Which event do you want to use to compute the SSP ?:');
            
            if length(index) > 1
                waitfor(errordlg("Choose only one event."))
                return
            elseif isempty(index)
                waitfor(errordlg("Missing event to remove or to targert."))
                return
            end

            eventname = choice{index};

            bst_process('CallProcess', 'process_ssp_ecg', sFiles, [], ...
                'eventname',   eventname, ...
                'sensortypes', app.SensorType, ...
                'usessp',      1, ...
                'select',      1);

            for i = 1:length(sFiles)
                view_timeseries(sFiles{i});
                panel_ssp_selection('OpenRaw');
                waitfor(errordlg("Click when you are done choosing"));
            end

            %app.createHitoryHlink('SSP', sFiles);
        end

        % Button pushed function: AddEEGPositionsButton
        function AddEEGPositionsButtonPushed(app, event)
            sFiles = app.selectedStudies;

            choice = ["Use default pattern", "Import a file ", "Cancel"];
            rep = uiconfirm(app.UIFigure, 'Import a file or use a default ?', ...
                'EEG position', 'Options', choice);
            
            switch rep
                case choice(1)
                    
                    capTypes = {'Colin27: BrainProducts EasyCap 128'};
                    capNumber = {22};
                    
                    index = listdlg('Name', 'Choose your Cap!', 'ListString', capTypes, 'ListSize', [350, 150]); 
                    if isempty(index)
                        return
                    end
                    
                    capNumber = capNumber{index};

                    uiconfirm(app.UIFigure, 'Colin27: BrainProduct EasyCap 128', 'EEG Pattern');
                    bst_process('CallProcess', 'process_channel_addloc', sFiles, [], ...
                                'channelfile', {'', ''}, ...
                                'usedefault',  capNumber, ...  % Colin27: BrainProducts EasyCap 128
                                'fixunits',    1, ...
                                'vox2ras',     1);
                    
                case choice(2)
                    
                    title = "Select the electrode file (it should have the *.elc extension)";
                    file = app.getFilePath(title, "data", "*.elc");
                    
                    if isequal(file, 0)
                        return
                    end
                    
                    bst_process('CallProcess', 'process_channel_addloc', sFiles, [], ...
                                'channelfile', {file, 'XENSOR'}, ...
                                'usedefault', 1, ...
                                'fixunits', 1, ...
                                'vox2ras', 0);
            end

            %app.createHitoryHlink('Add EEG Position', sFiles);

        end

        % Button pushed function: RefineRegistrationButton
        function RefineRegistrationButtonPushed(app, event)
            sFiles = app.selectedStudies;

            bst_process('CallProcess', 'process_headpoints_refine', sFiles, []);

            %app.createHitoryHlink('Refine Registration', sFiles);
        end

        % Button pushed function: ProjectElectrodesonScalpButton
        function ProjectElectrodesonScalpButtonPushed(app, event)

            sFiles = app.selectedStudies;

            bst_process('CallProcess', 'process_channel_project', sFiles, [], 'sensortypes', 'EEG');


            %app.createHitoryHlink('Project Electrode on Scalp', sFiles);
        end

        % Button pushed function: DetectHeartbeatsButton
        function DetectHeartbeatsButtonPushed(app, event)

            sFiles = app.selectedStudies;

            type = "cardiac";
            channel = 'ECG';
            name = 'cardiac';
            app.detectArtifact(sFiles, type, channel, name);

            %app.createHitoryHlink('Detect Heartbeats', sFiles);
        end

        % Button pushed function: ImportEventsButton
        function ImportEventsButtonPushed(app, event)

            sFiles = app.selectedStudies;

            title = "Select the event file (it should have the *.txt extension)";
            file = app.getFilePath(title, "data", "*.txt");
            assignin("base", "file", file)
            
            title = 'Name the event';
            question = 'Name of the imported events:';
            new_name = char(inputdlg(question, title));

            bst_process('CallProcess', 'process_evt_import', sFiles, [], ...
                'evtfile', {file, 'ARRAY-TIMES'}, ...
                'evtname', new_name);

            %app.createHitoryHlink('Import Events', sFiles);
        end

        % Button pushed function: AverageReferenceButton
        function AverageReferenceButtonPushed(app, event)

            sFiles = app.selectedStudies;

            bst_process('CallProcess', 'process_eegref', sFiles, [], ...
                'eegref', 'AVERAGE', ...
                'sensortypes', app.SensorType);

            for i = 1:length(sFiles)
                view_timeseries(sFiles{i});
                panel_ssp_selection('OpenRaw');
                waitfor(errordlg("Click when you are done choosing"));
            end

            %app.createHitoryHlink('Average Reference', sFiles);
        end

        % Button pushed function: ICAButton
        function ICAButtonPushed(app, event)

            sFiles = app.selectedStudies;

            title = 'ICA components';
            question = 'How many ICA components (nb electrode / 2): ';
            ICA_components = inputdlg(question, title);

            if isempty(ICA_components)
                return
            end

            message = uiconfirm(app.UIFigure, 'This might take a couple a minutes... You have time ti grab a cup of coffee.', 'loading');

            bst_process('CallProcess', 'process_ica', sFiles, [], ...
                'timewindow', [], ...
                'eventname', '', ...
                'eventtime', [0, 0], ...
                'bandpass', [0, 0], ...
                'nicacomp', str2double(ICA_components{1}), ... % modifi ici!
                'sensortypes', app.SensorType, ...
                'icasort',      '', ...
                'usessp', 1, ...
                'ignorebad', 1, ...
                'saveerp', 0, ...
                'method', 1, ...
                'select', []);

            delete(message)

            for i = 1:length(sFiles)
                view_timeseries(sFiles{i});
                panel_ssp_selection('OpenRaw');
                waitfor(msgbox("Click when you are done choosing"));
            end

            %app.createHitoryHlink('ICA', sFiles);
        end

        % Button pushed function: ICAButton_2
        function ICAButton_2Pushed(app, event)
            app.ICAButtonPushed;
        end

        % Selection change function: TabGroup1
        function TabGroup1SelectionChanged(app, event)
            app.switchAnalysisTab("TabGroup1");
        end

        % Selection change function: TabGroup2
        function TabGroup2SelectionChanged(app, event)
            app.switchAnalysisTab("TabGroup2");
        end

        % Value changed function: TypeofanalysisDropDown
        function TypeofanalysisDropDownValueChanged(app, event)
            app.switchAnalysisTab("DropDown");
        end

        % Button pushed function: OpenBrainstormButton
        function OpenBrainstormButtonPushed(app, event)
            brainstorm;
        end

        % Button pushed function: SelectDeselectallButton
        function SelectDeselectallButtonPushed(app, event)
            app.selectAllStudies;
        end

        % Button pushed function: DeleteStudySubjectButton
        function DeleteStudySubjectButtonPushed(app, event)

            %A REVOIR!

            sFiles = app.selectedStudies;

            % Choix des types de données a importer.
            choice = ["Delete Folder (recommended)", "Delete File", "Delete Subject", "Cancel"];
            type = uiconfirm(app.UIFigure, "Which file do you want to delete ?", "Files to delete", 'Options', choice);

            switch type
                case choice(1)
                    del = 1;
                case choice(2)
                    del = 2;
                case choice(3)
                    del = 3;
                case choice(4)
                    return
            end

            bst_process('CallProcess', 'process_delete', sFiles, [], ...
                'target', del); % 1: fichier 2: folder 3: subjects
        end

        % Button pushed function: UpdateTreeButton
        function UpdateTreeButtonPushed(app, event)
            app.updateTree;
        end

        % Button pushed function: UpdateEventsButton
        function UpdateEventsButtonPushed(app, event)
            sFiles = app.selectedStudies;
            delete(app.EventsDetectedPanel.Children());
            app.createHistoryEventButton(sFiles, 'event')
        end

        % Button pushed function: UpdateHistoryButton
        function UpdateHistoryButtonPushed(app, event)
            sFiles = app.selectedStudies();
            delete(app.HistoryPanel.Children());
            app.createHistoryEventButton(sFiles, 'history')
        end

        % Button pushed function: AddTagButton
        function AddTagButtonPushed(app, event)
            sFiles = app.selectedStudies;

            title = 'Add tag';
            question = 'Tag name to add: ';
            Tag = char(inputdlg(question, title));
            
            if isempty(Tag)
                return
            end

            choice = ["Add Tag to the file Name", "Add tag to the file path", "Cancel"];

            rep = uiconfirm(app.UIFigure, 'where do you want to add the tag ?', ...
                'Add Tag', 'Options', choice);

            switch rep
                case choice(1)
                    bst_process('CallProcess', 'process_add_tag', sFiles, [], ...
                        'tag',           Tag, ...
                        'output',        1);

                case choice(2)
                    bst_process('CallProcess', 'process_add_tag', sFiles, [], ...
                        'tag',           Tag, ...
                        'output',        2);
                    
                case choice(3)
                    return
            end
        end

        % Button pushed function: ConverttoBIDSButton
        function ConverttoBIDSButtonPushed(app, event)
            sFiles = app.selectedStudies;
            app.convertToBIDS(sFiles);
        end

        % Callback function
        function CreateEventFiletsvButtonPushed(app, event)
            sFiles = app.selectedStudies;
            app.createTsvFile(sFiles);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)

            rep = uiconfirm(app.UIFigure, 'Do you want to close the app ?', 'Close request');

            if rep == "OK"
                %cd(app.WorkingFolderPath)
                %delete('html/*.html')

                delete(app)
            end

        end

        % Button pushed function: ColorMode
        function ColorModePushed(app, event)
           
            if app.ColorMode.Text == "Dark Mode"
                backColor = [0 0 0];
                textColor = [1 1 1];
                app.ColorMode.Text = "Bright Mode";
            elseif app.ColorMode.Text == "Bright Mode"
                backColor = [0.94 0.94 0.94];
                textColor = [0 0 0];
                app.ColorMode.Text = "Dark Mode";
            end

            app.switchParent(app.UIFigure, backColor, textColor);
            
        end

        % Button pushed function: OpenPIPBUILDButton
        function OpenPIPBUILDButtonPushed(app, event)
            % Open Pipeline Builder
            run('Pipeline_Builder.mlapp');
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Tree = uitree(app.UIFigure, 'checkbox');
            app.UpdateTreeButton = uibutton(app.UIFigure, 'push');
            app.UpdateTreeButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateTreeButtonPushed, true);
            app.UpdateTreeButton.Text = 'Update Tree';
            app.CurrentProtocolEditField = uieditfield(app.UIFigure, 'text');
            app.CurrentProtocolEditField.Tag = 'CurrentProto';
            app.CurrentProtocolEditField.Editable = 'off';
            app.CurrentProtocolLabel = uilabel(app.UIFigure);
            app.CurrentProtocolLabel.Text = 'Current Protocol:';
            app.CurrentSubjectEditField = uieditfield(app.UIFigure, 'text');
            app.CurrentSubjectEditField.Tag = 'CurrentSubject';
            app.CurrentSubjectEditField.Editable = 'off';
            app.CurrentSubjectLabel = uilabel(app.UIFigure);
            app.CurrentSubjectLabel.Text = 'Current Subject:';
            app.TabGroup2 = uitabgroup(app.UIFigure);
            app.TabGroup2.SelectionChangedFcn = createCallbackFcn(app, @TabGroup2SelectionChanged, true);
            app.EEGTabGr2 = uitab(app.TabGroup2);
            app.EEGTabGr2.Title = 'EEG';
            app.AverageReferenceButton = uibutton(app.EEGTabGr2, 'push');
            app.AverageReferenceButton.ButtonPushedFcn = createCallbackFcn(app, @AverageReferenceButtonPushed, true);
            app.AverageReferenceButton.Text = 'Average Reference';
            app.ICAButton = uibutton(app.EEGTabGr2, 'push');
            app.ICAButton.ButtonPushedFcn = createCallbackFcn(app, @ICAButtonPushed, true);
            app.ICAButton.Text = 'ICA';
            app.MEGTabGr2 = uitab(app.TabGroup2);
            app.MEGTabGr2.Title = 'MEG';
            app.RenameEventsButton = uibutton(app.MEGTabGr2, 'push');
            app.RenameEventsButton.ButtonPushedFcn = createCallbackFcn(app, @RenameEventsButtonPushed, true);
            app.RenameEventsButton.Text = 'Rename Events';
            app.DeleteEventsButton = uibutton(app.MEGTabGr2, 'push');
            app.DeleteEventsButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteEventsButtonPushed, true);
            app.DeleteEventsButton.Text = 'Delete Events';
            app.RemoveSimultaneousEventsButton = uibutton(app.MEGTabGr2, 'push');
            app.RemoveSimultaneousEventsButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveSimultaneousEventsButtonPushed, true);
            app.RemoveSimultaneousEventsButton.Text = 'Remove Simultaneous Events';
            app.DetectArtifactButton = uibutton(app.MEGTabGr2, 'push');
            app.DetectArtifactButton.ButtonPushedFcn = createCallbackFcn(app, @DetectArtifactButtonPushed, true);
            app.DetectArtifactButton.Text = 'Detect Artifact';
            app.SSPButton = uibutton(app.MEGTabGr2, 'push');
            app.SSPButton.ButtonPushedFcn = createCallbackFcn(app, @SSPButtonPushed, true);
            app.SSPButton.Text = 'SSP';
            app.ICAButton_2 = uibutton(app.MEGTabGr2, 'push');
            app.ICAButton_2.ButtonPushedFcn = createCallbackFcn(app, @ICAButton_2Pushed, true);
            app.ICAButton_2.Text = 'ICA';
            app.SelectDeselectallButton = uibutton(app.UIFigure, 'push');
            app.SelectDeselectallButton.ButtonPushedFcn = createCallbackFcn(app, @SelectDeselectallButtonPushed, true);
            app.SelectDeselectallButton.Text = 'Select/Deselect all';
            app.TabGroup1 = uitabgroup(app.UIFigure);
            app.TabGroup1.SelectionChangedFcn = createCallbackFcn(app, @TabGroup1SelectionChanged, true);
            app.EEGTabGr1 = uitab(app.TabGroup1);
            app.EEGTabGr1.Title = 'EEG';
            app.AddEEGPositionsButton = uibutton(app.EEGTabGr1, 'push');
            app.AddEEGPositionsButton.ButtonPushedFcn = createCallbackFcn(app, @AddEEGPositionsButtonPushed, true);
            app.AddEEGPositionsButton.Text = 'Add EEG Positions';
            app.RefineRegistrationButton = uibutton(app.EEGTabGr1, 'push');
            app.RefineRegistrationButton.ButtonPushedFcn = createCallbackFcn(app, @RefineRegistrationButtonPushed, true);
            app.RefineRegistrationButton.Text = 'Refine Registration';
            app.ProjectElectrodesonScalpButton = uibutton(app.EEGTabGr1, 'push');
            app.ProjectElectrodesonScalpButton.ButtonPushedFcn = createCallbackFcn(app, @ProjectElectrodesonScalpButtonPushed, true);
            app.ProjectElectrodesonScalpButton.Text = 'Project Electrodes on Scalp';
            app.DetectHeartbeatsButton = uibutton(app.EEGTabGr1, 'push');
            app.DetectHeartbeatsButton.ButtonPushedFcn = createCallbackFcn(app, @DetectHeartbeatsButtonPushed, true);
            app.DetectHeartbeatsButton.Text = 'Detect Heartbeats';
            app.ImportEventsButton = uibutton(app.EEGTabGr1, 'push');
            app.ImportEventsButton.ButtonPushedFcn = createCallbackFcn(app, @ImportEventsButtonPushed, true);
            app.ImportEventsButton.Text = 'Import Events';
            app.MEGTabGr1 = uitab(app.TabGroup1);
            app.MEGTabGr1.Title = 'MEG';
            app.ConvertEpochstoContinueButton = uibutton(app.MEGTabGr1, 'push');
            app.ConvertEpochstoContinueButton.ButtonPushedFcn = createCallbackFcn(app, @ConvertEpochstoContinueButtonPushed, true);
            app.ConvertEpochstoContinueButton.Text = 'Convert Epochs to Continue';
            app.FrequencyAnalysisPanel = uipanel(app.UIFigure);
            app.FrequencyAnalysisPanel.Title = 'Frequency Analysis';
            app.NotchFilterButton = uibutton(app.FrequencyAnalysisPanel, 'push');
            app.NotchFilterButton.ButtonPushedFcn = createCallbackFcn(app, @NotchFilterButtonPushed, true);
            app.NotchFilterButton.Text = 'Notch Filter';
            app.BandpassFilterButton = uibutton(app.FrequencyAnalysisPanel, 'push');
            app.BandpassFilterButton.ButtonPushedFcn = createCallbackFcn(app, @BandpassFilterButtonPushed, true);
            app.BandpassFilterButton.Text = 'Band-pass Filter';
            app.PowerSpectrumDensityButton = uibutton(app.FrequencyAnalysisPanel, 'push');
            app.PowerSpectrumDensityButton.ButtonPushedFcn = createCallbackFcn(app, @PowerSpectrumDensityButtonPushed, true);
            app.PowerSpectrumDensityButton.Text = 'Power Spectrum Density';
            app.Panel = uipanel(app.UIFigure);
            app.OpenBrainstormButton = uibutton(app.Panel, 'push');
            app.OpenBrainstormButton.ButtonPushedFcn = createCallbackFcn(app, @OpenBrainstormButtonPushed, true);
            app.OpenBrainstormButton.Text = 'Open Brainstorm ';
            app.TypeofanalysisDropDown = uidropdown(app.Panel);
            app.TypeofanalysisDropDown.Items = {'EEG', 'MEG'};
            app.TypeofanalysisDropDown.ValueChangedFcn = createCallbackFcn(app, @TypeofanalysisDropDownValueChanged, true);
            app.TypeofanalysisDropDown.Tag = 'FormatDeFichier';
            app.TypeofanalysisDropDown.Value = 'MEG';
            app.TypeofanalysisDropDownLabel = uilabel(app.Panel);
            app.TypeofanalysisDropDownLabel.Text = 'Type of analysis';
            app.AddTagButton = uibutton(app.Panel, 'push');
            app.AddTagButton.ButtonPushedFcn = createCallbackFcn(app, @AddTagButtonPushed, true);
            app.AddTagButton.Text = 'Add Tag';
            app.UpdateEventsButton = uibutton(app.Panel, 'push');
            app.UpdateEventsButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateEventsButtonPushed, true);
            app.UpdateEventsButton.Text = 'Update Events';
            app.ConverttoBIDSButton = uibutton(app.Panel, 'push');
            app.ConverttoBIDSButton.ButtonPushedFcn = createCallbackFcn(app, @ConverttoBIDSButtonPushed, true);
            app.ConverttoBIDSButton.Text = 'Convert to BIDS';
            app.UpdateHistoryButton = uibutton(app.Panel, 'push');
            app.UpdateHistoryButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateHistoryButtonPushed, true);
            app.UpdateHistoryButton.Text = 'Update History';
            app.ImportinDataBaseButton = uibutton(app.Panel, 'push');
            app.ImportinDataBaseButton.Text = 'Import in DataBase';
            app.ColorMode = uibutton(app.Panel, 'push');
            app.ColorMode.ButtonPushedFcn = createCallbackFcn(app, @ColorModePushed, true);
            app.ColorMode.Text = 'Dark Mode';
            app.SubjectStudyPanel = uipanel(app.UIFigure);
            app.SubjectStudyPanel.Title = 'Subject/Study';
            app.CreateSubjectButton = uibutton(app.SubjectStudyPanel, 'push');
            app.CreateSubjectButton.ButtonPushedFcn = createCallbackFcn(app, @CreateSubjectButtonPushed, true);
            app.CreateSubjectButton.Text = 'Create Subject';
            app.DeleteStudySubjectButton = uibutton(app.SubjectStudyPanel, 'push');
            app.DeleteStudySubjectButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteStudySubjectButtonPushed, true);
            app.DeleteStudySubjectButton.Text = 'Delete Study/Subject';
            app.ReviewRawFilesButton = uibutton(app.SubjectStudyPanel, 'push');
            app.ReviewRawFilesButton.ButtonPushedFcn = createCallbackFcn(app, @ReviewRawFilesButtonPushed, true);
            app.ReviewRawFilesButton.Text = 'Review Raw Files';
            app.ProtocolPanel = uipanel(app.UIFigure);
            app.ProtocolPanel.Title = 'Protocol';
            app.CreateProtocolButton = uibutton(app.ProtocolPanel, 'push');
            app.CreateProtocolButton.ButtonPushedFcn = createCallbackFcn(app, @CreateProtocolButtonPushed, true);
            app.CreateProtocolButton.Text = 'Create Protocol';
            app.LoadProtocolButton = uibutton(app.ProtocolPanel, 'push');
            app.LoadProtocolButton.ButtonPushedFcn = createCallbackFcn(app, @LoadProtocolButtonPushed, true);
            app.LoadProtocolButton.Text = 'Load Protocol';
            app.DeleteProtocolButton = uibutton(app.ProtocolPanel, 'push');
            app.DeleteProtocolButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteProtocolButtonPushed, true);
            app.DeleteProtocolButton.Text = 'Delete Protocol';
            app.EEGHyperlink = uihyperlink(app.UIFigure);
            app.EEGHyperlink.URL = 'https://neuroimage.usc.edu/brainstorm/Tutorials/Epilepsy';
            app.EEGHyperlink.Text = 'Brainstorm EEG Analysis Tutorial';
            app.MEGHyperlink = uihyperlink(app.UIFigure);
            app.MEGHyperlink.URL = 'https://neuroimage.usc.edu/brainstorm/Tutorials';
            app.MEGHyperlink.Text = 'Brainstorm MEG Analysis Tutorial';
            app.HistoryPanel = uipanel(app.UIFigure);
            app.HistoryPanel.Title = 'History';
            app.EventsDetectedPanel = uipanel(app.UIFigure);
            app.EventsDetectedPanel.Title = 'Events Detected';
            app.TESTINGButton = uibutton(app.UIFigure, 'push');
            app.TESTINGButton.ButtonPushedFcn = createCallbackFcn(app, @TESTINGButtonPushed, true);
            app.TESTINGButton.Text = 'TESTING';
            app.Hyperlink = uihyperlink(app.UIFigure);
            app.Hyperlink.URL = 'https://github.com/CorentinLabelle/EEG-MEG-Analysis-Tool';
            app.Hyperlink.Text = 'GitHub';
            app.OpenPIPBUILDButton = uibutton(app.UIFigure, 'push');
            app.OpenPIPBUILDButton.ButtonPushedFcn = createCallbackFcn(app, @OpenPIPBUILDButtonPushed, true);
            app.OpenPIPBUILDButton.Text = 'Open PIPBUILD';
            app.bids_edfButton = uibutton(app.UIFigure, 'push');
            app.bids_edfButton.ButtonPushedFcn = createCallbackFcn(app, @bids_edfButtonPushed, true);
            app.bids_edfButton.Text = 'bids_edf';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EEG_MEG_ANALYSIS

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end