classdef Pipeline_Builder < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TypeofArtifactPanel             matlab.ui.container.Panel
        HighFrequencymusclesensorCheckBox  matlab.ui.control.CheckBox
        LowFrequencyeyeteethCheckBox    matlab.ui.control.CheckBox
        BlinkCheckBox                   matlab.ui.control.CheckBox
        CardiacCheckBox                 matlab.ui.control.CheckBox
        EventNameforArtifactEditField   matlab.ui.control.EditField
        EventNameforArtifactEditFieldLabel  matlab.ui.control.Label
        EventNameforSSPEditField        matlab.ui.control.EditField
        EventNameforSSPEditFieldLabel   matlab.ui.control.Label
        MEGProcessesPanel               matlab.ui.container.Panel
        ICAMEGCheckBox                  matlab.ui.control.CheckBox
        RemoveSilmultaneousEventCheckBox  matlab.ui.control.CheckBox
        SSPCheckBox                     matlab.ui.control.CheckBox
        DetectArtifactCheckBox          matlab.ui.control.CheckBox
        PowerSpectrumDensityMEGCheckBox  matlab.ui.control.CheckBox
        BandPassFilterMEGCheckBox       matlab.ui.control.CheckBox
        ConvertEpochtoContinueCheckBox  matlab.ui.control.CheckBox
        NotchFilterMEGCheckBox          matlab.ui.control.CheckBox
        EEGProcessesPanel               matlab.ui.container.Panel
        ICAEEGCheckBox                  matlab.ui.control.CheckBox
        AverageReferenceCheckBox        matlab.ui.control.CheckBox
        PowerSpectrumDensityEEGCheckBox  matlab.ui.control.CheckBox
        BandPassFiltereEEGCheckBox      matlab.ui.control.CheckBox
        NotchFilterEEGCheckBox          matlab.ui.control.CheckBox
        ProjectElectrodeonScalpCheckBox  matlab.ui.control.CheckBox
        RefineRegistrationCheckBox      matlab.ui.control.CheckBox
        AddEEGPositionCheckBox          matlab.ui.control.CheckBox
        NumberofComponentsEditField     matlab.ui.control.NumericEditField
        NumberofComponentsEditFieldLabel  matlab.ui.control.Label
        PipelineTypeDropDown            matlab.ui.control.DropDown
        PipelineTypeDropDownLabel       matlab.ui.control.Label
        ImportButton                    matlab.ui.control.Button
        ChooseCapListBox                matlab.ui.control.ListBox
        ChooseCapListBoxLabel           matlab.ui.control.Label
        AddEEGPositionGroup             matlab.ui.container.ButtonGroup
        ImportFileButton                matlab.ui.control.RadioButton
        UseDefaultPatternButton         matlab.ui.control.RadioButton
        SavePipelineButton              matlab.ui.control.Button
        BandPassFrequencesPanel         matlab.ui.container.Panel
        HighCutOffHzEditField           matlab.ui.control.NumericEditField
        HighCutOffHzEditFieldLabel      matlab.ui.control.Label
        LowCutOffHzEditField            matlab.ui.control.NumericEditField
        LowCutOffHzEditFieldLabel       matlab.ui.control.Label
        NotchGroup                      matlab.ui.container.ButtonGroup
        Frequence                       matlab.ui.control.TextArea
        SpecificFrequenceButton         matlab.ui.control.RadioButton
        EuropeButton                    matlab.ui.control.RadioButton
        NorthAmericaButton              matlab.ui.control.RadioButton
        BrowseButton                    matlab.ui.control.Button
        SaveinFolderEditField           matlab.ui.control.EditField
        SaveinFolderEditFieldLabel      matlab.ui.control.Label
        PipelineNameEditField           matlab.ui.control.EditField
        PipelineNameEditFieldLabel      matlab.ui.control.Label
    end

    
    properties (Access = private)
        % Description
        
        Path = '/mnt/3b5a15cf-20ff-4840-8d84-ddbd428344e9/ALAB1/';
        
        Pipeline = struct();
        
        AddEEGPosition = struct();
        RefineRegistration = struct();
        ProjectElectrodeOnScalp = struct();
        NotchFilter = struct();
        BandPassFilter = struct();
        PowerSpectrumDensity = struct();
        AverageReference = struct();
        ICA = struct();
    end
    
    methods (Access = private)
        
        function param = getNotchFilterParameters(app)
            
            % Get Notch Filter Parameters
            switch app.NotchGroup.SelectedObject
                case app.EuropeButton
                    param = [50, 100, 150, 200];
                    
                case app.NorthAmericaButton
                    param = [60, 120, 180, 240];
                    
                case app.SpecificFrequenceButton
                    % Assuming frequences are separated by commas.
                    frequences = split(app.Frequence.Value, ',');
                    nbFrequence = length(frequences);
                     
                    param = zeros(1, nbFrequence);
                    for i = 1:nbFrequence
                        param(i) = string(frequences{i});
                    end

                    return
            end
            
        end
        
        function EEGPositionCapStructure = getEEGPositionParameters(app)
            
            % Get EEG Position Parameters
            
            switch app.ChooseCapListBox.Value
                case "Colin27: BrainProducts EasyCap 128"
                    capNumber = 22;
                otherwise
                    capNumber = 0;
            end

            EEGPositionCapStructure.Cap = app.ChooseCapListBox.Value;
            EEGPositionCapStructure.CapNumber = capNumber;
            
        end
        
        function processStructure = getEEGPipelineProcesses(app)
            
            processStructure = struct();
            
            if (app.RefineRegistrationCheckBox.Value)
                processStructure.RefineRegistration.ToRun = true;
            end
            
            if (app.ProjectElectrodeonScalpCheckBox.Value)
                processStructure.ProjectElectrodeOnScalp.ToRun = true;
            end
            
            if (app.AddEEGPositionCheckBox.Value)
                processStructure.AddEEGPosition.ToRun = true;
                processStructure.AddEEGPosition.FileType = app.AddEEGPositionGroup.SelectedObject.Text;
                processStructure.AddEEGPosition.Parameters = app.getEEGPositionParameters;
            end
            
            if (app.NotchFilterEEGCheckBox.Value)
                processStructure.NotchFilter.ToRun = true;
                processStructure.NotchFilter.FrequenceType = app.NotchGroup.SelectedObject;
                processStructure.NotchFilter.Parameters = app.getNotchFilterParameters;
            end
            
            if (app.BandPassFiltereEEGCheckBox.Value)
                processStructure.BandPassFilter.ToRun = true;
                processStructure.BandPassFilter.Parameters = [app.LowCutOffHzEditField.Value, app.HighCutOffHzEditField.Value];
            end
            
            if (app.PowerSpectrumDensityEEGCheckBox.Value)
                processStructure.PowerSpectrumDensity.ToRun = true;
            end
            
            if (app.AverageReferenceCheckBox.Value)
                processStructure.AverageReference.ToRun  = true;
            end
            
            if (app.ICAEEGCheckBox.Value)
                processStructure.ICA.ToRun = true;
                processStructure.ICA.NumberOfComponents = app.NumberofComponentsEditField.Value;
            end
            
            return
        end
        
        function processStructure = getMEGPipelineProcesses(app)
            
            processStructure = struct();
            
            if (app.ConvertEpochtoContinueCheckBox.Value)
                processStructure.ConvertEpochtoContinue.ToRun = true;
            end
            
            if (app.NotchFilterMEGCheckBox.Value)
                processStructure.NotchFilter.ToRun = true;
                processStructure.NotchFilter.FrequenceType = app.NotchGroup.SelectedObject;
                processStructure.NotchFilter.Parameters = app.getNotchFilterParameters;
            end
            
            if (app.BandPassFilterMEGCheckBox.Value)
                processStructure.BandPassFilter.ToRun = true;
                processStructure.BandPassFilter.Parameters = [app.LowCutOffHzEditField.Value, app.HighCutOffHzEditField.Value];
            end
            
            if (app.PowerSpectrumDensityMEGCheckBox.Value)
                processStructure.PowerSpectrumDensity.ToRun = true;
            end
        
            if (app.DetectArtifactCheckBox.Value)
                processStructure.DetectArtifact.toRun = true;
                
                checkBoxes = app.TypeofArtifactPanel.Children;
                type = [];
                
                for i = 0:length(checkBoxes)
                    
                    if (checkBoxes(i).Value)
                        type = [type checkBoxes(i).Text];
                    end
                    
                    processStructure.DetectArtifact.ArtifactType = type;
                end
            end
            
            %if (app.RemoveSimultaneousCheckBox.Value)
            %end
            
            if (app.SSPCheckBox.Value)
                processStructure.SSP.ToRun = true;
                processStructure.SSP.EventName = app.EventNameforSSPEditField.Value;
            end
            
            if (app.ICAEEGCheckBox.Value)
                processStructure.ICA.ToRun = true;
                processStructure.ICA.NumberOfComponents = app.NumberofComponentsEditField.Value;
            end
        end
        
        function [] = fillCheckBoxWithStructure(app, pipelineStructure)
            
            fields = fieldnames(pipelineStructure.Pipeline.Processes);
            
            for i = 1:length(fields)
                
                field = fields{i};
                
                if field == "RefineRegistration"
                    app.RefineRegistrationCheckBox.Value = true;
                
                
                elseif field == "AddEEGPosition"
                    app.AddEEGPositionCheckBox.Value = true;

                              
                elseif field == "ProjectElectrodeOnScalp"
                    app.ProjectElectrodeonScalpCheckBox.Value = true;
                    
                    
                elseif field == "NotchFilter"
                    app.NotchFilterEEGCheckBox.Value = true;
                    
                    strFrequenceType = pipelineStructure.Pipeline.Processes.NotchFilter.FrequenceType.Text;
                    
                    if (contains(strFrequenceType, 'Europe'))
                        app.EuropeButton.Value = true;
                        
                    elseif (contains(strFrequenceType, 'America'))
                        app.NorthAmericaButton.Value = true;
                        
                    elseif (contains(strFrequenceType, 'Frequence'))
                       app.SpecificFrequenceButton.Value = true;
                       app.NotchGroupSelectionChanged;
                       
                       frequences = pipelineStructure.Pipeline.Processes.NotchFilter.Parameters;
                       app.Frequence.Value = '';
                       for j = 1:length(frequences)
                            app.Frequence.Value = strcat(app.Frequence.Value, sprintf('%.0f', frequences(j)), ', ');
                       end
                       app.Frequence.Value = app.Frequence.Value{1}(1:end-1);
                      
                        
                    end
                    
                elseif field == "BandPassFilter"
                    app.BandPassFiltereEEGCheckBox.Value = true;
                    app.LowCutOffHzEditField.Value = pipelineStructure.Pipeline.Processes.BandPassFilter.Parameters(1);
                    app.HighCutOffHzEditField.Value = pipelineStructure.Pipeline.Processes.BandPassFilter.Parameters(2);


                elseif field == "PowerSpectrumDensity"
                    app.PowerSpectrumDensityEEGCheckBox.Value = true;
                    
                    
                elseif field == "AverageReference"
                    app.AverageReferenceCheckBox.Value = true;   
                    
                    
                elseif field == "ICA"
                    app.ICAEEGCheckBox.Value = true;
                    app.NumberofComponentsEditField.Value = pipelineStructure.Pipeline.Processes.ICA.NumberOfComponents;
                
                end
                
                app.refreshVisibility;
                
            end
            
            
        end
        
        function [] = refreshVisibility(app)
            
            app.AddEEGPositionCheckBoxValueChanged;
            app.NotchFilterEEGCheckBoxValueChanged;
            app.NotchFilterEEGCheckBoxValueChanged;
            app.BandPassFiltereEEGCheckBoxValueChanged;
            app.BandPassFiltereEEGCheckBoxValueChanged;
            app.ICAEEGCheckBoxValueChanged;
            app.ICAMEGCheckBoxValueChanged;
            app.DetectArtifactCheckBoxValueChanged;
            app.SSPCheckBoxValueChanged;
            
        end
    end
    
    
    methods(Static)
        
    function [] = savePipeline(fileName, filePath, structure)

             save(fullfile(filePath, fileName), "-struct", "structure");
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            choice = ["EEG", "MEG"];
            rep = string(uiconfirm(app.UIFigure, 'What type of analysis do you want to conduct ?', 'Type of analysis', 'Options', choice));
            
            switch rep
                case choice(1)
                    app.EEGProcessesPanel.Visible = true;
                    app.MEGProcessesPanel.Visible = false;
                    app.PipelineTypeDropDown.Value = "EEG";
                    
                case choice(2)
                   app.MEGProcessesPanel.Visible = true;
                   app.EEGProcessesPanel.Visible = false;
                   app.PipelineTypeDropDown.Value = "MEG";
            end
        end

        % Value changed function: PipelineTypeDropDown
        function PipelineTypeDropDownValueChanged(app, event)
            switch app.PipelineTypeDropDown.Value
                case 'EEG'
                    app.EEGProcessesPanel.Visible = true;
                    app.MEGProcessesPanel.Visible = false;
                    
                    checkBoxes = app.MEGProcessesPanel.Children;
                    for i = 1:length(checkBoxes)
                        checkBoxes(i).Value = false;
                    end
                    
                case 'MEG'
                    app.MEGProcessesPanel.Visible = true;
                    app.EEGProcessesPanel.Visible = false;
                    
                    checkBoxes = app.EEGProcessesPanel.Children;
                    for i = 1:length(checkBoxes)
                        checkBoxes(i).Value = false;
                    end
            end
            
            
            app.refreshVisibility;
            
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            path = uigetdir('/mnt/3b5a15cf-20ff-4840-8d84-ddbd428344e9/ALAB1/');
            app.SaveinFolderEditField.Value = path;
            
            fieldPosition = app.SaveinFolderEditField.Position;
            assignin("base", "long", length(path))
            app.SaveinFolderEditField.Position = [fieldPosition(1), fieldPosition(2), length(path) + 300, fieldPosition(4)];
        end

        % Value changed function: AddEEGPositionCheckBox
        function AddEEGPositionCheckBoxValueChanged(app, event)

            
            if app.AddEEGPositionCheckBox.Value
                
                app.AddEEGPositionGroup.Visible = true;
                app.AddEEGPositionGroupSelectionChanged;
                
            else
                
                app.AddEEGPositionGroup.Visible = false;
                app.ChooseCapListBox.Visible = false;
                
            end
            
        end

        % Value changed function: NotchFilterEEGCheckBox
        function NotchFilterEEGCheckBoxValueChanged(app, event)
 
            if app.NotchFilterEEGCheckBox.Value
                
                app.NotchGroup.Visible = true;
               
            else
                
                app.NotchGroup.Visible = false;
                
            end
            
        end

        % Value changed function: BandPassFiltereEEGCheckBox
        function BandPassFiltereEEGCheckBoxValueChanged(app, event)

            if app.BandPassFiltereEEGCheckBox.Value
                
                app.BandPassFrequencesPanel.Visible = true;
               
            else
                
                app.BandPassFrequencesPanel.Visible = false;
                
            end
            
        end

        % Selection changed function: NotchGroup
        function NotchGroupSelectionChanged(app, event)
            switch app.NotchGroup.SelectedObject
                
                case app.EuropeButton
                    app.Frequence.Visible = false;
                    
                case app.NorthAmericaButton
                    app.Frequence.Visible = false;
                    
                case app.SpecificFrequenceButton
                    app.Frequence.Visible = true;
                    
            end
        end

        % Selection changed function: AddEEGPositionGroup
        function AddEEGPositionGroupSelectionChanged(app, event)
            switch app.AddEEGPositionGroup.SelectedObject
                
                case app.UseDefaultPatternButton
                    app.ChooseCapListBox.Visible = true;
                    
                case app.ImportFileButton
                    app.ChooseCapListBox.Visible = false;
            end
        end

        % Value changed function: Frequence
        function FrequenceValueChanged(app, event)
            value = app.Frequence.Value;
            
            position = app.Frequence.Position;
            
            app.Frequence.Position = [position(1) position(2) length(value{1}) + 100 position(4)];
            
        end

        % Value changed function: PipelineNameEditField
        function PipelineNameEditFieldValueChanged(app, event)
            value = app.PipelineNameEditField.Value;
            
            assignin("base", "value", value);
            
            position = app.PipelineNameEditField.Position;
            
            app.PipelineNameEditField.Position = [position(1) position(2) length(value) + 200 position(4)];
        end

        % Button pushed function: SavePipelineButton
        function SavePipelineButtonPushed(app, event)

            
            app.Pipeline = struct();
            
            app.Pipeline.Name = app.PipelineNameEditField.Value;
            
            app.Pipeline.Folder = app.SaveinFolderEditField.Value;
            
            if app.PipelineTypeDropDown.Value == "EEG"
                
                app.Pipeline.Processes = app.getEEGPipelineProcesses;
                
            elseif app.PipelineTypeDropDown.Value == "MEG"
                
                app.Pipeline.Processes = app.getMEGPipelineProcesses;
                
            end
            
            
            app.Pipeline.NumberOfProcesses = length(fieldnames(app.Pipeline.Processes));
            
            
            c = clock;
            c(6) = round(c(6));
            app.Pipeline.DateOfCreation = c;


            fileName = strcat(app.PipelineNameEditField.Value, '.mat');
            filePath = app.SaveinFolderEditField.Value;
            
            app.savePipeline(fileName, filePath, app.Pipeline)
        end

        % Button pushed function: ImportButton
        function ImportButtonPushed(app, event)
            
            [fileName, folderPath] = uigetfile('*.mat', 'Select MAT file', app.Path);
            
            app.PipelineNameEditField.Value = fileName(1:end-4);
            app.SaveinFolderEditField.Value = folderPath;

            app.Pipeline = struct();
            app.Pipeline = load(strcat(folderPath, fileName));
            
            
            app.fillCheckBoxWithStructure(app.Pipeline);
            
        end

        % Value changed function: ICAEEGCheckBox
        function ICAEEGCheckBoxValueChanged(app, event)
            
           if (app.ICAEEGCheckBox.Value)              
               app.NumberofComponentsEditField.Visible = true;
               app.NumberofComponentsEditFieldLabel.Visible = true;
           else
               app.NumberofComponentsEditField.Visible = false;
               app.NumberofComponentsEditFieldLabel.Visible = false;
           end
            
        end

        % Value changed function: DetectArtifactCheckBox
        function DetectArtifactCheckBoxValueChanged(app, event)

            if (app.DetectArtifactCheckBox.Value)
                app.TypeofArtifactPanel.Visible = true;
                
            else 
                app.TypeofArtifactPanel.Visible = false;
                
            end
            
        end

        % Value changed function: SSPCheckBox
        function SSPCheckBoxValueChanged(app, event)
            
            if (app.SSPCheckBox.Value)
                
                app.EventNameforSSPEditField.Visible = true;
                app.EventNameforSSPEditFieldLabel.Visible = true;
                
            else
                app.EventNameforSSPEditField.Visible = false;
                app.EventNameforSSPEditFieldLabel.Visible = false;
            end
            
            
        end

        % Value changed function: ICAMEGCheckBox
        function ICAMEGCheckBoxValueChanged(app, event)

            if (app.ICAMEGCheckBox.Value)              
                app.NumberofComponentsEditField.Visible = true;
                app.NumberofComponentsEditFieldLabel.Visible = true;             
            else
                app.NumberofComponentsEditField.Visible = false;
                app.NumberofComponentsEditFieldLabel.Visible = false;
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.PipelineNameEditFieldLabel = uilabel(app.UIFigure);
            app.PipelineNameEditFieldLabel.Text = 'Pipeline Name';
            app.PipelineNameEditField = uieditfield(app.UIFigure, 'text');
            app.PipelineNameEditField.ValueChangedFcn = createCallbackFcn(app, @PipelineNameEditFieldValueChanged, true);
            app.SaveinFolderEditFieldLabel = uilabel(app.UIFigure);
            app.SaveinFolderEditFieldLabel.Text = 'Save in Folder';
            app.SaveinFolderEditField = uieditfield(app.UIFigure, 'text');
            app.BrowseButton = uibutton(app.UIFigure, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.NotchGroup = uibuttongroup(app.UIFigure);
            app.NotchGroup.SelectionChangedFcn = createCallbackFcn(app, @NotchGroupSelectionChanged, true);
            app.NotchGroup.Title = 'Notch Frequences';
            app.NotchGroup.Visible = 'off';
            app.NorthAmericaButton = uiradiobutton(app.NotchGroup);
            app.NorthAmericaButton.Text = 'North America (60Hz, 120Hz, 180Hz, 240Hz)';
            app.NorthAmericaButton.Value = true;
            app.EuropeButton = uiradiobutton(app.NotchGroup);
            app.EuropeButton.Text = 'Europe (50Hz, 100Hz, 150Hz, 200Hz)';
            app.SpecificFrequenceButton = uiradiobutton(app.NotchGroup);
            app.SpecificFrequenceButton.Text = 'Specific Frequence:';
            app.Frequence = uitextarea(app.NotchGroup);
            app.Frequence.ValueChangedFcn = createCallbackFcn(app, @FrequenceValueChanged, true);
            app.Frequence.Visible = 'off';
            app.BandPassFrequencesPanel = uipanel(app.UIFigure);
            app.BandPassFrequencesPanel.Title = 'Band Pass Frequences';
            app.BandPassFrequencesPanel.Visible = 'off';
            app.LowCutOffHzEditFieldLabel = uilabel(app.BandPassFrequencesPanel);
            app.LowCutOffHzEditFieldLabel.Text = 'Low Cut Off (Hz)';
            app.LowCutOffHzEditField = uieditfield(app.BandPassFrequencesPanel, 'numeric');
            app.HighCutOffHzEditFieldLabel = uilabel(app.BandPassFrequencesPanel);
            app.HighCutOffHzEditFieldLabel.Text = 'High Cut Off (Hz)';
            app.HighCutOffHzEditField = uieditfield(app.BandPassFrequencesPanel, 'numeric');
            app.SavePipelineButton = uibutton(app.UIFigure, 'push');
            app.SavePipelineButton.ButtonPushedFcn = createCallbackFcn(app, @SavePipelineButtonPushed, true);
            app.SavePipelineButton.Text = 'Save Pipeline';
            app.AddEEGPositionGroup = uibuttongroup(app.UIFigure);
            app.AddEEGPositionGroup.SelectionChangedFcn = createCallbackFcn(app, @AddEEGPositionGroupSelectionChanged, true);
            app.AddEEGPositionGroup.Title = 'Original EEG position file';
            app.AddEEGPositionGroup.Visible = 'off';
            app.UseDefaultPatternButton = uiradiobutton(app.AddEEGPositionGroup);
            app.UseDefaultPatternButton.Text = 'Use Default Pattern';
            app.UseDefaultPatternButton.Value = true;
            app.ImportFileButton = uiradiobutton(app.AddEEGPositionGroup);
            app.ImportFileButton.Text = 'Import File';
            app.ChooseCapListBoxLabel = uilabel(app.UIFigure);
            app.ChooseCapListBoxLabel.Visible = 'off';
            app.ChooseCapListBoxLabel.Text = 'Choose Cap:';
            app.ChooseCapListBox = uilistbox(app.UIFigure);
            app.ChooseCapListBox.Items = {'Colin27: BrainProducts EasyCap 128'};
            app.ChooseCapListBox.Visible = 'off';
            app.ChooseCapListBox.Value = 'Colin27: BrainProducts EasyCap 128';
            app.ImportButton = uibutton(app.UIFigure, 'push');
            app.ImportButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.ImportButton.Text = 'Import';
            app.PipelineTypeDropDownLabel = uilabel(app.UIFigure);
            app.PipelineTypeDropDownLabel.Text = 'Pipeline Type';
            app.PipelineTypeDropDown = uidropdown(app.UIFigure);
            app.PipelineTypeDropDown.Items = {'EEG', 'MEG'};
            app.PipelineTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @PipelineTypeDropDownValueChanged, true);
            app.PipelineTypeDropDown.Value = 'EEG';
            app.NumberofComponentsEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofComponentsEditFieldLabel.Visible = 'off';
            app.NumberofComponentsEditFieldLabel.Text = 'Number of Components';
            app.NumberofComponentsEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberofComponentsEditField.Visible = 'off';
            app.EEGProcessesPanel = uipanel(app.UIFigure);
            app.EEGProcessesPanel.Title = 'EEG Processes';
            app.EEGProcessesPanel.Visible = 'off';
            app.AddEEGPositionCheckBox = uicheckbox(app.EEGProcessesPanel);
            app.AddEEGPositionCheckBox.ValueChangedFcn = createCallbackFcn(app, @AddEEGPositionCheckBoxValueChanged, true);
            app.AddEEGPositionCheckBox.Text = 'Add EEG Position';
            app.RefineRegistrationCheckBox = uicheckbox(app.EEGProcessesPanel);
            app.RefineRegistrationCheckBox.Text = 'Refine Registration';
            app.ProjectElectrodeonScalpCheckBox = uicheckbox(app.EEGProcessesPanel);
            app.ProjectElectrodeonScalpCheckBox.Text = 'Project Electrode on Scalp';
            app.NotchFilterEEGCheckBox = uicheckbox(app.EEGProcessesPanel);
            app.NotchFilterEEGCheckBox.ValueChangedFcn = createCallbackFcn(app, @NotchFilterEEGCheckBoxValueChanged, true);
            app.NotchFilterEEGCheckBox.Text = 'Notch Filter';
            app.BandPassFiltereEEGCheckBox = uicheckbox(app.EEGProcessesPanel);
            app.BandPassFiltereEEGCheckBox.ValueChangedFcn = createCallbackFcn(app, @BandPassFiltereEEGCheckBoxValueChanged, true);
            app.BandPassFiltereEEGCheckBox.Text = 'Band-Pass Filter';
            app.PowerSpectrumDensityEEGCheckBox = uicheckbox(app.EEGProcessesPanel);
            app.PowerSpectrumDensityEEGCheckBox.Text = 'Power Spectrum Density';
            app.AverageReferenceCheckBox = uicheckbox(app.EEGProcessesPanel);
            app.AverageReferenceCheckBox.Text = 'Average Reference';
            app.ICAEEGCheckBox = uicheckbox(app.EEGProcessesPanel);
            app.ICAEEGCheckBox.ValueChangedFcn = createCallbackFcn(app, @ICAEEGCheckBoxValueChanged, true);
            app.ICAEEGCheckBox.Text = 'ICA';
            app.MEGProcessesPanel = uipanel(app.UIFigure);
            app.MEGProcessesPanel.Title = 'MEG Processes';
            app.MEGProcessesPanel.Visible = 'off';
            app.NotchFilterMEGCheckBox = uicheckbox(app.MEGProcessesPanel);
            app.NotchFilterMEGCheckBox.Text = 'Notch Filter';
            app.ConvertEpochtoContinueCheckBox = uicheckbox(app.MEGProcessesPanel);
            app.ConvertEpochtoContinueCheckBox.Text = 'Convert Epoch to Continue';
            app.BandPassFilterMEGCheckBox = uicheckbox(app.MEGProcessesPanel);
            app.BandPassFilterMEGCheckBox.Text = 'Band-Pass Filter';
            app.PowerSpectrumDensityMEGCheckBox = uicheckbox(app.MEGProcessesPanel);
            app.PowerSpectrumDensityMEGCheckBox.Text = 'Power Spectrum Density';
            app.DetectArtifactCheckBox = uicheckbox(app.MEGProcessesPanel);
            app.DetectArtifactCheckBox.ValueChangedFcn = createCallbackFcn(app, @DetectArtifactCheckBoxValueChanged, true);
            app.DetectArtifactCheckBox.Text = 'Detect Artifact';
            app.SSPCheckBox = uicheckbox(app.MEGProcessesPanel);
            app.SSPCheckBox.ValueChangedFcn = createCallbackFcn(app, @SSPCheckBoxValueChanged, true);
            app.SSPCheckBox.Text = 'SSP';
            app.RemoveSilmultaneousEventCheckBox = uicheckbox(app.MEGProcessesPanel);
            app.RemoveSilmultaneousEventCheckBox.Text = 'Remove Silmultaneous Event';
            app.ICAMEGCheckBox = uicheckbox(app.MEGProcessesPanel);
            app.ICAMEGCheckBox.ValueChangedFcn = createCallbackFcn(app, @ICAMEGCheckBoxValueChanged, true);
            app.ICAMEGCheckBox.Text = 'ICA';
            app.EventNameforSSPEditFieldLabel = uilabel(app.UIFigure);
            app.EventNameforSSPEditFieldLabel.Visible = 'off';
            app.EventNameforSSPEditFieldLabel.Text = 'Event Name for SSP';
            app.EventNameforSSPEditField = uieditfield(app.UIFigure, 'text');
            app.EventNameforSSPEditField.Visible = 'off';
            app.EventNameforArtifactEditFieldLabel = uilabel(app.UIFigure);
            app.EventNameforArtifactEditFieldLabel.Visible = 'off';
            app.EventNameforArtifactEditFieldLabel.Text = 'Event Name for Artifact';
            app.EventNameforArtifactEditField = uieditfield(app.UIFigure, 'text');
            app.EventNameforArtifactEditField.Visible = 'off';
            app.TypeofArtifactPanel = uipanel(app.UIFigure);
            app.TypeofArtifactPanel.Title = 'Type of Artifact';
            app.TypeofArtifactPanel.Visible = 'off';
            app.CardiacCheckBox = uicheckbox(app.TypeofArtifactPanel);
            app.CardiacCheckBox.Text = 'Cardiac';
            app.BlinkCheckBox = uicheckbox(app.TypeofArtifactPanel);
            app.BlinkCheckBox.Text = 'Blink';
            app.LowFrequencyeyeteethCheckBox = uicheckbox(app.TypeofArtifactPanel);
            app.LowFrequencyeyeteethCheckBox.Text = 'Low Frequency (eye/teeth)';
            app.HighFrequencymusclesensorCheckBox = uicheckbox(app.TypeofArtifactPanel);
            app.HighFrequencymusclesensorCheckBox.Text = 'High Frequency (muscle/sensor)';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Pipeline_Builder

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end