classdef Analysis_Tool < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AnalysisToolUIFigure            matlab.ui.Figure
        ProtocolMenu                    matlab.ui.container.Menu
        CreateProtocolMenu              matlab.ui.container.Menu
        ReloadCurrentProtocolMenu       matlab.ui.container.Menu
        LoadProtocolMenu                matlab.ui.container.Menu
        DeleteProtocolMenu              matlab.ui.container.Menu
        SubjectMenu                     matlab.ui.container.Menu
        CreateSubjectMenu               matlab.ui.container.Menu
        DeleteSubjectMenu               matlab.ui.container.Menu
        StudyMenu                       matlab.ui.container.Menu
        ReviewRawFilesMenu              matlab.ui.container.Menu
        SelectComponentsMenu            matlab.ui.container.Menu
        DeleteStudyMenu                 matlab.ui.container.Menu
        EventMenu                       matlab.ui.container.Menu
        RenameEventMenu                 matlab.ui.container.Menu
        DeleteEventMenu                 matlab.ui.container.Menu
        ConversionMenu                  matlab.ui.container.Menu
        ExporttoEDFMenu                 matlab.ui.container.Menu
        ExporttoBrainVisionMenu         matlab.ui.container.Menu
        ExporttoBIDSMenu                matlab.ui.container.Menu
        OptionsMenu                     matlab.ui.container.Menu
        AnalysisTypeMenu                matlab.ui.container.Menu
        EEGMenu                         matlab.ui.container.Menu
        MEGMenu                         matlab.ui.container.Menu
        OpenBrainstormMenu              matlab.ui.container.Menu
        AddTagMenu                      matlab.ui.container.Menu
        ColorModeMenu                   matlab.ui.container.Menu
        Image3                          matlab.ui.control.Image
        Image2                          matlab.ui.control.Image
        Image                           matlab.ui.control.Image
        ulaval                          matlab.ui.control.Image
        braincanada                     matlab.ui.control.Image
        cervo                           matlab.ui.control.Image
        PROTOCOLPanel                   matlab.ui.container.Panel
        ExpandButton                    matlab.ui.control.Button
        CollapseButton                  matlab.ui.control.Button
        SelectRawButton                 matlab.ui.control.Button
        SelectImportedButton            matlab.ui.control.Button
        CurrentProtocolLabel            matlab.ui.control.Label
        CurrentProtocolEditField        matlab.ui.control.EditField
        UpdateTreeButton                matlab.ui.control.Button
        SelectDeselectallButton         matlab.ui.control.Button
        POSTPROCESSPanel                matlab.ui.container.Panel
        ComputeSourcesButton            matlab.ui.control.Button
        AverageButton                   matlab.ui.control.Button
        SelectGoodTrialButton           matlab.ui.control.Button
        RejectTrialsButton              matlab.ui.control.Button
        ImportinDataBaseButton          matlab.ui.control.Button
        Hyperlink2_2                    matlab.ui.control.Hyperlink
        Hyperlink2                      matlab.ui.control.Hyperlink
        Hyperlink                       matlab.ui.control.Hyperlink
        Tree                            matlab.ui.container.CheckBoxTree
        PIPELINEPanel                   matlab.ui.container.Panel
        RunPipelineButton               matlab.ui.control.Button
        ImportPipelineButton            matlab.ui.control.Button
        ClearPipelineButton             matlab.ui.control.Button
        OpenPipelineBuilderButton       matlab.ui.control.Button
        UpdateEventsButton              matlab.ui.control.Button
        UpdateHistoryButton             matlab.ui.control.Button
        ClearEventsButton               matlab.ui.control.Button
        ClearHistoryButton              matlab.ui.control.Button
        PipelineTextArea                matlab.ui.control.TextArea
        EVENTSPanel                     matlab.ui.container.Panel
        HISTORYPanel                    matlab.ui.container.Panel
        FREQUENCYPanel                  matlab.ui.container.Panel
        PowerSpectrumDensityButton      matlab.ui.control.Button
        BandpassFilterButton            matlab.ui.control.Button
        NotchFilterButton               matlab.ui.control.Button
        TabGroup1                       matlab.ui.container.TabGroup
        EEGTabGr1                       matlab.ui.container.Tab
        ImportEventsButton              matlab.ui.control.Button
        DetectHeartbeatsButton          matlab.ui.control.Button
        ProjectElectrodesonScalpButton  matlab.ui.control.Button
        RefineRegistrationButton        matlab.ui.control.Button
        AddEEGPositionsButton           matlab.ui.control.Button
        MEGTabGr1                       matlab.ui.container.Tab
        ConvertEpochstoContinueButton   matlab.ui.control.Button
        TabGroup2                       matlab.ui.container.TabGroup
        EEGTabGr2                       matlab.ui.container.Tab
        ICAEEGButton                    matlab.ui.control.Button
        AverageReferenceButton          matlab.ui.control.Button
        MEGTabGr2                       matlab.ui.container.Tab
        ICAMEGButton                    matlab.ui.control.Button
        SSPButton                       matlab.ui.control.Button
        DetectArtifactButton            matlab.ui.control.Button
        RemoveSimultaneousEventsButton  matlab.ui.control.Button
    end


    properties (Access = public)

        % Global Variable
        userAnswer = string;
        Testing = false;
        
        controller ControllerAnalysisTool;
        app_Manager App_Manager;
        
    end        

    methods (Access = public)
        
        function answer = returnTextInputValues(app, answer)
            app.userAnswer = answer;
        end
        
        function rep = list_dialog(~, question, choice, selectionMode)
            
            arguments
                ~
                question char
                choice cell
                selectionMode char = 'single';
            end
            
            longestChoice = max(cellfun(@length, choice));
            
            index = listdlg('PromptString', question, 'ListString', choice, ...
                'SelectionMode', selectionMode, 'ListSize', [200 + 8*(longestChoice-10), 400]);
            
            if isempty(index)
                rep = [];
                return
            end
            
            rep = cell(1, length(index));
            for i = 1:length(index)
                rep(i) = choice(index(i));
            end
            
            rep = string(rep);
        
        end
        
        function rep = input_dialog(~, question, size)
            
            arguments
                ~
                question char;
                size double = [1, 35];
            end
            
            rep = inputdlg(question, question, size);
            
            if isempty(rep)
                return
            end
            
            rep = rep{1};
            
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, type)

            app.controller = ControllerAnalysisTool();
            
            app.app_Manager = App_Manager(app);

            % Input for Type of Analysis
            if nargin == 1
                choice = ["EEG", "MEG"];
                type = uiconfirm(app.AnalysisToolUIFigure, "What type of analysis do you want to conduct ?", ...
                    "Type of analysis", "Options", choice);
            end
            
            app.app_Manager.switch_Analysis_Type(upper(type));
            
            % Input for Working Folder
            if nargin == 1
                wf = uigetdir(app.controller.WorkingFolder, "Select your Working Folder");
            
                if ~isequal(wf, 0)
                    app.controller.asgWorkingFolderPath(wf)
                else
                    % If wf is empty, we keep the default Working Folder.
                    uiconfirm(app.AnalysisToolUIFigure, "Default Working Folder will be : " + app.controller.getWorkingFolderPath,...
                        "Default Working Folder");
                end
            end
            
            % Create folder
            app.controller.createWorkingFolder();
            
        end

        % Button pushed function: OpenPipelineBuilderButton
        function OpenPipelineBuilderButtonPushed(app, event)
            % Open Pipeline Builder
            run('Pipeline_Builder.mlapp');
        end

        % Button pushed function: ImportPipelineButton
        function ImportPipelineButtonPushed(app, event)
            
            % User input Pipeline File
            [file, folder] = uigetfile(app.controller.getPipelineSupportedExtensionToGetFile, 'Select file', app.controller.getPipelineSearchPath);
            
            % If Cancel
            if (file == 0)
                return
            end
            
            % Update PipelineSearchPath
            app.controller.asgPipelineSearchPath(folder);
            
            % Load Pipeline
            app.controller.loadPipeline(fullfile(folder, file));
            app.PipelineTextArea.Value = app.controller.printCurrentPipeline();
            
        end

        % Button pushed function: ClearPipelineButton
        function ClearPipelineButtonPushed(app, event)

            % Clear Pipeline
            app.controller.clearPipeline();
            app.PipelineTextArea.Value = "NO PIPELINE LOADED";
           
        end

        % Button pushed function: RunPipelineButton
        function RunPipelineButtonPushed(app, event)

            sFiles = app.app_Manager.selected_Studies();
            
            app.controller.runPipeline(sFiles);
            
            app.app_Manager.update_Tree_And_Check_New_Studies();  
        end

        % Button pushed function: NotchFilterButton
        function NotchFilterButtonPushed(app, event)

            sFiles = app.app_Manager.selected_Studies();
            
            % Input for Frequences
            choice = ["I am in North America (60 Hz, 120 Hz, 180 Hz, ...)", ...
                "I am in Europe (50 Hz, 100 Hz, 150 Hz, ...)", "Specific Frequence"];
            rep = uiconfirm(app.AnalysisToolUIFigure, 'Where has your protocol been conducted ?', ...
                'Notch filter', 'Options', choice);

            switch rep
                case choice(1)
                    frequence = [60 120 180 240];
                case choice(2)
                    frequence = [50 100 150 200];
                case choice(3)
                    question = 'Enter the frequences (Hz): ';
                    %app.app_Manager.ask_user(question);
                    %frequence = double(string(app.userAnswer));
                    frequence = app.input_dialog(question);
                    
            end
            
            app.controller.notchFilter(sFiles, frequence);
            
            % Update tree and Check new studies
            app.app_Manager.update_Tree_And_Check_New_Studies();
   
        end

        % Button pushed function: BandpassFilterButton
        function BandpassFilterButtonPushed(app, event)

            sFiles = app.app_Manager.selected_Studies();

            % Input for Frequences
            question = 'Lower cut off frequency (Hz): ';
            %app.app_Manager.ask_user(question);
            %LowPass = app.userAnswer;
            LowPass = str2double(app.input_dialog(question));

            question = 'Higher cut off frequency (Hz): ';
            %app.app_Manager.ask_user(question);
            %HighPass = app.userAnswer;
            HighPass = str2double(app.input_dialog(question));

            if isempty(HighPass) || isempty(LowPass)
                uialert(app.AnalysisToolUIFigure, "Please enter an upper and lower frequency cutoff points.", ...
                    'Error')
                return
            end

            app.controller.bandPassFilter(sFiles, [LowPass HighPass]);
            
            app.app_Manager.update_Tree_And_Check_New_Studies();         
            
        end

        % Button pushed function: PowerSpectrumDensityButton
        function PowerSpectrumDensityButtonPushed(app, event)

            sFiles = app.app_Manager.selected_Studies();
            
            windowLength = 10;
            
            app.controller.powerSpectrumDensity(sFiles, windowLength);

            
        end

        % Button pushed function: ConvertEpochstoContinueButton
        function ConvertEpochstoContinueButtonPushed(app, event)
            sFiles = app.app_Manager.selected_Studies();
            
            app.controller.convertEpochsToContinue(sFiles);
        end

        % Button pushed function: DetectArtifactButton
        function DetectArtifactButtonPushed(app, event)
            sFiles = app.app_Manager.selected_Studies();
             
            % Input for type of artifact
            choice = ["Cardiac", "Blink", "Low Frequency Artifact (1-7 Hz; eye/teeth movement)", ...
                        "High Frequency Artifact (40-240 Hz; muscle movement/sensor artifacts)"];
            question = 'Choose Artifact';
            %app.app_Manager.ask_user_list(choice, question, 'on');
            %artifacts = app.userAnswer;
            artifacts = app.list_dialog(question, choice);
            if isempty(artifacts)
                return
            end
            
            lowFrequence = 0;
            highFrequence = 0;
            if (any(choice(1) == artifacts))
                channelName = 'ECG';
                app.controller.checkIfChannelIsInChannelFile(sFiles, channelName);
                app.controller.detectCardiacArtifact(sFiles, channelName, 'cardiac');
            end
            
            if (any(choice(2) == artifacts))
                channelName = 'VEOG';
                app.controller.checkIfChannelIsInChannelFile(sFiles, channelName);
                app.controller.detectBlinkArtifact(sFiles, channelName, 'blink');
            end
            
            if (any(choice(3) == artifacts))
                    lowFrequence = 1;
            end
            
            if (any(choice(4) == artifacts))
                    highFrequence = 1;
            end
            
            if (lowFrequence ~= 0 || highFrequence ~= 0)
                app.controller.detectOtherArtifact(sFiles, lowFrequence, highFrequence);
            end
        end

        % Button pushed function: RemoveSimultaneousEventsButton
        function RemoveSimultaneousEventsButtonPushed(app, event)
            sFiles = app.app_Manager.selected_Studies();

            % Display all Events
            choice = app.app_Manager.getEvents(sFiles);

            question = 'Remove the event named: ';
            %app.app_Manager.ask_user_list(choice, question, 'off');
            %eventRename = app.userAnswer;
            eventRename = app.list_dialog(question, choice);
            
            question = 'When it is too close to the event: ';
            %app.app_Manager.ask_user_list(choice, question, 'off');
            %eventTarget = app.userAnswer;
            eventTarget = app.list_dialog(question, choice);
            
            if isempty(eventRename) || isempty(eventTarget)
                waitfor(errordlg("Missing event to remove or to targert."))
                return
            end
            
            app.controller.removeSimultaneaousEvents(sFiles, eventRename, eventTarget);
            
        end

        % Button pushed function: SSPButton
        function SSPButtonPushed(app, event)
            sFiles = app.app_Manager.selected_Studies();

            % Display all Events
            choice = app.app_Manager.getEvents(sFiles);
            question = 'Which event do you want to use to compute the SSP ?';

            %app.app_Manager.ask_user_list(choice, question, 'off');
            %eventName = app.userAnswer;
            eventName = app.list_dialog(question, choice);
            
            if isempty(eventName)
                waitfor(errordlg("Missing event to remove or to targert."))
                return
            end
            
            
            if (eventName == "cardiac")
                app.controller.sspCardiac(sFiles);
            
            elseif (eventName == "blink")
                app.controller.sspBlink
            
            else
                app.controller.sspGeneric(sFiles, eventName);
            end
            
        end

        % Button pushed function: AddEEGPositionsButton
        function AddEEGPositionsButtonPushed(app, event)
            sFiles = app.app_Manager.selected_Studies();

            % Input for either Default Pattern or Import File
            choice = ["Use default pattern", "Import a file ", "Cancel"];
            rep = uiconfirm(app.AnalysisToolUIFigure, 'Import a file or use a default ?', ...
                'EEG position', 'Options', choice);
            
            switch rep
                case choice(1)
                    % List of available cap
                    caps = {'Colin27: BrainProducts EasyCap 128'};
                    %app.app_Manager.ask_user_list(fields(capTypes), 'Choose your Cap!', 'off');
                    %cap = app.userAnswer;
                    cap = app.list_dialog('Choose your Cap!', caps);
                    
                    if isempty(cap)
                        return
                    end
                    
                    fileType = 'Use Default Pattern';
                    electrodeFile = char.empty();
                    
                case choice(2)
                    % Select electrode file
                    title = "Select the electrode file (it should have the *.elc extension)";
                    extension = {'*.elc'};
                    [file, ~] = app.app_Manager.getFilePath(title, extension);
                    
                    fileType = 'Import';
                    cap = char.empty();
                    electrodeFile = file;
                    
                    
                otherwise
                    return
            end
            
            app.controller.addEegPosition(sFiles, fileType, cap, electrodeFile);
        end

        % Button pushed function: RefineRegistrationButton
        function RefineRegistrationButtonPushed(app, event)
            sFiles = app.app_Manager.selected_Studies();
            
            app.controller.refineRegistration(sFiles);
        end

        % Button pushed function: ProjectElectrodesonScalpButton
        function ProjectElectrodesonScalpButtonPushed(app, event)
            sFiles = app.app_Manager.selected_Studies();
            
            app.controller.projectElectrodesOnScalp(sFiles);
        end

        % Button pushed function: DetectHeartbeatsButton
        function DetectHeartbeatsButtonPushed(app, event)
            sFiles = app.app_Manager.selected_Studies();
            
            channelName = 'ECG';
            app.controller.checkIfChannelIsInChannelFile(sFiles, channelName);
            
            app.controller.detectCardiacArtifact(sFiles, channelName, 'cardiac')
        end

        % Button pushed function: ImportEventsButton
        function ImportEventsButtonPushed(app, event)

            sFiles = app.app_Manager.selected_Studies();

            title = "Select the event file (it should have the *.txt extension)";
            extension = {'*.txt'};
            
            [filePath, ~] = app.app_Manager.getFilePath(title, extension);
            
            question = 'Name of the imported events:';
            %app.ask_user(question);
            %eventName = app.userAnswer;
            eventName = app.input_dialog(question);

            app.BasicFct.importEventFromFile(sFiles, filePath, eventName);

        end

        % Button pushed function: AverageReferenceButton
        function AverageReferenceButtonPushed(app, event)
            sFiles = app.app_Manager.selected_Studies();
            
            app.controller.averageReference(sFiles);
        end

        % Button pushed function: ICAEEGButton
        function ICAEEGButtonPushed(app, event)

            sFiles = app.app_Manager.selected_Studies();

            % Input for number of components
            question = 'How many ICA components (nb electrode / 2): ';
            %app.app_Manager.ask_user(question);
            %ICAcomponents = app.userAnswer;
            ICAcomponents = app.input_dialog(question);

            if isempty(ICAcomponents)
                return
            end

            nbComponents = str2double(ICAcomponents);
            
            app.controller.ica(sFiles, nbComponents);
            
%             try
%                 event.Source
%             catch
%                 process = app.controller.createProcess('ICA', parameters);
%                 app.controller.run(process, sFiles);
%                 %app.meg_Analysis.ica(sFiles, nbComponents);
%                 return
%             end
        end

        % Button pushed function: ICAMEGButton
        function ICAMEGButtonPushed(app, event)
            app.ICAEEGButtonPushed();
        end

        % Selection change function: TabGroup1
        function TabGroup1SelectionChanged(app, event)
            app.app_Manager.switch_Analysis_Type(app.TabGroup1.SelectedTab.Title);
        end

        % Selection change function: TabGroup2
        function TabGroup2SelectionChanged(app, event)
           app.app_Manager.switch_Analysis_Type(app.TabGroup2.SelectedTab.Title);
        end

        % Button pushed function: SelectDeselectallButton
        function SelectDeselectallButtonPushed(app, event)
            app.app_Manager.select_All_Studies();
        end

        % Button pushed function: UpdateTreeButton
        function UpdateTreeButtonPushed(app, event)
            app.app_Manager.update_Tree_And_Check_New_Studies();
        end

        % Button pushed function: UpdateEventsButton
        function UpdateEventsButtonPushed(app, event)
            sFiles = app.app_Manager.selected_Studies();
            delete(app.EVENTSPanel.Children());
            app.app_Manager.create_History_Event_Button(sFiles, 'event')
        end

        % Button pushed function: UpdateHistoryButton
        function UpdateHistoryButtonPushed(app, event)
            sFiles = app.app_Manager.selected_Studies();
            delete(app.HISTORYPanel.Children());
            app.app_Manager.create_History_Event_Button(sFiles, 'history')
        end

        % Button pushed function: ClearHistoryButton
        function ClearHistoryButtonPushed(app, event)
            delete(app.HISTORYPanel.Children);
        end

        % Button pushed function: ClearEventsButton
        function ClearEventsButtonPushed(app, event)
            delete(app.EVENTSPanel.Children);
        end

        % Button pushed function: ImportinDataBaseButton
        function ImportinDataBaseButtonPushed(app, event)
            
            sFiles = app.app_Manager.selected_Studies();
            
            % Select Event
            allEvents = app.app_Manager.getEvents(sFiles);
            %app.app_Manager.ask_user_list(allEvents, 'Import Events', 'on');
            %eventCell = app.userAnswer;
            eventCell = app.list_dialog('Import Events', allEvents, 'multiple');
            
            eventStr = '';
            for i = 1:length(eventCell)
                eventStr = strcat(eventStr, eventCell{i}, ',');
            end
            eventStr = eventStr(1:end-1);
            
            if isempty(event)
                waitfor(errordlg("Please select at least one event"))
                return
            end    
            
            % Input for Epoch Time
            question = 'Epoch Time in seconds (format -X.X ; X.X): ';
            %app.app_Manager.ask_user(question);
            %epochTimeStr = app.userAnswer;
            epochTimeStr = app.input_dialog(question);
            epochTime = str2double(split(epochTimeStr, ';'));
            
            
            for i = 1:length(sFiles)
                subjectName = sFiles(i).SubjectName;
                app.BasicFct.importEvents(sFiles(i), subjectName, eventStr, epochTime);
            end
            
            app.app_Manager.update_Tree_And_Check_New_Studies();
            
        end

        % Button pushed function: RejectTrialsButton
        function RejectTrialsButtonPushed(app, event)

            sFiles = app.app_Manager.selected_Studies();

            options = {'MEG gradio', 'MEG magneto', 'EEG', 'SEEG/ECOG', 'EOG', 'ECG'};
            %app.app_Manager.ask_user_list(options, 'Signal Detection Type', 'on');
            %selectedOptions = app.userAnswer;
            selectedOptions = app.list_dialog('Signal Detection Type', options);
            
            if isempty(selectedOptions)
                return
            end
            MEGgrad = [];
            MEGmagneto = [];
            EEG = [];
            SEEG_ECOG = [];
            EOG = [];
            ECG = [];
            
            if any(strcmp(options{1}, selectedOptions))
                
                question = 'MEG gradio minimum (fT): ';
                %app.app_Manager.ask_user(question);
                %MEGgradMin = app.userAnswer;
                MEGgradMin = app.input_dialog(question);
    
                question = 'MEG gradio maximum (fT): ';
                %app.app_Manager.ask_user(question);
                %MEGgradMax = app.userAnswer;
                MEGgradMax = app.input_dialog(question);
                
                MEGgrad = [MEGgradMin MEGgradMax];
            end
            
            if (strcmp(options{2}, selectedOptions))
                
                question = 'MEG magneto minimum (fT): ';
                %app.app_Manager.ask_user(question);
                %MEGmagnetoMin = app.userAnswer;
                MEGmagnetoMin = app.input_dialog(question);
                
                question = 'MEG magneto maximum (fT): ';
                %app.app_Manager.ask_user(question);
                %MEGmagnetoMax = app.userAnswer;
                MEGmagnetoMax = app.input_dialog(question);
                
                MEGmagneto = [MEGmagnetoMin MEGmagnetoMax];
                
            end
            
            if (strcmp(options{3}, selectedOptions))
                
                question = 'EEG minimum (µV): ';
                %app.app_Manager.ask_user(question);
                %EEGmin = app.userAnswer;
                EEGmin = app.input_dialog(question);
                
                question = 'EEG maximum (µV): ';
                %app.app_Manager.ask_user(question);
                %EEGmax = app.userAnswer;
                EEGmax = app.input_dialog(question);
                
                EEG = [EEGmin EEGmax];
                
            end
            
            if (strcmp(options{4}, selectedOptions))
                
                question = 'SEEG/ECOG minimum (µV): ';
                %app.app_Manager.ask_user(question);
                %SEEG_ECOGmin = app.userAnswer;
                SEEG_ECOGmin = app.input_dialog(question);
                
                question = 'SEEG/ECOG maximum (µV): ';
                %app.app_Manager.ask_user(question);
                %SEEG_ECOGmax = app.userAnswer;
                SEEG_ECOGmax = app.input_dialog(question);
                
                SEEG_ECOG = [SEEG_ECOGmin SEEG_ECOGmax];
                
            end
            
            if (strcmp(options{5}, selectedOptions))
                
                question = 'EOG minimum (µV): ';
                %app.app_Manager.ask_user(question);
                %EOGmin = app.userAnswer;
                EOGmin = app.input_dialog(question);
                
                question = 'EOG maximum (µV): ';
                %app.app_Manager.ask_user(question);
                %EOGmax = app.userAnswer;
                EOGmax = app.input_dialog(question);
                
                EOG = [EOGmin EOGmax];
                
            end
            
            if (strcmp(options{6}, selectedOptions))
                
                question = 'ECG minimum (mV): ';
                %app.app_Manager.ask_user(question);
                %ECGmin = app.userAnswer;
                ECGmin = app.input_dialog(question);
                
                question = 'ECG maximum (mV): ';
                %app.app_Manager.ask_user(question);
                %ECGmax = app.userAnswer;
                ECGmax = app.input_dialog(question);
                
                ECG = [ECGmin ECGmax];
                 
            end
            
            app.controller.rejectBadTrials(sFiles, MEGgrad, ...
                            MEGmagneto, EEG, SEEG_ECOG, EOG, ECG);

        end

        % Button pushed function: SelectGoodTrialButton
        function SelectGoodTrialButtonPushed(app, event)
            sFiles = app.app_Manager.selected_Studies();
            app.app_Manager.update_Tree_And_Check_New_Studies();
            assignin("base", "sFiles", sFiles);
        end

        % Button pushed function: AverageButton
        function AverageButtonPushed(app, event)
            
            sFiles = app.app_Manager.selected_Studies();
            
            averageTypes = ["Everything", "By Subject", "By Folder (subject average)", ...
                            "By Folder (grand average)", "By Trial Group (folder average)", ...
                            "By Trial Group (subject average)", "By Trial Group (grand average)"];
            %app.app_Manager.ask_user_list(averageTypes, 'Choose type', 'off');
            %averageType = app.userAnswer;
            averageType = app.list_dialog('Choose Type', averageTypes);
            
            if isempty(averageType)
                return
            end
            
            functionTypes = ["Arithmetic Average", "Average Absolute Values", "Root Mean Square", ...
                            "Standard Deviation", "Standard Error", ...
                            "Arithmetic Average + Standard Deviation", ...
                            "Arithmetic Average + Standard Error", "Median"];
            %app.app_Manager.ask_user_list(functionTypes, 'Choose type', 'off');
            %functionType = app.userAnswer;
            functionType = app.list_dialog('Choose type', functionTypes);
            
            if isempty(functionType)
                return
            end
            
            app.BasicFct.average(sFiles, averageType, functionType);
            
        end

        % Menu selected function: CreateProtocolMenu
        function CreateProtocolMenuSelected(app, event)
            % Input for Protocol Name
            %app.app_Manager.ask_user('Enter the Protocol Name')
            %ProtocolName = app.userAnswer;
            ProtocolName = app.input_dialog('Protocol Name');
            
            % Do nothing if Protocol Name is empty
            if isempty(ProtocolName)
                return
            end
            
            % If Protocol with same name has already been created
            allProtocols = app.controller.getAllProtocols;
            if (any(strcmp(allProtocols, ProtocolName)))
                message = 'Cannot create Protocol as another Protocol has the same name';
                title = 'Cannot create Protocol';
                waitfor(uiconfirm(app.AnalysisToolUIFigure, message, title));
                return
            end
            
            % Create Protocol
            app.controller.createProtocol(ProtocolName);
            
            
            % Update Tree and Load Current Protocol (it refreshes the
            % brainstorm interface)
            app.app_Manager.update_Tree();
            app.ReloadCurrentProtocolMenuSelected;
            
            return            
        end

        % Menu selected function: ReloadCurrentProtocolMenu
        function ReloadCurrentProtocolMenuSelected(app, event)
            db_reload_database(bst_get('iProtocol'));
        end

        % Menu selected function: LoadProtocolMenu
        function LoadProtocolMenuSelected(app, event)
            % Display all Protocol
            allProtocols = app.controller.getAllProtocols;
            question = 'Choose Protocol to Load';
            %app.app_Manager.ask_user_list(allProtocols, question, 'off');
            %protocolName = app.userAnswer;
            protocolName = app.list_dialog(question, allProtocols);
            
            if isempty(protocolName)
                return
            end
            protocolIndex = bst_get('Protocol', protocolName);
            
            % Set Protocol
            bst_set('iProtocol', protocolIndex);
            gui_brainstorm('SetCurrentProtocol', protocolIndex);
            
            % Update Tree
            app.app_Manager.update_Tree();
        end

        % Menu selected function: DeleteProtocolMenu
        function DeleteProtocolMenuSelected(app, event)
            % Display all Protocols
            allProtocols = app.controller.getAllProtocols;
            
            question = 'Protocol to delete';
            %app.app_Manager.ask_user_list(allProtocols, question, 'on');
            %protocolName = app.userAnswer;
            protocolName = app.list_dialog(question, allProtocols, 'multiple');
            
            if isempty(protocolName)
                return
            end
            
            message = "You're about to delete the following protocol: ";
            for i = 1:length(protocolName)
                if i ~= 1
                    message = strcat(message, ', ');
                end
                message = strcat(message, protocolName{i});
            end
            selection = uiconfirm(app.AnalysisToolUIFigure, message, "Protocol Delete");
            
            % Delete Protocol
            if selection == "OK"
                for i = 1:length(protocolName)
                    app.controller.deleteProtocol(protocolName{i})
                end
                uiconfirm(app.AnalysisToolUIFigure, "Protocol has been deleted", "Protocol Delete");         
            end

            % Update Tree
            app.app_Manager.update_Tree();
        end

        % Menu selected function: CreateSubjectMenu
        function CreateSubjectMenuSelected(app, event)
            % Input for Subject Name
            question = 'Subject Name: ';
            %app.app_Manager.ask_user(question);
            %subjectName = app.userAnswer;
            subjectName = app.input_dialog(question);
            
            if isempty(subjectName)
                return
            end
            
            % Input for type of Anatomy
            choice = ["Specify anatomy folder", "Use default anatomy", "Cancel"];
            rep = uiconfirm(app.AnalysisToolUIFigure, 'Do you have a specific anatomy folder for your subjet or do you want to use the default anatomy ?', ...
                'Create Subject', 'Options', choice);
            
            switch rep
                case choice(1)  % Specific Anatomy
                    title = "Choose the anatomy folder";
                    AnatomyPath = uigetdir(pwd, title);

                    if isequal(AnatomyPath, 0)
                        return
                    end
                    app.BasicFct.importAnatomy(subjectName, AnatomyPath, app.controller.getAnatomyFileFormat);
                    
                case choice(2)  % Default Anatomy
                    db_add_subject(subjectName, [], 1, 0);

                case choice(3)  % Cancel
                    return
            end

            % Update Tree
            app.app_Manager.update_Tree();
        end

        % Menu selected function: DeleteSubjectMenu
        function DeleteSubjectMenuSelected(app, event)
            sFiles = app.app_Manager.selected_Studies();
            subjects = unique({sFiles.SubjectName});
            
            message = "You're about to delete the following subject: ";
            for i = 1:length(subjects)
                if i ~= 1
                    message = message + ', ';
                end
                message = message + subjects{i};
            end
            rep = uiconfirm(app.AnalysisToolUIFigure, message, 'Subject to delete');
            
            if rep == "OK"
                del = 3;
                bst_process('CallProcess', 'process_delete', sFiles, [], ...
                'target', del); % 1: fichier 2: folder (recommended) 3: subjects
            end
                
            
            app.app_Manager.update_Tree();
        end

        % Menu selected function: ReviewRawFilesMenu
        function ReviewRawFilesMenuSelected(app, event)
            protoSubjects = bst_get('ProtocolSubjects');
            
            % List of all Subjects
            subjects = cell(1, length(protoSubjects.Subject));
            for i = 1:length(protoSubjects.Subject)
                subjects{i} = protoSubjects.Subject(i).Name;
            end
            
            % Select Subject associated with the dataset
            %app.app_Manager.ask_user_list(subjects, 'Select Subject', 'off');
            %subjectName = app.userAnswer;
            subjectName = char(app.list_dialog('Select Subject', subjects));
            
            if isempty(subjectName)
                return
            end
            
            title = "Select your data file";
            extension_in = app.controller.getSupportedExtension;
            rawFilesPath = app.app_Manager.review_Raw_Files(title, extension_in);
            
            app.controller.reviewRawFiles(subjectName, rawFilesPath);

            app.app_Manager.update_Tree_And_Check_New_Studies();
        end

        % Menu selected function: DeleteStudyMenu
        function DeleteStudyMenuSelected(app, event)
            sFiles = app.app_Manager.selected_Studies();
            studies = unique({sFiles.Condition});
            
            message = "You're about to delete the following studies: ";
            for i = 1:length(studies)
                if i ~= 1
                    message = message + ', ';
                end
                message = message + studies{i};
            end
            
            rep = uiconfirm(app.AnalysisToolUIFigure, message, 'Subject to delete');
            
            if rep == "OK"
                del = 2;
                bst_process('CallProcess', 'process_delete', sFiles, [], ...
                'target', del); % 1: fichier 2: folder (recommended) 3: subjects
            end
            
            app.app_Manager.update_Tree();
        end

        % Menu selected function: RenameEventMenu
        function RenameEventMenuSelected(app, event)
            sFiles = app.app_Manager.selected_Studies();

            % Display all Events
            events = app.controller.getEvents(sFiles);
            question = 'Chose the Event to rename';
            %app.app_Manager.ask_user_list(events, question, 'off');
            %oldName = app.userAnswer;
            oldName = app.list_dialog(question, events);
            
            if isempty(oldName)
                return
            end

            % Input for New Name
            question = 'Enter the new Event Name';
            %app.app_Manager.ask_user(question);
            %newName = app.userAnswer;
            newName = app.input_dialog(question);
            

            if isempty(newName)
                waitfor(errordlg("Please enter a new event name."))
                return
            end
            
            % Renaming Event
            app.controller.renameEvent(sFiles, oldName, newName);
        end

        % Menu selected function: DeleteEventMenu
        function DeleteEventMenuSelected(app, event)
            sFiles = app.app_Manager.selected_Studies();

            % Display all Events
            events = app.controller.getEvents(sFiles);
            question = 'Select the Event to delete:';
            %app.app_Manager.ask_user_list(events, question, 'on');
            %eventToDelete = app.userAnswer;
            eventToDelete = app.list_dialog(question, events, 'n');
            
            if isempty(eventToDelete)
                msgbox("Missing event to delete.")
                return
            end

            % Deleting Events
            for i = 1:length(eventToDelete)
                app.controller.deleteEvent(sFiles, eventToDelete{i});
            end
        end

        % Menu selected function: ExporttoEDFMenu
        function ExporttoEDFMenuSelected(app, event)
            sFiles = app.app_Manager.selected_Studies();
            
            path = uigetdir(pwd, "Where do you want to save the EDF file");
            
            if isequal(path, 0)
                return
            end
            
            for i = 1:length(sFiles)
                app.controller.exportData(sFiles(i), path, '.edf');
            end
        end

        % Menu selected function: ExporttoBrainVisionMenu
        function ExporttoBrainVisionMenuSelected(app, event)
            sFiles = app.app_Manager.selected_Studies();
            
            path = uigetdir(pwd, "Where do you want to save the BrainVision file");
            
            if isequal(path, 0)
                return
            end
            
            for i = 1:length(sFiles)
                app.controller.exportData(sFiles(i), path, '.eeg');
            end
        end

        % Menu selected function: ExporttoBIDSMenu
        function ExporttoBIDSMenuSelected(app, event)
            sFiles = app.app_Manager.selected_Studies();
            
            % Input for BIDS folder
            question = 'Name of BIDS folder';
            %app.app_Manager.ask_user(question);
            %BIDSfolderName = app.userAnswer;
            BidsFolderName = app.input_dialog(question);
            
            if isempty(BidsFolderName)
                return
            end
            
            path = uigetdir(pwd, "Where do you want to save the BIDS folder");
            
            if isequal(path, 0)
                return
            end
            
            bidsPath = fullfile(path, BidsFolderName, '/');
            
            app.controller.exportToBids(sFiles, bidsPath);

        end

        % Menu selected function: OpenBrainstormMenu
        function OpenBrainstormMenuSelected(app, event)
            brainstorm;
        end

        % Menu selected function: AddTagMenu
        function AddTagMenuSelected(app, event)
            sFiles = app.app_Manager.selected_Studies();
            
            question = 'Tag name to add: ';
            %app.app_Manager.ask_user(question);
            %tag = app.userAnswer;
            tag = app.input_dialog(question);
            
            if isempty(Tag)
                return
            end

            choice = ["Add Tag to the file Name", "Add tag to the file path", "Cancel"];

            rep = uiconfirm(app.AnalysisToolUIFigure, 'where do you want to add the tag ?', ...
                'Add Tag', 'Options', choice);

            switch rep
                case choice(1)
                    output = 1;
                case choice(2)
                    output = 2;
                case choice(3)
                    return    
            end
                    
            bst_process('CallProcess', 'process_add_tag', sFiles, [], ...
                    'tag',           tag, ...
                    'output',        output); 
        end

        % Menu selected function: ColorModeMenu
        function ColorModeMenuSelected(app, event)
            
            if app.ColorModeMenu.Text == "Dark Mode"
                backColor = [0 0 0];
                textColor = [1 1 1];
                textColorHyperLink = [0 0.8 1];
                app.ColorModeMenu.Text = "Bright Mode";
                app.cervo.ImageSource = 'CERVO_Dark.png';
                app.braincanada.ImageSource = 'BrainCanada_Dark.png';
                app.ulaval.ImageSource = 'Ulaval_Dark.png';
            elseif app.ColorModeMenu.Text == "Bright Mode"
                backColor = [1 1 1];
                textColor = [0 0 0];
                textColorHyperLink = [0 0.4 0.8];
                app.ColorModeMenu.Text = "Dark Mode";            
                app.cervo.ImageSource = 'CERVO_Bright.png';
                app.braincanada.ImageSource = 'BrainCanada_Bright.png';
                app.ulaval.ImageSource = 'Ulaval_Bright.png';
            end

            app.app_Manager.switch_Parent_Color_Mode(app.AnalysisToolUIFigure, ...
                backColor, textColor, textColorHyperLink);
            
        end

        % Menu selected function: SelectComponentsMenu
        function SelectComponentsMenuSelected(app, event)
            sFiles = app.app_Manager.selected_Studies();
            app.controller.viewComponents(sFiles);
        end

        % Menu selected function: EEGMenu
        function EEGMenuSelected(app, event)
            app.app_Manager.switch_Analysis_Type('EEG');
        end

        % Menu selected function: MEGMenu
        function MEGMenuSelected(app, event)
            app.app_Manager.switch_Analysis_Type('MEG');
        end

        % Button pushed function: ComputeSourcesButton
        function ComputeSourcesButtonPushed(app, event)
            sFiles = app.app_Manager.selected_Studies();
            
            app.BasicFct.computeSources(sFiles);
        end

        % Button pushed function: SelectImportedButton
        function SelectImportedButtonPushed(app, event)
            app.app_Manager.selectImportedStudies();
        end

        % Button pushed function: SelectRawButton
        function SelectRawButtonPushed(app, event)
            app.app_Manager.selectRawStudies();
        end

        % Button pushed function: CollapseButton
        function CollapseButtonPushed(app, event)
            app.Tree.collapse;
        end

        % Button pushed function: ExpandButton
        function ExpandButtonPushed(app, event)
            app.Tree.expand;
        end

        % Close request function: AnalysisToolUIFigure
        function AnalysisToolUIFigureCloseRequest(app, event)

            rep = uiconfirm(app.AnalysisToolUIFigure, 'Do you want to close the app ?', 'Close request');
            
            app.controller.removePaths();
            
            if rep == "OK"
                delete(app)
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AnalysisToolUIFigure = uifigure('Visible', 'off');
            app.AnalysisToolUIFigure.Name = 'Analysis Tool';
            app.AnalysisToolUIFigure.CloseRequestFcn = createCallbackFcn(app, @AnalysisToolUIFigureCloseRequest, true);
            app.ProtocolMenu = uimenu(app.AnalysisToolUIFigure);
            app.ProtocolMenu.Text = 'Protocol';
            app.CreateProtocolMenu = uimenu(app.ProtocolMenu);
            app.CreateProtocolMenu.MenuSelectedFcn = createCallbackFcn(app, @CreateProtocolMenuSelected, true);
            app.CreateProtocolMenu.Text = 'Create Protocol';
            app.ReloadCurrentProtocolMenu = uimenu(app.ProtocolMenu);
            app.ReloadCurrentProtocolMenu.MenuSelectedFcn = createCallbackFcn(app, @ReloadCurrentProtocolMenuSelected, true);
            app.ReloadCurrentProtocolMenu.Text = 'Reload Current Protocol';
            app.LoadProtocolMenu = uimenu(app.ProtocolMenu);
            app.LoadProtocolMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadProtocolMenuSelected, true);
            app.LoadProtocolMenu.Text = 'Load Protocol';
            app.DeleteProtocolMenu = uimenu(app.ProtocolMenu);
            app.DeleteProtocolMenu.MenuSelectedFcn = createCallbackFcn(app, @DeleteProtocolMenuSelected, true);
            app.DeleteProtocolMenu.Text = 'Delete Protocol';
            app.SubjectMenu = uimenu(app.AnalysisToolUIFigure);
            app.SubjectMenu.Text = 'Subject';
            app.CreateSubjectMenu = uimenu(app.SubjectMenu);
            app.CreateSubjectMenu.MenuSelectedFcn = createCallbackFcn(app, @CreateSubjectMenuSelected, true);
            app.CreateSubjectMenu.Text = 'Create Subject';
            app.DeleteSubjectMenu = uimenu(app.SubjectMenu);
            app.DeleteSubjectMenu.MenuSelectedFcn = createCallbackFcn(app, @DeleteSubjectMenuSelected, true);
            app.DeleteSubjectMenu.Text = 'Delete Subject';
            app.StudyMenu = uimenu(app.AnalysisToolUIFigure);
            app.StudyMenu.Text = 'Study';
            app.ReviewRawFilesMenu = uimenu(app.StudyMenu);
            app.ReviewRawFilesMenu.MenuSelectedFcn = createCallbackFcn(app, @ReviewRawFilesMenuSelected, true);
            app.ReviewRawFilesMenu.Text = 'Review Raw Files';
            app.SelectComponentsMenu = uimenu(app.StudyMenu);
            app.SelectComponentsMenu.MenuSelectedFcn = createCallbackFcn(app, @SelectComponentsMenuSelected, true);
            app.SelectComponentsMenu.Text = 'Select Components';
            app.DeleteStudyMenu = uimenu(app.StudyMenu);
            app.DeleteStudyMenu.MenuSelectedFcn = createCallbackFcn(app, @DeleteStudyMenuSelected, true);
            app.DeleteStudyMenu.Text = 'Delete Study';
            app.EventMenu = uimenu(app.AnalysisToolUIFigure);
            app.EventMenu.Text = 'Event';
            app.RenameEventMenu = uimenu(app.EventMenu);
            app.RenameEventMenu.MenuSelectedFcn = createCallbackFcn(app, @RenameEventMenuSelected, true);
            app.RenameEventMenu.Text = 'Rename Event';
            app.DeleteEventMenu = uimenu(app.EventMenu);
            app.DeleteEventMenu.MenuSelectedFcn = createCallbackFcn(app, @DeleteEventMenuSelected, true);
            app.DeleteEventMenu.Text = 'Delete Event';
            app.ConversionMenu = uimenu(app.AnalysisToolUIFigure);
            app.ConversionMenu.Text = 'Conversion';
            app.ExporttoEDFMenu = uimenu(app.ConversionMenu);
            app.ExporttoEDFMenu.MenuSelectedFcn = createCallbackFcn(app, @ExporttoEDFMenuSelected, true);
            app.ExporttoEDFMenu.Text = 'Export to EDF';
            app.ExporttoBrainVisionMenu = uimenu(app.ConversionMenu);
            app.ExporttoBrainVisionMenu.MenuSelectedFcn = createCallbackFcn(app, @ExporttoBrainVisionMenuSelected, true);
            app.ExporttoBrainVisionMenu.Text = 'Export to BrainVision';
            app.ExporttoBIDSMenu = uimenu(app.ConversionMenu);
            app.ExporttoBIDSMenu.MenuSelectedFcn = createCallbackFcn(app, @ExporttoBIDSMenuSelected, true);
            app.ExporttoBIDSMenu.Text = 'Export to BIDS';
            app.OptionsMenu = uimenu(app.AnalysisToolUIFigure);
            app.OptionsMenu.Text = 'Options';
            app.AnalysisTypeMenu = uimenu(app.OptionsMenu);
            app.AnalysisTypeMenu.Text = 'Analysis Type';
            app.EEGMenu = uimenu(app.AnalysisTypeMenu);
            app.EEGMenu.MenuSelectedFcn = createCallbackFcn(app, @EEGMenuSelected, true);
            app.EEGMenu.Separator = 'on';
            app.EEGMenu.Text = 'EEG';
            app.MEGMenu = uimenu(app.AnalysisTypeMenu);
            app.MEGMenu.MenuSelectedFcn = createCallbackFcn(app, @MEGMenuSelected, true);
            app.MEGMenu.Checked = 'on';
            app.MEGMenu.Text = 'MEG';
            app.OpenBrainstormMenu = uimenu(app.OptionsMenu);
            app.OpenBrainstormMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenBrainstormMenuSelected, true);
            app.OpenBrainstormMenu.Text = 'Open Brainstorm';
            app.AddTagMenu = uimenu(app.OptionsMenu);
            app.AddTagMenu.MenuSelectedFcn = createCallbackFcn(app, @AddTagMenuSelected, true);
            app.AddTagMenu.Text = 'Add Tag';
            app.ColorModeMenu = uimenu(app.OptionsMenu);
            app.ColorModeMenu.MenuSelectedFcn = createCallbackFcn(app, @ColorModeMenuSelected, true);
            app.ColorModeMenu.Text = 'Dark Mode';
            app.TabGroup2 = uitabgroup(app.AnalysisToolUIFigure);
            app.TabGroup2.SelectionChangedFcn = createCallbackFcn(app, @TabGroup2SelectionChanged, true);
            app.EEGTabGr2 = uitab(app.TabGroup2);
            app.EEGTabGr2.Title = 'EEG';
            app.AverageReferenceButton = uibutton(app.EEGTabGr2, 'push');
            app.AverageReferenceButton.ButtonPushedFcn = createCallbackFcn(app, @AverageReferenceButtonPushed, true);
            app.AverageReferenceButton.Text = 'Average Reference';
            app.ICAEEGButton = uibutton(app.EEGTabGr2, 'push');
            app.ICAEEGButton.ButtonPushedFcn = createCallbackFcn(app, @ICAEEGButtonPushed, true);
            app.ICAEEGButton.Text = 'ICA';
            app.MEGTabGr2 = uitab(app.TabGroup2);
            app.MEGTabGr2.Title = 'MEG';
            app.RemoveSimultaneousEventsButton = uibutton(app.MEGTabGr2, 'push');
            app.RemoveSimultaneousEventsButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveSimultaneousEventsButtonPushed, true);
            app.RemoveSimultaneousEventsButton.Text = 'Remove Simultaneous Events';
            app.DetectArtifactButton = uibutton(app.MEGTabGr2, 'push');
            app.DetectArtifactButton.ButtonPushedFcn = createCallbackFcn(app, @DetectArtifactButtonPushed, true);
            app.DetectArtifactButton.Text = 'Detect Artifact';
            app.SSPButton = uibutton(app.MEGTabGr2, 'push');
            app.SSPButton.ButtonPushedFcn = createCallbackFcn(app, @SSPButtonPushed, true);
            app.SSPButton.Text = 'SSP';
            app.ICAMEGButton = uibutton(app.MEGTabGr2, 'push');
            app.ICAMEGButton.ButtonPushedFcn = createCallbackFcn(app, @ICAMEGButtonPushed, true);
            app.ICAMEGButton.Text = 'ICA';
            app.TabGroup1 = uitabgroup(app.AnalysisToolUIFigure);
            app.TabGroup1.SelectionChangedFcn = createCallbackFcn(app, @TabGroup1SelectionChanged, true);
            app.EEGTabGr1 = uitab(app.TabGroup1);
            app.EEGTabGr1.Title = 'EEG';
            app.AddEEGPositionsButton = uibutton(app.EEGTabGr1, 'push');
            app.AddEEGPositionsButton.ButtonPushedFcn = createCallbackFcn(app, @AddEEGPositionsButtonPushed, true);
            app.AddEEGPositionsButton.Text = 'Add EEG Positions';
            app.RefineRegistrationButton = uibutton(app.EEGTabGr1, 'push');
            app.RefineRegistrationButton.ButtonPushedFcn = createCallbackFcn(app, @RefineRegistrationButtonPushed, true);
            app.RefineRegistrationButton.Text = 'Refine Registration';
            app.ProjectElectrodesonScalpButton = uibutton(app.EEGTabGr1, 'push');
            app.ProjectElectrodesonScalpButton.ButtonPushedFcn = createCallbackFcn(app, @ProjectElectrodesonScalpButtonPushed, true);
            app.ProjectElectrodesonScalpButton.Text = 'Project Electrodes on Scalp';
            app.DetectHeartbeatsButton = uibutton(app.EEGTabGr1, 'push');
            app.DetectHeartbeatsButton.ButtonPushedFcn = createCallbackFcn(app, @DetectHeartbeatsButtonPushed, true);
            app.DetectHeartbeatsButton.Text = 'Detect Heartbeats';
            app.ImportEventsButton = uibutton(app.EEGTabGr1, 'push');
            app.ImportEventsButton.ButtonPushedFcn = createCallbackFcn(app, @ImportEventsButtonPushed, true);
            app.ImportEventsButton.Text = 'Import Events';
            app.MEGTabGr1 = uitab(app.TabGroup1);
            app.MEGTabGr1.Title = 'MEG';
            app.ConvertEpochstoContinueButton = uibutton(app.MEGTabGr1, 'push');
            app.ConvertEpochstoContinueButton.ButtonPushedFcn = createCallbackFcn(app, @ConvertEpochstoContinueButtonPushed, true);
            app.ConvertEpochstoContinueButton.Text = 'Convert Epochs to Continue';
            app.FREQUENCYPanel = uipanel(app.AnalysisToolUIFigure);
            app.FREQUENCYPanel.Title = 'FREQUENCY';
            app.NotchFilterButton = uibutton(app.FREQUENCYPanel, 'push');
            app.NotchFilterButton.ButtonPushedFcn = createCallbackFcn(app, @NotchFilterButtonPushed, true);
            app.NotchFilterButton.Text = 'Notch Filter';
            app.BandpassFilterButton = uibutton(app.FREQUENCYPanel, 'push');
            app.BandpassFilterButton.ButtonPushedFcn = createCallbackFcn(app, @BandpassFilterButtonPushed, true);
            app.BandpassFilterButton.Text = 'Band-pass Filter';
            app.PowerSpectrumDensityButton = uibutton(app.FREQUENCYPanel, 'push');
            app.PowerSpectrumDensityButton.ButtonPushedFcn = createCallbackFcn(app, @PowerSpectrumDensityButtonPushed, true);
            app.PowerSpectrumDensityButton.Text = 'Power Spectrum Density';
            app.HISTORYPanel = uipanel(app.AnalysisToolUIFigure);
            app.HISTORYPanel.Title = 'HISTORY';
            app.EVENTSPanel = uipanel(app.AnalysisToolUIFigure);
            app.EVENTSPanel.Title = 'EVENTS';
            app.PipelineTextArea = uitextarea(app.AnalysisToolUIFigure);
            app.PipelineTextArea.Value = {'NO PIPELINE LOADED'};
            app.ClearHistoryButton = uibutton(app.AnalysisToolUIFigure, 'push');
            app.ClearHistoryButton.ButtonPushedFcn = createCallbackFcn(app, @ClearHistoryButtonPushed, true);
            app.ClearHistoryButton.Text = 'Clear History';
            app.ClearEventsButton = uibutton(app.AnalysisToolUIFigure, 'push');
            app.ClearEventsButton.ButtonPushedFcn = createCallbackFcn(app, @ClearEventsButtonPushed, true);
            app.ClearEventsButton.Text = 'Clear Events';
            app.UpdateHistoryButton = uibutton(app.AnalysisToolUIFigure, 'push');
            app.UpdateHistoryButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateHistoryButtonPushed, true);
            app.UpdateHistoryButton.Text = 'Update History';
            app.UpdateEventsButton = uibutton(app.AnalysisToolUIFigure, 'push');
            app.UpdateEventsButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateEventsButtonPushed, true);
            app.UpdateEventsButton.Text = 'Update Events';
            app.PIPELINEPanel = uipanel(app.AnalysisToolUIFigure);
            app.PIPELINEPanel.Title = 'PIPELINE';
            app.OpenPipelineBuilderButton = uibutton(app.PIPELINEPanel, 'push');
            app.OpenPipelineBuilderButton.ButtonPushedFcn = createCallbackFcn(app, @OpenPipelineBuilderButtonPushed, true);
            app.OpenPipelineBuilderButton.Text = 'Open Pipeline Builder';
            app.ClearPipelineButton = uibutton(app.PIPELINEPanel, 'push');
            app.ClearPipelineButton.ButtonPushedFcn = createCallbackFcn(app, @ClearPipelineButtonPushed, true);
            app.ClearPipelineButton.Text = 'Clear Pipeline';
            app.ImportPipelineButton = uibutton(app.PIPELINEPanel, 'push');
            app.ImportPipelineButton.ButtonPushedFcn = createCallbackFcn(app, @ImportPipelineButtonPushed, true);
            app.ImportPipelineButton.Text = 'Import Pipeline';
            app.RunPipelineButton = uibutton(app.PIPELINEPanel, 'push');
            app.RunPipelineButton.ButtonPushedFcn = createCallbackFcn(app, @RunPipelineButtonPushed, true);
            app.RunPipelineButton.Text = 'Run Pipeline';
            app.Tree = uitree(app.AnalysisToolUIFigure, 'checkbox');
            app.Hyperlink = uihyperlink(app.AnalysisToolUIFigure);
            app.Hyperlink.URL = 'https://github.com/CorentinLabelle/EEG-MEG-Analysis-Tool';
            app.Hyperlink.Text = 'GitHub/CorentinLabelle/AnalysisTool';
            app.Hyperlink2 = uihyperlink(app.AnalysisToolUIFigure);
            app.Hyperlink2.URL = 'https://neuroimage.usc.edu/brainstorm/Tutorials';
            app.Hyperlink2.Text = 'Brainstorm MEG Tutorial';
            app.Hyperlink2_2 = uihyperlink(app.AnalysisToolUIFigure);
            app.Hyperlink2_2.URL = 'https://neuroimage.usc.edu/brainstorm/Tutorials/Epilepsy';
            app.Hyperlink2_2.Text = 'Brainstorm EEG Tutorial';
            app.ImportinDataBaseButton = uibutton(app.AnalysisToolUIFigure, 'push');
            app.ImportinDataBaseButton.ButtonPushedFcn = createCallbackFcn(app, @ImportinDataBaseButtonPushed, true);
            app.ImportinDataBaseButton.Text = 'Import in DataBase';
            app.POSTPROCESSPanel = uipanel(app.AnalysisToolUIFigure);
            app.POSTPROCESSPanel.Title = 'POST-PROCESS';
            app.RejectTrialsButton = uibutton(app.POSTPROCESSPanel, 'push');
            app.RejectTrialsButton.ButtonPushedFcn = createCallbackFcn(app, @RejectTrialsButtonPushed, true);
            app.RejectTrialsButton.Text = 'Reject Trials';
            app.SelectGoodTrialButton = uibutton(app.POSTPROCESSPanel, 'push');
            app.SelectGoodTrialButton.ButtonPushedFcn = createCallbackFcn(app, @SelectGoodTrialButtonPushed, true);
            app.SelectGoodTrialButton.Text = 'Select Good Trial';
            app.AverageButton = uibutton(app.POSTPROCESSPanel, 'push');
            app.AverageButton.ButtonPushedFcn = createCallbackFcn(app, @AverageButtonPushed, true);
            app.AverageButton.Text = 'Average';
            app.ComputeSourcesButton = uibutton(app.POSTPROCESSPanel, 'push');
            app.ComputeSourcesButton.ButtonPushedFcn = createCallbackFcn(app, @ComputeSourcesButtonPushed, true);
            app.ComputeSourcesButton.Text = 'Compute Sources';
            app.PROTOCOLPanel = uipanel(app.AnalysisToolUIFigure);
            app.PROTOCOLPanel.Title = 'PROTOCOL';
            app.SelectDeselectallButton = uibutton(app.PROTOCOLPanel, 'push');
            app.SelectDeselectallButton.ButtonPushedFcn = createCallbackFcn(app, @SelectDeselectallButtonPushed, true);
            app.SelectDeselectallButton.Text = 'Select/Deselect all';
            app.UpdateTreeButton = uibutton(app.PROTOCOLPanel, 'push');
            app.UpdateTreeButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateTreeButtonPushed, true);
            app.UpdateTreeButton.Text = 'Update Tree';
            app.CurrentProtocolEditField = uieditfield(app.PROTOCOLPanel, 'text');
            app.CurrentProtocolEditField.Tag = 'CurrentProto';
            app.CurrentProtocolEditField.Editable = 'off';
            app.CurrentProtocolLabel = uilabel(app.PROTOCOLPanel);
            app.CurrentProtocolLabel.Text = 'Current Protocol:';
            app.SelectImportedButton = uibutton(app.PROTOCOLPanel, 'push');
            app.SelectImportedButton.ButtonPushedFcn = createCallbackFcn(app, @SelectImportedButtonPushed, true);
            app.SelectImportedButton.Text = 'Select Imported';
            app.SelectRawButton = uibutton(app.PROTOCOLPanel, 'push');
            app.SelectRawButton.ButtonPushedFcn = createCallbackFcn(app, @SelectRawButtonPushed, true);
            app.SelectRawButton.Text = 'Select Raw';
            app.CollapseButton = uibutton(app.PROTOCOLPanel, 'push');
            app.CollapseButton.ButtonPushedFcn = createCallbackFcn(app, @CollapseButtonPushed, true);
            app.CollapseButton.Text = 'Collapse';
            app.ExpandButton = uibutton(app.PROTOCOLPanel, 'push');
            app.ExpandButton.ButtonPushedFcn = createCallbackFcn(app, @ExpandButtonPushed, true);
            app.ExpandButton.Text = 'Expand';
            app.cervo = uiimage(app.AnalysisToolUIFigure);
            app.cervo.ImageSource = 'CERVO_Bright.png';
            app.braincanada = uiimage(app.AnalysisToolUIFigure);
            app.braincanada.ImageSource = 'BrainCanada_Bright.png';
            app.ulaval = uiimage(app.AnalysisToolUIFigure);
            app.ulaval.ImageSource = 'Ulaval_Bright.png';
            app.Image = uiimage(app.AnalysisToolUIFigure);
            app.Image.ImageSource = 'Brainstorm.png';
            app.Image2 = uiimage(app.AnalysisToolUIFigure);
            app.Image2.ImageSource = 'EEGNet.png';
            app.Image3 = uiimage(app.AnalysisToolUIFigure);
            app.Image3.ImageSource = 'EEGLab.png';
            % Show the figure after all components are created
            app.AnalysisToolUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Analysis_Tool(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.AnalysisToolUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.AnalysisToolUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AnalysisToolUIFigure)
        end
    end
end